classdef MEZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        graphs            matlab.ui.container.TabGroup
        arrange           matlab.ui.container.Tab
        arr               matlab.ui.control.UIAxes
        E                 matlab.ui.container.Tab
        Ecolor            matlab.ui.control.UIAxes
        EonKT             matlab.ui.container.Tab
        EKT               matlab.ui.control.UIAxes
        phi               matlab.ui.container.Tab
        phicolor          matlab.ui.control.UIAxes
        forcelines        matlab.ui.container.Tab
        force             matlab.ui.control.UIAxes
        opt12nd           matlab.ui.container.Tab
        opt12ndE          matlab.ui.control.UIAxes
        ELinsert          matlab.ui.container.Panel
        PP                matlab.ui.control.Label
        OS                matlab.ui.control.Label
        addPP             matlab.ui.control.Button
        addOS             matlab.ui.control.Button
        changeplace       matlab.ui.control.Button
        deleteEL          matlab.ui.control.Button
        fieldcalculation  matlab.ui.container.Panel
        deletedata        matlab.ui.control.Button
        solve             matlab.ui.control.Button
        saveresults       matlab.ui.control.Button
        Label_8           matlab.ui.control.Label
        changeepsilon     matlab.ui.control.NumericEditField
        numberoflines     matlab.ui.control.NumericEditField
        choosearea        matlab.ui.control.RockerSwitch
        Label_10          matlab.ui.control.Label
        insertX           matlab.ui.control.NumericEditField
        xLabel            matlab.ui.control.Label
        insertY           matlab.ui.control.NumericEditField
        yLabel            matlab.ui.control.Label
        Ephi              matlab.ui.control.Switch
        showE             matlab.ui.control.EditField
        Label_11          matlab.ui.control.Label
        Panel_2           matlab.ui.container.Panel
        AddOpt            matlab.ui.control.Button
        Opt               matlab.ui.control.Button
        optresults        matlab.ui.control.Table
        Label_9           matlab.ui.control.Label
        Switch            matlab.ui.control.Switch
        methodchoose      matlab.ui.container.ButtonGroup
        Levenberg         matlab.ui.control.RadioButton
        Grad              matlab.ui.control.RadioButton
        BFGSmethod        matlab.ui.control.RadioButton
        saveoptresults    matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        EmaxLabel         matlab.ui.control.Label
        showEmax          matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        showEps           matlab.ui.control.EditField
        tEditFieldLabel   matlab.ui.control.Label
        showTime          matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        showCond          matlab.ui.control.EditField
        Label_6           matlab.ui.control.Label
        Lamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        circle0
        react0 % Description
        listofelectrodes % Description
        ellips0 % Description
        ball0 % Description
        cylinder0 % Description
        tor0 % Description
        konus0 % Description
        torrect0
        Eelectr % Description
        x0y0E0 % Description
        x0y0phi0
        chargesnumber0
        chargesnumber
        charges % Description
        optparam % Description
        param % Description
        circles % Description
        areatodraw % Description
        calcXYE % Description
        reacts % Description
        ellipses % Description
        balls % Description
        cylinders
        tors
        konuses
        torrects
        proofopt % Description
        hesse % Description
        giveinfo % Description
        checkall % Description
        generalinfo % Description
        itercounter % Description
        precision % Description
        ForSaveTable % Description
        contourPoints % Description
        firstiter % Description
        otheriter % Description
    end

    methods (Access = public)
        
        function [x, y, xk, yk, U] = makecircle(app)
            if isempty(app.PP.UserData)
               app.chargesnumber0.UserData=zeros(1, 3);
            end
            circle=app.circle0;
            report=[];
            app.checkall=0;
            try
            Rcirc=eval(circle{1});
            Xcirc=eval(circle{2});
            Ycirc=eval(circle{3});
            Ucirc=eval(circle{4});
            ncirc=eval(circle{5});
            array=[Rcirc; Xcirc; Ycirc; Ucirc; ncirc];
            report=1;
            catch ME
                report=getReport(ME);
            end
 
            if report~=1
                uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;
                return
            end


            if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами.', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end
            
            if Rcirc<=0 || Ycirc<=Rcirc || ncirc<=0 || mod(ncirc, 1) || ncirc<10
                uialert(app.UIFigure, {'Радиуc окружности должен быть положительным числом.'; 'Ордината центра окружности должна быть больше её радиуса.'; 'Число ЭЗ должно быть натуральным числом не менее 10.'}, ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end                       
            
             
            app.chargesnumber.Value=ncirc;
            m=1:app.chargesnumber.Value;
            alfa=2*pi/app.chargesnumber.Value;
            H=(2*2*pi*Rcirc)/(app.chargesnumber.Value+2*2*pi);
            x=Xcirc+(Rcirc-H)*cos(alfa*(m-1));
            y=Ycirc+(Rcirc-H)*sin(alfa*(m-1));
            xk=Xcirc+Rcirc*cos(alfa*(m-1));
            yk=Ycirc+Rcirc*sin(alfa*(m-1));
            app.contourPoints(:, 3)=xk;
            app.contourPoints(:, 4)=yk;
            app.contourPoints(:, 6)=1;
            app.contourPoints(:, 7)=Inf;
            app.contourPoints(:, 8)=Rcirc;
            app.contourPoints(:, 9)=Xcirc; 
            app.contourPoints(:, 10)=Ycirc;
            app.contourPoints(:, 12)=Inf;
            ContactChecking(app);
            if app.checkall==1
                uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                        'Ошибка', 'Modal', true)
                    app.contourPoints=[];
                    return
            end
            app.contourPoints=[];
            if isempty(app.PP.UserData)
                app.chargesnumber0.UserData(1, 1)=1;
            else
                app.chargesnumber0.UserData(1, 1)=app.chargesnumber0.UserData(1, 1)+1;
            end                        
            U=ones(length(m), 1)*Ucirc;
            app.checkall=0;            
            app.PP.UserData(end+1:end+app.chargesnumber.Value, 1)=Xcirc+(Rcirc-H)*cos(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 2)=Ycirc+(Rcirc-H)*sin(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 3)=Xcirc+Rcirc*cos(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 4)=Ycirc+Rcirc*sin(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 5)=ones(length(m), 1)*Ucirc;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 6)=ones(length(m), 1);
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 7)=ones(length(m), 1)*app.chargesnumber0.UserData(1, 1);
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 8)=ones(length(m), 1)*Rcirc;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 9)=ones(length(m), 1)*Xcirc;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 10)=ones(length(m), 1)*Ycirc;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 12)=ones(length(m), 1)*ncirc;
            plot(app.arr, app.PP.UserData(end-app.chargesnumber.Value+1:end, 1), app.PP.UserData(end-app.chargesnumber.Value+1:end, 2), '*')
            plot(app.arr, app.PP.UserData(end-app.chargesnumber.Value+1:end, 3), app.PP.UserData(end-app.chargesnumber.Value+1:end, 4), '*')
            text(app.arr, Xcirc, Ycirc, num2str(app.chargesnumber0.UserData(1, 1)));
            maxValue=max(max(app.PP.UserData(:, 3)), max(app.PP.UserData(:, 4)));
            minValue=min(min(app.PP.UserData(:, 3)), min(app.PP.UserData(:, 4)));
            app.arr.XLim=[minValue, maxValue];
            app.arr.YLim=[minValue, maxValue];
            axis(app.arr, 'square')
        end
        
        function [x, y, xk, yk, U] = makereact(app)
                   if isempty(app.PP.UserData)
                   app.chargesnumber0.UserData=zeros(1, 3);
                   end 
               react=app.react0;
               report=[];
               app.checkall=0;
               try
               Arect=eval(react{1});
               Brect=eval(react{2});
               Xrect=eval(react{3});
               Yrect=eval(react{4});
               Urect=eval(react{5});
               nrect=eval(react{6});
               array=[Arect; Brect; Xrect; Yrect; Urect; nrect];
               report=1;
               catch ME
                   report=getReport(ME);
               end
               
                if report~=1
                    uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;
                    return
                end
 
                if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                    uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end
                
                if Arect<=0 || Brect<=0 || Yrect<=0 || nrect<=0 || mod(nrect, 1) || nrect<30
                    uialert(app.UIFigure, {'Ширина и высота прямоугольника должны быть положительными числами.'; 'Ордината нижнего края прямоугольника должна быть положительным числом.'; ...
                        'Число ЭЗ должно быть натуральным числом не менее 30.'}, ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end            


               app.chargesnumber.Value=nrect;
               m=nrect;
               m=m-4;
               m1=m*Arect/(Arect+Brect);
               m1=ceil(m1);
               m2=m-m1;
               if mod(m1, 2)~=0 && m1>1
                   m1bot=ceil(m1/2);
                   m1top=m1-m1bot;
               elseif m1>1
                   m1bot=m1/2;
                   m1top=m1/2;
               else 
                   m1bot=0;
                   m1top=1;
               end
               
               if mod(m2, 2)~=0 && m2>1
                   m2right=ceil(m2/2);
                   m2left=m2-m2right;
               elseif m2>1
                   m2right=m2/2;
                   m2left=m2/2;
               else
                   m2right=0;
                   m2left=m2;
               end

               xk=[Xrect; Xrect+Arect; Xrect+Arect; Xrect];
               yk=[Yrect; Yrect; Yrect+Brect; Yrect+Brect];
                              
               
               x1bot=linspace(Xrect, Xrect+Arect, m1bot+2);
               deltax=x1bot(end)-x1bot(end-1);
               x1bot=x1bot(2:end-1);
               y1bot=Yrect*ones(length(x1bot), 1);
               
               y1right=linspace(Yrect, Yrect+Brect, m2right+2);
               deltay=y1right(end)-y1right(end-1);
               y1right=y1right(2:end-1);
               x1right=(Xrect+Arect)*ones(length(y1right), 1);
               
               x1top=linspace(Xrect+Arect, Xrect, m1top+2);
               x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
               
               y1left=linspace(Yrect+Brect, Yrect, m2left+2);
               y1left=y1left(2:end-1);
               x1left=Xrect*ones(length(y1left), 1);
               
               xk=[xk(1); x1bot'; xk(2); x1right; xk(3); x1top'; xk(4); x1left];
               yk=[yk(1); y1bot; yk(2); y1right'; yk(3); y1top; yk(4); y1left'];
               
               app.contourPoints(:, 3)=xk;
               app.contourPoints(:, 4)=yk;
               app.contourPoints(:, 6)=2;
               app.contourPoints(:, 7)=Inf;
               app.contourPoints(:, 8)=Xrect;
               app.contourPoints(:, 9)=Yrect; 
               app.contourPoints(:, 10)=Arect;
               app.contourPoints(:, 11)=Brect;
               app.contourPoints(:, 12)=0;
               app.contourPoints(:, 13)=0;
               ContactChecking(app);
                if app.checkall==1
                    uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                            'Ошибка', 'Modal', true)
                        app.contourPoints=[];
                        return
                end
               app.contourPoints=[];               
               app.checkall=0;   
                if isempty(app.PP.UserData) 
                    app.chargesnumber0.UserData(1, 2)=1;
                else
                    app.chargesnumber0.UserData(1, 2)=app.chargesnumber0.UserData(1, 2)+1;
                end               
          
               m=length(xk);
               
               app.PP.UserData(end+1:end+m, 3)=xk;
               app.PP.UserData(end-m+1:end, 4)=yk;
               
               Arect0=Arect;
               Brect0=Brect;
               Xrect0=Xrect;
               Yrect0=Yrect;
               Urect0=Urect;
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               if (Arect0-2*2*deltay)>0 && (Brect0-2*2*deltax)>0
                  Arect=Arect0-2*2*deltay;
                  Xrect=Xrect0+2*deltay;
                  Brect=Brect0-2*2*deltax;
                  Yrect=Yrect0+2*deltax;                  
               else
                  Arect=Arect0-2*min(Arect0, Brect0)/6;
                  Xrect=Xrect0+(Arect0-Arect)/2;                   
                  Brect=Brect0-2*min(Arect0, Brect0)/6;
                  Yrect=Yrect0+(Brect0-Brect)/2;
               end
               m=m-4;
               m1=m*Arect/(Arect+Brect);
               m1=ceil(m1);
               m2=m-m1;
               if mod(m1, 2)~=0 && m1>1
                   m1bot=ceil(m1/2);
                   m1top=m1-m1bot;
               elseif m1>1
                   m1bot=m1/2;
                   m1top=m1/2;
               else
                   m1bot=0;
                   m1top=1;
               end
               
               if mod(m2, 2)~=0 && m2>1
                   m2right=ceil(m2/2);
                   m2left=m2-m2right;
               elseif m2>1
                   m2right=m2/2;
                   m2left=m2/2;
               else
                   m2right=0;
                   m2left=m2;
               end
               
               x=[Xrect; Xrect+Arect; Xrect+Arect; Xrect];
               y=[Yrect; Yrect; Yrect+Brect; Yrect+Brect];
               
               x1bot=linspace(Xrect, Xrect+Arect, m1bot+2);
               x1bot=x1bot(2:end-1);
               y1bot=Yrect*ones(length(x1bot), 1);
               
               y1right=linspace(Yrect, Yrect+Brect, m2right+2);
               y1right=y1right(2:end-1);
               x1right=(Xrect+Arect)*ones(length(y1right), 1);
               
               x1top=linspace(Xrect+Arect, Xrect, m1top+2);
               x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
               
               y1left=linspace(Yrect+Brect, Yrect, m2left+2);
               y1left=y1left(2:end-1);
               x1left=Xrect*ones(length(y1left), 1);
               
               x=[x(1); x1bot'; x(2); x1right; x(3); x1top'; x(4); x1left];
               y=[y(1); y1bot; y(2); y1right'; y(3); y1top; y(4); y1left'];
               
               m=length(x);
               
               app.PP.UserData(end-m+1:end, 1)=x;
               app.PP.UserData(end-m+1:end, 2)=y;
               
                
%                m=1:app.chargesnumber.Value;
%                h=2*(Arect+Brect)/(app.chargesnumber.Value-1);
%                x_rect_bottom=Xrect:h:Xrect+Arect;
%                y_rect_bottom=Yrect*ones(1, length(x_rect_bottom));
%                y_rect_right=(Yrect+h):h:Yrect+Brect;
%                x_rect_right=(Xrect+Arect)*ones(1, length(y_rect_right));
%                x_rect_top=flip(x_rect_bottom(1:end-1));
%                y_rect_top=flip(y_rect_bottom(1:end-1))+Brect;
%                x_rect_left=Xrect*ones(1, length(y_rect_right)-1);
%                y_rect_left=flip(y_rect_right(1:end-1));
%                xk=[x_rect_bottom(:); x_rect_right(:); x_rect_top(:); x_rect_left(:)];
%                yk=[y_rect_bottom(:); y_rect_right(:); y_rect_top(:); y_rect_left(:)];
%                app.PP.UserData(end+1:end+length(xk), 3)=xk;
%                app.PP.UserData(end-length(yk)+1:end, 4)=yk;
%                
%                Xrect0=Xrect+min(Arect, Brect)/6;
%                Yrect0=Yrect+min(Arect, Brect)/6;
%                Arect0=Arect-2*min(Arect, Brect)/6;
%                Brect0=Brect-2*min(Arect, Brect)/6;
%                h=2*(Arect0+Brect0)/(app.chargesnumber.Value-1);
%         
%                x_rect_bottom=Xrect0:h:Xrect0+Arect0;
%                y_rect_bottom=Yrect0*ones(1, length(x_rect_bottom));
%                y_rect_right=(Yrect0+h):h:Yrect0+Brect0;
%                x_rect_right=(Xrect0+Arect0)*ones(1, length(y_rect_right));
%                x_rect_top=flip(x_rect_bottom(1:end-1));
%                y_rect_top=flip(y_rect_bottom(1:end-1))+Brect0;
%                x_rect_left=Xrect0*ones(1, length(y_rect_right)-1);
%                y_rect_left=flip(y_rect_right(1:end-1));
%         
%                x=[x_rect_bottom(:); x_rect_right(:); x_rect_top(:); x_rect_left(:)];
%                y=[y_rect_bottom(:); y_rect_right(:); y_rect_top(:); y_rect_left(:)];
%                if length(x)>length(xk)
%                x=x(1:length(xk));
%                y=y(1:length(yk));
%                else if length(x)<length(xk)
%                        xk=xk(1:length(x));
%                        yk=yk(1:length(y));
%                end
%                end
               
               app.PP.UserData(end-m+1:end, 5)=ones(m, 1)*Urect0;
               
               app.PP.UserData(end-m+1:end, 6)=ones(m, 1)*2;
               app.PP.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 2);
               app.PP.UserData(end-m+1:end, 8)=ones(m, 1)*Xrect0;
               app.PP.UserData(end-m+1:end, 9)=ones(m, 1)*Yrect0;
               app.PP.UserData(end-m+1:end, 10)=ones(m, 1)*Arect0;
               app.PP.UserData(end-m+1:end, 11)=ones(m, 1)*Brect0;
               app.PP.UserData(end-m+1:end, 12)=ones(m, 1)*nrect;
               app.PP.UserData(end-m+1:end, 13)=ones(m, 1)*0;               
               plot(app.arr, app.PP.UserData(end-m+1:end, 1), app.PP.UserData(end-m+1:end, 2), '*')
               plot(app.arr, app.PP.UserData(end-m+1:end, 3), app.PP.UserData(end-m+1:end, 4), '*')
               text(app.arr, Xrect0+Arect0/2, Yrect0+Brect0/2, num2str(app.chargesnumber0.UserData(1, 2)));
               maxValue=max(max(app.PP.UserData(:, 3)), max(app.PP.UserData(:, 4)));
               minValue=min(min(app.PP.UserData(:, 3)), min(app.PP.UserData(:, 4)));
               app.arr.XLim=[minValue, maxValue];
               app.arr.YLim=[minValue, maxValue];
               axis(app.arr, 'square')         
        end
    
        function [x, y, xk, yk] = makecylinder(app)
               if isempty(app.OS.UserData)
               app.chargesnumber0.UserData=zeros(1, 5);
               end
               cylinder=app.cylinder0;
               report=[];
               app.checkall=0;
               try
               Arect=eval(cylinder{1});
               Brect=eval(cylinder{2});
               Xrect=0;
               Yrect=eval(cylinder{3});
               Urect=eval(cylinder{4});
               nrect=eval(cylinder{5});
               array=[Arect; Brect; Xrect; Yrect; Urect; nrect];
               report=1;
               catch ME
                   report=getReport(ME);
               end
               
                if report~=1
                    uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;
                    return
                end
 
                if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                    uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end
                
                if Arect<=0 || Brect<=0 || nrect<=0 || mod(nrect, 1) || nrect<30
                    uialert(app.UIFigure, {'Радиус и высота цилиндра должны быть положительными числами.'; ...
                        'Число ЭЗ должно быть натуральным числом не менее 30.'}, ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end            

               m=nrect;
               m=m-4;
               m1=m*Arect/(Arect+Brect);
               m1=ceil(m1);
               m2=m-m1;
               if mod(m1, 2)~=0 && m1>1
                   m1bot=ceil(m1/2);
                   m1top=m1-m1bot;
               elseif m1>1
                   m1bot=m1/2;
                   m1top=m1/2;
               else
                   m1bot=0;
                   m1top=1;
               end
               
               m2right=m2;
               
               xk=[0; Xrect+Arect; Xrect+Arect; 0];
               yk=[Yrect; Yrect; Yrect+Brect; Yrect+Brect];
               
               x1bot=linspace(Xrect, Xrect+Arect, m1bot+2);
               deltax=x1bot(end)-x1bot(end-1);
               x1bot=x1bot(2:end-1);
               y1bot=Yrect*ones(length(x1bot), 1);
               
               y1right=linspace(Yrect, Yrect+Brect, m2right+2);
               deltay=y1right(end)-y1right(end-1);
               y1right=y1right(2:end-1);
               x1right=(Xrect+Arect)*ones(length(y1right), 1);
               
               x1top=linspace(Xrect+Arect, Xrect, m1top+2);
               x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
              
               
               xk=[xk(1); x1bot'; xk(2); x1right; xk(3); x1top'; xk(4)];
               yk=[yk(1); y1bot; yk(2); y1right'; yk(3); y1top; yk(4)];
               
               app.contourPoints(:, 3)=xk;
               app.contourPoints(:, 4)=yk;
               app.contourPoints(:, 6)=2;
               app.contourPoints(:, 7)=Inf;
               app.contourPoints(:, 8)=Xrect;
               app.contourPoints(:, 9)=Yrect; 
               app.contourPoints(:, 10)=Arect;
               app.contourPoints(:, 11)=Brect;
               app.contourPoints(:, 12)=0;
               app.contourPoints(:, 13)=0;            
               ContactChecking(app);
               if app.checkall==1
                    uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                            'Ошибка', 'Modal', true)
                        app.contourPoints=[];
                        return
               end
               app.contourPoints=[];                  
                                       
               if isempty(app.OS.UserData) 
                    app.chargesnumber0.UserData(1, 2)=1;
               else
                    app.chargesnumber0.UserData(1, 2)=app.chargesnumber0.UserData(1, 2)+1;
               end               
               app.checkall=0;
               m=length(xk);
               
               app.OS.UserData(end+1:end+m, 3)=xk;
               app.OS.UserData(end-m+1:end, 4)=yk;
               
               Arect0=Arect;
               Brect0=Brect;
               Xrect0=0;
               Yrect0=Yrect;
               Urect0=Urect;
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               if Arect0-2*deltay>0 && Brect0-2*2*deltax>0
                  Arect=Arect0-2*deltay;
                  Xrect=0;
                  Brect=Brect0-2*2*deltax;
                  Yrect=Yrect0+2*deltax;                  
               else
                  Arect=Arect0-2*min(Arect0, Brect0)/5;
                  Xrect=0;
                  Brect=Brect0-2*min(Arect0, Brect0)/5;
                  Yrect=Yrect0+(Brect0-Brect)/2;                  
               end
               m=m-4;
               m1=m*Arect/(Arect+Brect);
               m1=ceil(m1);
               m2=m-m1;
               if mod(m1, 2)~=0 && m1>1
                   m1bot=ceil(m1/2);
                   m1top=m1-m1bot;
               elseif m1>1
                   m1bot=m1/2;
                   m1top=m1/2;
               else
                   m1bot=0;
                   m1top=1;
               end
               
               m2right=m2;

               x=[Xrect; Xrect+Arect; Xrect+Arect; Xrect];
               y=[Yrect; Yrect; Yrect+Brect; Yrect+Brect];
               
               x1bot=linspace(Xrect, Xrect+Arect, m1bot+2);
               x1bot=x1bot(2:end-1);
               y1bot=Yrect*ones(length(x1bot), 1);
               
               y1right=linspace(Yrect, Yrect+Brect, m2right+2);
               y1right=y1right(2:end-1);
               x1right=(Xrect+Arect)*ones(length(y1right), 1);
               
               x1top=linspace(Xrect+Arect, Xrect, m1top+2);
               x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
               
               x=[x(1); x1bot'; x(2); x1right; x(3); x1top'; x(4)];
               y=[y(1); y1bot; y(2); y1right'; y(3); y1top; y(4)]; 
               
               m=length(x);
               
               app.OS.UserData(end-m+1:end, 1)=x;
               app.OS.UserData(end-m+1:end, 2)=y;
               
               
               app.OS.UserData(end-m+1:end, 5)=ones(m, 1)*Urect0;
               
               app.OS.UserData(end-m+1:end, 6)=ones(m, 1)*2;
               app.OS.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 2);
               app.OS.UserData(end-m+1:end, 8)=ones(m, 1)*Xrect0;
               app.OS.UserData(end-m+1:end, 9)=ones(m, 1)*Yrect0;
               app.OS.UserData(end-m+1:end, 10)=ones(m, 1)*Arect0;
               app.OS.UserData(end-m+1:end, 11)=ones(m, 1)*Brect0;
               app.OS.UserData(end-m+1:end, 12)=ones(m, 1)*nrect;
               app.OS.UserData(end-m+1:end, 13)=ones(m, 1)*0;
               
               plot(app.arr, app.OS.UserData(end-m+1:end, 1), app.OS.UserData(end-m+1:end, 2), '*')
               plot(app.arr, app.OS.UserData(end-m+1:end, 3), app.OS.UserData(end-m+1:end, 4), '*')
               text(app.arr, Xrect0+Arect0/2, Yrect0+Brect0/2, num2str(app.chargesnumber0.UserData(1, 2)));
               maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
               minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
               app.arr.XLim=[minValue, maxValue];
               app.arr.YLim=[minValue, maxValue];
               axis(app.arr, 'square') 


%                m=1:app.chargesnumber.Value;
%                h=(Brect+2*Arect)/(app.chargesnumber.Value-1);
%                x_rect_bottom=Xrect+h:h:Xrect+Arect;
%                y_rect_bottom=Yrect*ones(1, length(x_rect_bottom));
%                y_rect_right=(Yrect+h):h:Yrect+Brect;
%                x_rect_right=(Xrect+Arect)*ones(1, length(y_rect_right));
%                x_rect_top=flip(x_rect_bottom(1:end-1));
%                y_rect_top=flip(y_rect_bottom(1:end-1))+Brect;
%                xk=[x_rect_bottom(:); x_rect_right(:); x_rect_top(:)];
%                yk=[y_rect_bottom(:); y_rect_right(:); y_rect_top(:)];
%                app.OS.UserData(end+1:end+length(xk), 3)=xk;
%                app.OS.UserData(end-length(yk)+1:end, 4)=yk;
%                
%         
%                x=ones(length(xk), 1)*(Arect-2*h);
%                y=linspace(Yrect+h, Yrect+Brect-h, length(x));
% 
%                app.OS.UserData(end-length(x)+1:end, 1)=x;
%                app.OS.UserData(end-length(x)+1:end, 2)=y;
%                
%                app.OS.UserData(end-length(x)+1:end, 5)=ones(length(x), 1)*Urect;
%                
%                app.OS.UserData(end-length(x)+1:end, 6)=ones(length(x), 1)*2;
%                app.OS.UserData(end-length(x)+1:end, 7)=ones(length(x), 1)*app.chargesnumber.UserData(1, 2);
%                app.OS.UserData(end-length(x)+1:end, 8)=ones(length(x), 1)*Xrect;
%                app.OS.UserData(end-length(x)+1:end, 9)=ones(length(x), 1)*Yrect;
%                app.OS.UserData(end-length(x)+1:end, 10)=ones(length(x), 1)*Arect;
%                app.OS.UserData(end-length(x)+1:end, 11)=ones(length(x), 1)*Brect;
%                
%                plot(app.arr, app.OS.UserData(end-length(x)+1:end, 1), app.OS.UserData(end-length(y)+1:end, 2), '*')
%                plot(app.arr, app.OS.UserData(end-length(xk)+1:end, 3), app.OS.UserData(end-length(yk)+1:end, 4), '*')
%                text(app.arr, Xrect+Arect/2, Yrect+Brect/2, num2str(app.chargesnumber.UserData(1, 2)));
%                maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
%                minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
%                app.arr.XLim=[minValue, maxValue];
%                app.arr.YLim=[minValue, maxValue];
%                axis(app.arr, 'square')    
        end
    
        function [r, z, rk, zk] = makeball(app)
             if isempty(app.OS.UserData)
               app.chargesnumber0.UserData=zeros(1, 5);
             end 
            ball=app.ball0;
            report=[];
            app.checkall=0;
            try
            Rball=eval(ball{1});
            Zball=eval(ball{2});
            Uball=eval(ball{3});
            nball=eval(ball{4});
            array=[Rball; Zball; Uball; nball];
            report=1;
            catch ME
                report=getReport(ME);
            end
            
            if report~=1
                uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;
                return
            end
            
            if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами.', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end
            
            if Rball<=0 || nball<=0 || mod(nball, 1) || nball<10
                uialert(app.UIFigure, {'Радиуc шара должен быть положительным числом.'; 'Число ЭЗ должно быть натуральным числом не менее 10.'}, ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end            
            
            app.chargesnumber.Value=nball;
            m=1:app.chargesnumber.Value;
            H=(2*2*pi*Rball)/(app.chargesnumber.Value+2*2*pi);
            alfa1=linspace(-90, 90, app.chargesnumber.Value);
            alfa2=linspace(-90, 90, app.chargesnumber.Value);
            r=(Rball-H)*cos(alfa1);
            z=Zball+(Rball-H)*sin(alfa1);
            rk=Rball*cos(alfa1);
            zk=Zball+Rball*sin(alfa1);
            app.contourPoints(:, 3)=rk;
            app.contourPoints(:, 4)=zk;
            app.contourPoints(:, 6)=1;
            app.contourPoints(:, 7)=Inf;
            app.contourPoints(:, 8)=Rball;
            app.contourPoints(:, 9)=Zball; 
            app.contourPoints(:, 12)=Inf;          
            ContactChecking(app);
            if app.checkall==1
                uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                        'Ошибка', 'Modal', true)
                    app.contourPoints=[];
                    return
            end
            app.contourPoints=[];            
            
            app.checkall=0;            
            if isempty(app.OS.UserData)
                app.chargesnumber0.UserData(1, 1)=1;
            else
                app.chargesnumber0.UserData(1, 1)=app.chargesnumber0.UserData(1, 1)+1;
            end

            %alfa1=linspace(-90, 90, app.chargesnumber.Value);
            %alfa2=linspace(-90*0.9, 90*0.9, app.chargesnumber.Value);

            app.OS.UserData(end+1:end+app.chargesnumber.Value, 1)=(Rball-H)*cosd(alfa2);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 2)=Zball+(Rball-H)*sind(alfa2);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 3)=Rball*cosd(alfa1);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 4)=Zball+Rball*sind(alfa1);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 5)=ones(length(m), 1)*Uball;

            app.OS.UserData(end-app.chargesnumber.Value+1:end, 6)=ones(length(m), 1);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 7)=ones(length(m), 1)*app.chargesnumber0.UserData(1, 1);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 8)=ones(length(m), 1)*Rball;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 9)=ones(length(m), 1)*Zball;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 12)=ones(length(m), 1)*nball;
            plot(app.arr, app.OS.UserData(end-app.chargesnumber.Value+1:end, 1), app.OS.UserData(end-app.chargesnumber.Value+1:end, 2), '*')
            plot(app.arr, app.OS.UserData(end-app.chargesnumber.Value+1:end, 3), app.OS.UserData(end-app.chargesnumber.Value+1:end, 4), '*')
            text(app.arr, 0, Zball, num2str(app.chargesnumber0.UserData(1, 1)));
            maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
            minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
            app.arr.XLim=[minValue, maxValue];
            app.arr.YLim=[minValue, maxValue];
            axis(app.arr, 'square')            
        end
    
        function [x, y, xk, yk] = maketor(app)
            if isempty(app.OS.UserData)
                   app.chargesnumber0.UserData=zeros(1, 5);
            end  
            tor=app.tor0;
            report=[];
            app.checkall=0;
            try
            R1=eval(tor{1});
            h=eval(tor{2});
            Ztor=eval(tor{3});
            Utor=eval(tor{4});
            ntor=eval(tor{5});
            array=[R1; h; Ztor; Utor; ntor];
            report=1;
            catch ME
                report=getReport(ME);
            end
            
            if report~=1
                uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;
                return
            end
            
            if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end
            
            if R1<=0 || h<=0 || mod(ntor, 1) || ntor<10
                uialert(app.UIFigure, {'Внутренний радиус тора и его высота должны быть положительными числами.'; 'Число ЭЗ должно быть натуральным числом не менее 10.'}, ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end            
            
            app.chargesnumber.Value=ntor;
            m=1:app.chargesnumber.Value;
            alfa=2*pi/app.chargesnumber.Value;
            H=(2*2*pi*h/2)/(app.chargesnumber.Value+2*2*pi);
            Xcirc=R1+h/2;
            Rcirc=h/2;
            Ycirc=Ztor;
            
           xk=Xcirc+Rcirc*cos(alfa*(m-1));
           yk=Ycirc+Rcirc*sin(alfa*(m-1));
            
           app.contourPoints(:, 3)=xk;
           app.contourPoints(:, 4)=yk;
           app.contourPoints(:, 6)=3;
           app.contourPoints(:, 7)=Inf;
           app.contourPoints(:, 8)=R1;
           app.contourPoints(:, 9)=h; 
           app.contourPoints(:, 10)=Ztor; 
           app.contourPoints(:, 12)=0; 
           ContactChecking(app);
           if app.checkall==1
                uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                        'Ошибка', 'Modal', true)
                    app.contourPoints=[];
                    return
           end
            app.contourPoints=[];
            app.checkall=0;            
            if isempty(app.OS.UserData)
                app.chargesnumber0.UserData(1, 3)=1;
            else
                app.chargesnumber0.UserData(1, 3)=app.chargesnumber0.UserData(1, 3)+1;
            end            
            app.OS.UserData(end+1:end+app.chargesnumber.Value, 1)=Xcirc+(Rcirc-H)*cos(alfa*(m-1));
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 2)=Ycirc+(Rcirc-H)*sin(alfa*(m-1));
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 3)=Xcirc+Rcirc*cos(alfa*(m-1));
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 4)=Ycirc+Rcirc*sin(alfa*(m-1));
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 5)=ones(length(m), 1)*Utor;
            x=Xcirc+(Rcirc-H)*cos(alfa*(m-1));
            y=Ycirc+(Rcirc-H)*sin(alfa*(m-1));
            xk=Xcirc+Rcirc*cos(alfa*(m-1));
            yk=Ycirc+Rcirc*sin(alfa*(m-1));
            U=ones(length(m), 1)*Utor;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 6)=ones(length(m), 1)*3;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 7)=ones(length(m), 1)*app.chargesnumber0.UserData(1, 3);
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 8)=ones(length(m), 1)*R1;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 9)=ones(length(m), 1)*h;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 10)=ones(length(m), 1)*Ztor;
            app.OS.UserData(end-app.chargesnumber.Value+1:end, 12)=ones(length(m), 1)*ntor;
            plot(app.arr, app.OS.UserData(end-app.chargesnumber.Value+1:end, 1), app.OS.UserData(end-app.chargesnumber.Value+1:end, 2), '*')
            plot(app.arr, app.OS.UserData(end-app.chargesnumber.Value+1:end, 3), app.OS.UserData(end-app.chargesnumber.Value+1:end, 4), '*')
            text(app.arr, Xcirc, Ycirc, num2str(app.chargesnumber0.UserData(1, 3)));
            maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
            minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
            app.arr.XLim=[minValue, maxValue];
            app.arr.YLim=[minValue, maxValue];
            axis(app.arr, 'square')            
        end    
        
        function [x, y, xk, yk] = makekonus(app)
                   if isempty(app.OS.UserData)
                   app.chargesnumber0.UserData=zeros(1, 4);
                   end 
               konus=app.konus0;
               Arect=eval(konus{1});
               Brect=eval(konus{2});
               Xrect=0;
               Yrect=eval(konus{3});
               Urect=eval(konus{4});
               nrect=eval(konus{5});
                if isempty(app.OS.UserData) 
                    app.chargesnumber0.UserData(1, 4)=1;
                else
                    app.chargesnumber0.UserData(1, 4)=app.chargesnumber0.UserData(1, 4)+1;
                end
               app.chargesnumber.Value=nrect; 
               m=app.chargesnumber.Value;
               m=m-3;
               m1=m*Arect/(Arect+sqrt(Arect^2+Brect^2));
               m1=ceil(m1);
               m2=m-m1;

               xk=[Xrect; Xrect+Arect; Xrect];
               yk=[Yrect; Yrect; Yrect+Brect];
               
               x1bot=linspace(Xrect, Xrect+Arect, m1+2);
               deltax=x1bot(end)-x1bot(end-1); %%%%%%%%
               x1bot=x1bot(2:end-1);
               y1bot=Yrect*ones(length(x1bot), 1);
               
               x1right=linspace(Xrect, Xrect+Arect, m2+2);
               x1right=x1right(2:end-1);
               y1right=Yrect+(Arect-x1right)*(Brect/Arect);
               deltay=sqrt((x1right(end)-x1right(end-1))^2+(y1right(end)-y1right(end-1))^2); %%%%%%%%%%
                              
               xk=[xk; x1bot'; x1right'];
               yk=[yk; y1bot; y1right'];
               m=app.chargesnumber.Value;
               
               app.OS.UserData(end+1:end+m, 3)=xk;
               app.OS.UserData(end-m+1:end, 4)=yk;
               
               Arect0=Arect;
               Brect0=Brect;
               Xrect0=0;
               Yrect0=Yrect;
               Urect0=Urect;
               sinalfa=Brect0/sqrt(Brect0^2+Arect0^2);
               tanalfa=Brect0/Arect0;
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                if (Arect0-1*deltay/sinalfa-1*deltax/tanalfa-0.05*Arect0)>0 && (Brect0-1*deltax)>0
%                    Arect=Arect0-1*deltay/sinalfa-1*deltax/tanalfa-0.05*Arect0;
%                    Xrect=0.05*Arect0;
%                    Yrect=Yrect0+1*deltax;
%                    Brect=Arect*tanalfa;
%                else
                   Arect=Arect0-2*min(Arect0, Brect0)/15;
                   Xrect=0; %Xrect=Arect0/3-Arect/3;
                   Brect=Brect0-2*min(Arect0, Brect0)/15;
                   Yrect=Yrect0+Brect0/3-Brect/3;                   
%                  end

               m=m-2;
               m1=m*Arect/(Arect+sqrt(Arect^2+Brect^2));
               m1=ceil(m1);
               m2=m-m1;


               x1bot=linspace(Xrect, Arect0-(Yrect-Yrect0)*(cot(atan(Brect0/Arect0)/2)), m1+2);
               x1bot=x1bot(2:end-1);
               y1bot=Yrect*ones(length(x1bot), 1);
               
               x1right=linspace(Arect0-(Yrect-Yrect0)*(cot(atan(Brect0/Arect0)/2)), Xrect, m2+1);
               x1right=x1right(2:end);
               y1right=Yrect+(Arect0-(Yrect-Yrect0)*(cot(atan(Brect0/Arect0)/2))-x1right)*(Brect0/Arect0);
              
               x=[Xrect; Arect0-(Yrect-Yrect0)*(cot(atan(Brect0/Arect0)/2))];
               y=[Yrect; Yrect];
                            
               x=[x; x1bot'; x1right'];
               y=[y; y1bot; y1right'];
               
               m=app.chargesnumber.Value;
               
               app.OS.UserData(end-m+1:end, 1)=x;
               app.OS.UserData(end-m+1:end, 2)=y;
               
               
               app.OS.UserData(end-m+1:end, 5)=ones(m, 1)*Urect0;
               
               app.OS.UserData(end-m+1:end, 6)=ones(m, 1)*4;
               app.OS.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 4);
               app.OS.UserData(end-m+1:end, 8)=ones(m, 1)*Xrect0;
               app.OS.UserData(end-m+1:end, 9)=ones(m, 1)*Yrect0;
               app.OS.UserData(end-m+1:end, 10)=ones(m, 1)*Arect0;
               app.OS.UserData(end-m+1:end, 11)=ones(m, 1)*Brect0;
               app.OS.UserData(end-m+1:end, 12)=ones(m, 1)*nrect;
               
               plot(app.arr, app.OS.UserData(end-m+1:end, 1), app.OS.UserData(end-m+1:end, 2), '*')
               plot(app.arr, app.OS.UserData(end-m+1:end, 3), app.OS.UserData(end-m+1:end, 4), '*')
               text(app.arr, Xrect0+Arect0/2, Yrect0+Brect0/2-Brect0/6, num2str(app.chargesnumber0.UserData(1, 4)));
               maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
               minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
               app.arr.XLim=[minValue, maxValue];
               app.arr.YLim=[minValue, maxValue];
               axis(app.arr, 'square') 


%                m=1:app.chargesnumber.Value;
%                h=(Arect+sqrt(Arect^2+Brect^2))/(app.chargesnumber.Value-1);
%                x_rect_bottom=Xrect+h:h:Xrect+Arect;
%                y_rect_bottom=Yrect*ones(1, length(x_rect_bottom));
%                x_rect_right=Xrect+Arect-h:-h:Xrect+h;
%                y_rect_right=Yrect+(Arect-x_rect_right)*abs(tan(Brect/Arect));
%                xk=[x_rect_bottom(:); x_rect_right(:)];
%                yk=[y_rect_bottom(:); y_rect_right(:)];
%                app.OS.UserData(end+1:end+length(xk), 3)=xk;
%                app.OS.UserData(end-length(yk)+1:end, 4)=yk;
%                
%         
%                x=linspace(Arect-2*h, Xrect+h, length(xk));
%                y=Yrect+(-0.8*2*h+(Arect-x))*abs(tan(Brect/Arect));
% 
%                app.OS.UserData(end-length(x)+1:end, 1)=x;
%                app.OS.UserData(end-length(x)+1:end, 2)=y;
%                
%                app.OS.UserData(end-length(x)+1:end, 5)=ones(length(x), 1)*Urect;
%                
%                app.OS.UserData(end-length(x)+1:end, 6)=ones(length(x), 1)*4;
%                app.OS.UserData(end-length(x)+1:end, 7)=ones(length(x), 1)*app.chargesnumber.UserData(1, 4);
%                app.OS.UserData(end-length(x)+1:end, 8)=ones(length(x), 1)*Xrect;
%                app.OS.UserData(end-length(x)+1:end, 9)=ones(length(x), 1)*Yrect;
%                app.OS.UserData(end-length(x)+1:end, 10)=ones(length(x), 1)*Arect;
%                app.OS.UserData(end-length(x)+1:end, 11)=ones(length(x), 1)*Brect;
%                
%                plot(app.arr, app.OS.UserData(end-length(x)+1:end, 1), app.OS.UserData(end-length(y)+1:end, 2), '*')
%                plot(app.arr, app.OS.UserData(end-length(xk)+1:end, 3), app.OS.UserData(end-length(yk)+1:end, 4), '*')
%                text(app.arr, Xrect+Arect/2, (Arect-(Xrect+Arect/2))*abs(tan(Brect/Arect))+Yrect*0.9, num2str(app.chargesnumber.UserData(1, 4)));
%                maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
%                minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
%                app.arr.XLim=[minValue, maxValue];
%                app.arr.YLim=[minValue, maxValue];
%                axis(app.arr, 'square')              
        end
    
        function [] = maketorrect(app)
               if isempty(app.OS.UserData)
               app.chargesnumber0.UserData=zeros(1, 5);
               end 
               react=app.torrect0;
               report=[];
               app.checkall=0;
               try
               Arect=eval(react{1});
               Brect=eval(react{2});
               Xrect=eval(react{3});
               Yrect=eval(react{4});
               Urect=eval(react{5});
               nrect=eval(react{6});
               rrect=eval(react{7});
               array=[Arect; Brect; Xrect; Yrect; Urect; nrect; rrect];
               report=1;
               catch ME
                   report=getReport(ME);
               end
                
               if report~=1
                    uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;
                    return
                end
 
                if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                    uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами.', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end
                
                if Arect<=0 || Brect<=0 || Xrect<=0 || nrect<=0 || nrect>0.5 || rrect<0
                    uialert(app.UIFigure, {'Ширина и высота прямоугольника должны быть положительными числами.'; 'Абсцисса левого края прямоугольника должна быть положительным числом.'; ...
                        'Параметр h/l должен быть положительным числом не более 0,5.'; 'Радиус закругления должен быть положительным числом.'}, ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end            

                
               if rrect>min(Arect/2, Brect/2) 
                   uialert(app.UIFigure, 'Радиус закругления больше половины минимальной стороны прямоугольника.', 'Ошибка ввода', 'Modal', true)
                   app.checkall=1;
                   return
               end
               
               app.checkall=0;               


               Xc1=Xrect+rrect;
               Xc2=Xrect+Arect-rrect;
               Xc3=Xc2;
               Xc4=Xc1;
               Yc1=Yrect+rrect;
               Yc2=Yc1;
               Yc3=Yrect+Brect-rrect;
               Yc4=Yc3;
               r=rrect;
            
                m=1:ceil(pi/2/nrect); %Из отношения l/R=0.02;
                H=(4*pi*rrect)/(max(m)+4*pi); %из отношения h/L=2 
                alfa_c1=linspace(180, 270, max(m));
                alfa_c2=linspace(-90, 0, max(m));
                alfa_c3=linspace(0, 90, max(m));
                alfa_c4=linspace(90, 180, max(m));
            
               xk_c1=Xc1+r*cosd(alfa_c1);
               yk_c1=Yc1+r*sind(alfa_c1);
               
               xk_c2=Xc2+r*cosd(alfa_c2);
               yk_c2=Yc2+r*sind(alfa_c2);
               
               xk_c3=Xc3+r*cosd(alfa_c3);
               yk_c3=Yc3+r*sind(alfa_c3);
               
               xk_c4=Xc4+r*cosd(alfa_c4);
               yk_c4=Yc4+r*sind(alfa_c4);
               %%%%%%%%
               x_c1=Xc1+(r-H)*cosd(alfa_c1);
               y_c1=Yc1+(r-H)*sind(alfa_c1);
               
               x_c2=Xc2+(r-H)*cosd(alfa_c2);
               y_c2=Yc2+(r-H)*sind(alfa_c2);
               
               x_c3=Xc3+(r-H)*cosd(alfa_c3);
               y_c3=Yc3+(r-H)*sind(alfa_c3);
               
               x_c4=Xc4+(r-H)*cosd(alfa_c4);
               y_c4=Yc4+(r-H)*sind(alfa_c4);               
               
               lk=(pi*r/2)/length(xk_c1);
%                l=(pi*(r-H)/2)*length(x_c1); 
        
%                m=nrect;
%                m1=m*(Arect-2*r)/(Arect+Brect-4*r);
%                m1=ceil(m1);
%                m2=m-m1;
%                if mod(m1, 2)~=0 && m1>1
%                    m1bot=ceil(m1/2);
%                    m1top=m1-m1bot;
%                elseif m1>1
%                    m1bot=m1/2;
%                    m1top=m1/2;
%                else 
%                    m1bot=0;
%                    m1top=1;
%                end
%                
%                if mod(m2, 2)~=0 && m2>1
%                    m2right=ceil(m2/2);
%                    m2left=m2-m2right;
%                elseif m2>1
%                    m2right=m2/2;
%                    m2left=m2/2;
%                else
%                    m2right=0;
%                    m2left=m2;
%                end
               if (xk_c1(end)+lk)<=(xk_c2(1)-lk)
               x1bot=xk_c1(end)+lk:lk:xk_c2(1)-lk;
%              x1bot=x1bot(2:end-1);
               y1bot=(Yrect)*ones(length(x1bot), 1);
               else
                   x1bot=[];
                   y1bot=[];
               end
               
               if (yk_c2(end)+lk)<=(yk_c3(1)-lk)
               y2right=yk_c2(end)+lk:lk:yk_c3(1)-lk;
%              y2right=y2right(2:end-1);
               x2right=(Xrect+Arect)*ones(length(y2right), 1);
               else
                   y2right=[];
                   x2right=[];
               end
               
               if (xk_c4(1)+lk)<=(xk_c3(end)-lk)
               x1top=xk_c3(end)-lk:-lk:xk_c4(1)+lk;
%              x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
               else
                   x1top=[];
                   y1top=[];
               end
               
               if (yk_c1(1)+lk)<=(yk_c4(end)-lk)
               y2left=yk_c4(end)-lk:-lk:yk_c1(1)+lk;
%              y2left=y2left(2:end-1);
               x2left=(Xrect)*ones(length(y2left), 1);
               else
                   y2left=[];
                   x2left=[];
               end
               
               
               xk=[xk_c1'; x1bot(:); xk_c2'; x2right(:); xk_c3'; x1top(:);  xk_c4'; x2left(:)];
               yk=[yk_c1'; y1bot(:); yk_c2'; y2right(:); yk_c3'; y1top(:);  yk_c4'; y2left(:)];
               
               app.contourPoints(:, 3)=xk;
               app.contourPoints(:, 4)=yk;
               app.contourPoints(:, 6)=2;
               app.contourPoints(:, 7)=Inf;
               app.contourPoints(:, 8)=Xrect;
               app.contourPoints(:, 9)=Yrect; 
               app.contourPoints(:, 10)=Arect;
               app.contourPoints(:, 11)=Brect;
               app.contourPoints(:, 12)=Inf;
               app.contourPoints(:, 13)=rrect;           
               ContactChecking(app);
                if app.checkall==1
                    uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                            'Ошибка', 'Modal', true)
                        app.contourPoints=[];
                        return
                end
                app.contourPoints=[];                 
               
                plot(app.arr, [x1bot(:); x2right(:); x1top(:); x2left(:); xk_c1'; xk_c2'; xk_c3'; xk_c4'], [y1bot(:); y2right(:); y1top(:); y2left(:); yk_c1'; yk_c2'; yk_c3'; yk_c4'], '*')
               
                app.checkall=0;
                if isempty(app.OS.UserData) 
                    app.chargesnumber0.UserData(1, 5)=1;
                else
                    app.chargesnumber0.UserData(1, 5)=app.chargesnumber0.UserData(1, 5)+1;
                end               
               
%                m=nrect;
%                m1=m*(Arect-2*H)/(Arect+Brect-4*H);
%                m1=ceil(m1);
%                m2=m-m1;              
               
               x1bot=linspace(x_c1(end), x_c2(1), length(x1bot)+2);
               x1bot=x1bot(2:end-1);
               y1bot=(Yrect+H)*ones(length(x1bot), 1);
               
               y2right=linspace(y_c2(end), y_c3(1), length(y2right)+2);
               y2right=y2right(2:end-1);
               x2right=(Xrect+Arect-H)*ones(length(y2right), 1);
               
               x1top=linspace(x_c3(end), x_c4(1), length(x1top)+2);
               x1top=x1top(2:end-1);
               y1top=(Yrect+Brect-H)*ones(length(x1top), 1); 
               
               y2left=linspace(y_c4(end), y_c1(1), length(y2left)+2);
               y2left=y2left(2:end-1);
               x2left=(Xrect+H)*ones(length(y2left), 1);               
               
               
               plot(app.arr, [x1bot(:); x2right(:); x1top(:); x2left(:); x_c1'; x_c2'; x_c3'; x_c4'],...
                   [y1bot(:); y2right(:); y1top(:); y2left(:); y_c1'; y_c2'; y_c3'; y_c4'], '*')

               x=[x_c1'; x1bot(:); x_c2'; x2right(:); x_c3'; x1top(:);  x_c4'; x2left(:)];
               y=[y_c1'; y1bot(:); y_c2'; y2right(:); y_c3'; y1top(:);  y_c4'; y2left(:)];
                   
               m=length(xk);
               
               app.OS.UserData(end+1:end+m, 3)=xk;
               app.OS.UserData(end-m+1:end, 4)=yk;
               
               
               app.OS.UserData(end-m+1:end, 1)=x;
               app.OS.UserData(end-m+1:end, 2)=y;
               
               
               app.OS.UserData(end-m+1:end, 5)=ones(m, 1)*Urect;
               
               app.OS.UserData(end-m+1:end, 6)=ones(m, 1)*5;
               app.OS.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 5);
               app.OS.UserData(end-m+1:end, 8)=ones(m, 1)*Xrect;
               app.OS.UserData(end-m+1:end, 9)=ones(m, 1)*Yrect;
               app.OS.UserData(end-m+1:end, 10)=ones(m, 1)*Arect;
               app.OS.UserData(end-m+1:end, 11)=ones(m, 1)*Brect;
               app.OS.UserData(end-m+1:end, 12)=ones(m, 1)*length(unique([xk yk], 'rows'));
               app.OS.UserData(end-m+1:end, 13)=ones(m, 1)*rrect;
        
               app.OS.UserData=unique(app.OS.UserData,'stable', 'rows');
               text(app.arr, Xrect+Arect/2, Yrect+Brect/2, num2str(app.chargesnumber0.UserData(1, 5)));
               maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
               minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
               app.arr.XLim=[minValue, maxValue];
               app.arr.YLim=[minValue, maxValue];
               axis(app.arr, 'square')            
        end
    
    
        function [] = makekonus_mild(app)
                   if isempty(app.OS.UserData)
                   app.chargesnumber0.UserData=zeros(1, 5);
                   end 
                   konus=app.konus0;
                   report=[];
                   app.checkall=0;
                   try
                   A=eval(konus{1});
                   B=eval(konus{2});
                   X=0;
                   Y=eval(konus{3});
                   U=eval(konus{4});
                   R1=eval(konus{5});
                   R2=eval(konus{6});
                   array=[A; B; X; Y; U; R1; R2];
                   report=1;
                   catch ME
                       report=getReport(ME);
                   end
                   
                    if report~=1
                        uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                                'Ошибка', 'Modal', true)
                        app.checkall=1;
                        return
                    end
                    
                    if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                        uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                                'Ошибка', 'Modal', true)
                        app.checkall=1;    
                        return                
                    end
                    
                    if A<=0 || B<=0 || R1<=0 || R2<=0
                        uialert(app.UIFigure, {'Радиус конуса и его высота должны быть положительными числами.'; 'Радиусы скруглений углов конуса должны быть положительными числами.'}, ...
                                'Ошибка', 'Modal', true)
                        app.checkall=1;    
                        return                
                    end                     
                    
                    alfa=abs(atan(B/A)*180/pi);
                    if max(R1, R2)>A*tand(alfa/2)
                       uialert(app.UIFigure, 'Радиус закругления больше допустимого значения', 'Ошибка ввода', 'Modal', true)
                       app.checkall=1;  
                       return
                    end

                    phi1=linspace(-90, 90-alfa, ceil((pi-atan(B/A))/0.02));
                    x1=(A-R1*cotd(alfa/2))+R1*cosd(phi1);
                    y1=(Y+R1)+R1*sind(phi1);
                    l1=length(x1);
                    
                    phi2=linspace(90-alfa, 90, ceil(atan(B/A)/0.02));
                    x2=R2*cosd(phi2);
                    y2=(Y+B-(R2/cosd(alfa)))+R2*sind(phi2);
                    
                    lk1=0.02*R1;
                    lk2=0.02*R2;
                    lk=max(lk1, lk2);
                    if 0<=(x1(1)-lk1)
                       xbot=0:lk1:x1(1)-lk1;
                       ybot=Y*ones(1, length(xbot));
                    else
                       xbot=[];
                       ybot=[];
                    end
                    
                    if y2(1)>y1(end)
                       xright=x1(end):-lk:x2(1);
                       yright=(A-xright)*tand(alfa)+Y;
                    else
                       xright=[];
                       yright=[];
                    end                    
                   xk=[xbot'; x1'; xright'; x2'];
                   yk=[ybot'; y1'; yright'; y2'];
                   app.contourPoints(:, 3)=xk;
                   app.contourPoints(:, 4)=yk;
                   app.contourPoints(:, 6)=4;
                   app.contourPoints(:, 7)=Inf;
                   app.contourPoints(:, 8)=X;
                   app.contourPoints(:, 9)=Y; 
                   app.contourPoints(:, 10)=A;
                   app.contourPoints(:, 11)=B;
                   app.contourPoints(:, 12)=Inf;
                   app.contourPoints(:, 13)=R1;
                   app.contourPoints(:, 14)=R2;
                   ContactChecking(app);
                   if app.checkall==1
                        uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                                'Ошибка', 'Modal', true)
                            app.contourPoints=[];
                            return
                   end
                   app.contourPoints=[];
                   app.checkall=0;  
                    if isempty(app.OS.UserData) 
                        app.chargesnumber0.UserData(1, 4)=1;
                    else
                        app.chargesnumber0.UserData(1, 4)=app.chargesnumber0.UserData(1, 4)+1;
                    end                   
%%%%%%%%%%%%%%%%%%
                   htol=3;
                   Y0=Y;
                   A0=A;
                   B0=B;
                   R10=R1;
                   R20=R2;
                   A=A0-htol*lk1*cotd(alfa/2);
                   B=A*tand(alfa);
                   Y=Y0+htol*lk1;
                   alfa=atan(B/A)*180/pi;
                   R1=R1-htol*lk1;
                   R2=R2-htol*lk2;
%                    m1=m*A/(A+sqrt(A^2+B^2));
%                    m1=ceil(m1);
%                    m2=m-m1;
%                    alfa=atan(B/A)*180/pi;
%                    x1bot=linspace(X, A, m1);
%                    y1bot=ones(1, m1)*Y;
%    
%                    x1right=x1bot(end)-linspace(X, x1bot(end), m2);
%                    y1right=x1right*tand(alfa)+Y;
%                       
%                    x=[x1bot'; x1right'];
%                    y=[y1bot'; (fliplr(y1right))'];
                    phi1=[];
                    phi1=linspace(-90, 90-alfa, length(x1));
                    x1=[]; y1=[];
                    x1=(A-R1*cotd(alfa/2))+R1*cosd(phi1);
                    y1=(Y+R1)+R1*sind(phi1);
                    
                    phi2=[];
                    phi2=linspace(90-alfa, 90, length(x2));
                    x2=[]; y2=[];
                    x2=R2*cosd(phi2);
                    y2=(Y+B-(R2/cosd(alfa)))+R2*sind(phi2);
                    
                    xbot=[];
                    xbot=linspace(0, x1(1), length(ybot)+1);
                    xbot=xbot(1:end-1);
                    ybot=[];
                    ybot=Y*ones(1, length(xbot));         
                    if y2(1)>y1(end)
                       xright=[];
                       xright=linspace(x1(end), x2(1), length(yright));
                       yright=[];
                       yright=(A-xright)*tand(alfa)+Y;
                    else
                       xright=[];
                       yright=[];
                    end                    
                    x=[x1'; x2'; xbot'; xright'];
                    y=[y1'; y2'; ybot'; yright'];
                    m=length(xk);
                    if length(x)>m
                        x=x(1:end-(length(x)-m));
                        y=y(1:end-(length(x)-m));
                    else if length(x)<m
                        xk=xk(1:end-(m-length(x)));
                        yk=xk(1:end-(m-length(x)));
                    end
                    end
                    app.OS.UserData(end+1:end+m, 3)=xk;
                    app.OS.UserData(end-m+1:end, 4)=yk;      
                    app.OS.UserData(end-m+1:end, 1)=x;
                    app.OS.UserData(end-m+1:end, 2)=y;                
%%%%%%%%%%%%%%%%%%
                   
                   app.OS.UserData(end-m+1:end, 5)=ones(m, 1)*U;
                   
                   app.OS.UserData(end-m+1:end, 6)=ones(m, 1)*4;
                   app.OS.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 4);
                   app.OS.UserData(end-m+1:end, 8)=ones(m, 1)*X;
                   app.OS.UserData(end-m+1:end, 9)=ones(m, 1)*Y0;
                   app.OS.UserData(end-m+1:end, 10)=ones(m, 1)*A0;
                   app.OS.UserData(end-m+1:end, 11)=ones(m, 1)*B0;
                   app.OS.UserData(end-m+1:end, 12)=ones(m, 1)*m;
                   app.OS.UserData(end-m+1:end, 13)=ones(m, 1)*R10;
                   app.OS.UserData(end-m+1:end, 14)=ones(m, 1)*R20;
                    
                   plot(app.arr, app.OS.UserData(end-m+1:end, 1), app.OS.UserData(end-m+1:end, 2), '*')
                   plot(app.arr, app.OS.UserData(end-m+1:end, 3), app.OS.UserData(end-m+1:end, 4), '*')
                   text(app.arr, X+A0/2, Y0+B0/2-B0/6, num2str(app.chargesnumber0.UserData(1, 4)));
                   maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
                   minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
                   app.arr.XLim=[minValue, maxValue];
                   app.arr.YLim=[minValue, maxValue];
                   axis(app.arr, 'square') 
        
        end


        function [x, y, xk, yk] = makeellips(app)
             if isempty(app.PP.UserData)
               app.chargesnumber0.UserData=zeros(1, 3);
             end 
            ellips=app.ellips0;
            report=[];
            app.checkall=0;
            try
            Aell=eval(ellips{1});
            Bell=eval(ellips{2});
            Xell=eval(ellips{3});
            Yell=eval(ellips{4});
            Uell=eval(ellips{5});
            nell=eval(ellips{6});
            array=[Aell; Bell; Xell; Yell; Uell; nell];
            report=1;
            catch ME
                report=getReport(ME);
            end
            
            if report~=1
                uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;
                return
            end
            
            if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end
            
            if Aell<=0 || Bell<=0 || Yell<=Bell || nell<=0 || mod(nell, 1) || nell<10
                uialert(app.UIFigure, {'Длины полуосей эллипса должны быть положительными числами.'; ...
                    'Ордината центра элллипса должна быть больше длины соответствующей полуоси.'; 'Число ЭЗ должно быть натуральным числом не менее 10.'}, ...
                        'Ошибка', 'Modal', true)
                app.checkall=1;    
                return                
            end 
            
            app.chargesnumber.Value=nell;
            m=1:app.chargesnumber.Value;
            alfa=2*pi/app.chargesnumber.Value;
            H1=(2*2*pi*Aell)/(app.chargesnumber.Value+2*2*pi);
            H2=(2*2*pi*Bell)/(app.chargesnumber.Value+2*2*pi);            
            x=Xell+(Aell-H1)*cos(alfa*(m-1));
            y=Yell+(Bell-H2)*sin(alfa*(m-1)); 
            xk=Xell+Aell*cos(alfa*(m-1));
            yk=Yell+Bell*sin(alfa*(m-1));                        
            app.contourPoints(:, 3)=xk;
            app.contourPoints(:, 4)=yk;
            app.contourPoints(:, 6)=3;
            app.contourPoints(:, 7)=Inf;
            app.contourPoints(:, 8)=Xell;
            app.contourPoints(:, 9)=Yell; 
            app.contourPoints(:, 10)=Aell;
            app.contourPoints(:, 11)=Bell;
            app.contourPoints(:, 12)=nell;
            ContactChecking(app);
            if app.checkall==1
                uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                        'Ошибка', 'Modal', true)
                    app.contourPoints=[];
                    return
            end
            app.contourPoints=[];            
            app.checkall=0;
            if isempty(app.PP.UserData)
                app.chargesnumber0.UserData(1, 3)=1;
            else
                app.chargesnumber0.UserData(1, 3)=app.chargesnumber0.UserData(1, 3)+1;
            end

            app.PP.UserData(end+1:end+app.chargesnumber.Value, 1)=Xell+(Aell-H1)*cos(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 2)=Yell+(Bell-H2)*sin(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 3)=Xell+Aell*cos(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 4)=Yell+Bell*sin(alfa*(m-1));
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 5)=ones(length(m), 1)*Uell;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 6)=ones(length(m), 1)*3;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 7)=ones(length(m), 1)*app.chargesnumber0.UserData(1, 3);
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 8)=ones(length(m), 1)*Xell;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 9)=ones(length(m), 1)*Yell;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 10)=ones(length(m), 1)*Aell;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 11)=ones(length(m), 1)*Bell;
            app.PP.UserData(end-app.chargesnumber.Value+1:end, 12)=ones(length(m), 1)*nell;
            plot(app.arr, app.PP.UserData(end-app.chargesnumber.Value+1:end, 1), app.PP.UserData(end-app.chargesnumber.Value+1:end, 2), '*')
            plot(app.arr, app.PP.UserData(end-app.chargesnumber.Value+1:end, 3), app.PP.UserData(end-app.chargesnumber.Value+1:end, 4), '*')
            text(app.arr, Xell, Yell, num2str(app.chargesnumber0.UserData(1, 3)));
            maxValue=max(max(app.PP.UserData(:, 3)), max(app.PP.UserData(:, 4)));
            minValue=min(min(app.PP.UserData(:, 3)), min(app.PP.UserData(:, 4)));
            app.arr.XLim=[minValue, maxValue];
            app.arr.YLim=[minValue, maxValue];             
        end   

        function [x, y, xk, yk] = makereact_mild(app)
               if isempty(app.PP.UserData)
               app.chargesnumber0.UserData=zeros(1, 3);
               end 
               react=app.react0;
               report=[];
               app.checkall=0;
               try
               Arect=eval(react{1});
               Brect=eval(react{2});
               Xrect=eval(react{3});
               Yrect=eval(react{4});
               Urect=eval(react{5});
               nrect=eval(react{6});
               rrect=eval(react{7});
               array=[Arect; Brect; Xrect; Yrect; Urect; nrect; rrect];
               report=1;
               catch ME
                   report=getReport(ME);
               end
                
               if report~=1
                    uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;
                    return
                end
 
                if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                    uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end
                
                if Arect<=0 || Brect<=0 || Yrect<=0 || nrect<=0 || nrect>0.5 || rrect<0
                    uialert(app.UIFigure, {'Ширина и высота прямоугольника должны быть положительными числами.'; 'Ордината нижнего края прямоугольника должна быть положительным числом.'; ...
                        'Параметр h/l должен быть положительным числом не более 0,5.'; 'Радиус закругления должен быть положительным числом.'}, ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end            

                
               if rrect>min(Arect/2, Brect/2) 
                   uialert(app.UIFigure, 'Радиус закругления больше половины минимальной стороны прямоугольника', 'Ошибка ввода', 'Modal', true)
                   app.checkall=1;
                   return
               end
               
               app.checkall=0;               


               Xc1=Xrect+rrect;
               Xc2=Xrect+Arect-rrect;
               Xc3=Xc2;
               Xc4=Xc1;
               Yc1=Yrect+rrect;
               Yc2=Yc1;
               Yc3=Yrect+Brect-rrect;
               Yc4=Yc3;
               r=rrect;
            
                m=1:ceil(pi/2/nrect); %Из отношения l/R=0.02;
                H=(4*pi*rrect)/(max(m)+4*pi); %из отношения h/L=2 
                alfa_c1=linspace(180, 270, max(m));
                alfa_c2=linspace(-90, 0, max(m));
                alfa_c3=linspace(0, 90, max(m));
                alfa_c4=linspace(90, 180, max(m));
            
               xk_c1=Xc1+r*cosd(alfa_c1);
               yk_c1=Yc1+r*sind(alfa_c1);
               
               xk_c2=Xc2+r*cosd(alfa_c2);
               yk_c2=Yc2+r*sind(alfa_c2);
               
               xk_c3=Xc3+r*cosd(alfa_c3);
               yk_c3=Yc3+r*sind(alfa_c3);
               
               xk_c4=Xc4+r*cosd(alfa_c4);
               yk_c4=Yc4+r*sind(alfa_c4);
               %%%%%%%%
               x_c1=Xc1+(r-H)*cosd(alfa_c1);
               y_c1=Yc1+(r-H)*sind(alfa_c1);
               
               x_c2=Xc2+(r-H)*cosd(alfa_c2);
               y_c2=Yc2+(r-H)*sind(alfa_c2);
               
               x_c3=Xc3+(r-H)*cosd(alfa_c3);
               y_c3=Yc3+(r-H)*sind(alfa_c3);
               
               x_c4=Xc4+(r-H)*cosd(alfa_c4);
               y_c4=Yc4+(r-H)*sind(alfa_c4);               
               
               lk=(pi*r/2)/length(xk_c1);
%                l=(pi*(r-H)/2)*length(x_c1); 
        
%                m=nrect;
%                m1=m*(Arect-2*r)/(Arect+Brect-4*r);
%                m1=ceil(m1);
%                m2=m-m1;
%                if mod(m1, 2)~=0 && m1>1
%                    m1bot=ceil(m1/2);
%                    m1top=m1-m1bot;
%                elseif m1>1
%                    m1bot=m1/2;
%                    m1top=m1/2;
%                else 
%                    m1bot=0;
%                    m1top=1;
%                end
%                
%                if mod(m2, 2)~=0 && m2>1
%                    m2right=ceil(m2/2);
%                    m2left=m2-m2right;
%                elseif m2>1
%                    m2right=m2/2;
%                    m2left=m2/2;
%                else
%                    m2right=0;
%                    m2left=m2;
%                end
               if (xk_c1(end)+lk)<=(xk_c2(1)-lk)
               x1bot=xk_c1(end)+lk:lk:xk_c2(1)-lk;
%              x1bot=x1bot(2:end-1);
               y1bot=(Yrect)*ones(length(x1bot), 1);
               else
                   x1bot=[];
                   y1bot=[];
               end
               
               if (yk_c2(end)+lk)<=(yk_c3(1)-lk)
               y2right=yk_c2(end)+lk:lk:yk_c3(1)-lk;
%              y2right=y2right(2:end-1);
               x2right=(Xrect+Arect)*ones(length(y2right), 1);
               else
                   y2right=[];
                   x2right=[];
               end
               
               if (xk_c4(1)+lk)<=(xk_c3(end)-lk)
               x1top=xk_c3(end)-lk:-lk:xk_c4(1)+lk;
%              x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
               else
                   x1top=[];
                   y1top=[];
               end
               
               if (yk_c1(1)+lk)<=(yk_c4(end)-lk)
               y2left=yk_c4(end)-lk:-lk:yk_c1(1)+lk;
%              y2left=y2left(2:end-1);
               x2left=(Xrect)*ones(length(y2left), 1);
               else
                   y2left=[];
                   x2left=[];
               end
               
               
               xk=[xk_c1'; x1bot(:); xk_c2'; x2right(:); xk_c3'; x1top(:);  xk_c4'; x2left(:)];
               yk=[yk_c1'; y1bot(:); yk_c2'; y2right(:); yk_c3'; y1top(:);  yk_c4'; y2left(:)];
               
               app.contourPoints(:, 3)=xk;
               app.contourPoints(:, 4)=yk;
               app.contourPoints(:, 6)=2;
               app.contourPoints(:, 7)=Inf;
               app.contourPoints(:, 8)=Xrect;
               app.contourPoints(:, 9)=Yrect; 
               app.contourPoints(:, 10)=Arect;
               app.contourPoints(:, 11)=Brect;
               app.contourPoints(:, 12)=Inf;
               app.contourPoints(:, 13)=rrect;           
                ContactChecking(app);
                if app.checkall==1
                    uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                            'Ошибка', 'Modal', true)
                        app.contourPoints=[];
                        return
                end
                app.contourPoints=[];                 
               
                plot(app.arr, [x1bot(:); x2right(:); x1top(:); x2left(:); xk_c1'; xk_c2'; xk_c3'; xk_c4'], [y1bot(:); y2right(:); y1top(:); y2left(:); yk_c1'; yk_c2'; yk_c3'; yk_c4'], '*')
               
                app.checkall=0;
                if isempty(app.PP.UserData) 
                    app.chargesnumber0.UserData(1, 2)=1;
                else
                    app.chargesnumber0.UserData(1, 2)=app.chargesnumber0.UserData(1, 2)+1;
                end               
               
%                m=nrect;
%                m1=m*(Arect-2*H)/(Arect+Brect-4*H);
%                m1=ceil(m1);
%                m2=m-m1;              
               
               x1bot=linspace(x_c1(end), x_c2(1), length(x1bot)+2);
               x1bot=x1bot(2:end-1);
               y1bot=(Yrect+H)*ones(length(x1bot), 1);
               
               y2right=linspace(y_c2(end), y_c3(1), length(y2right)+2);
               y2right=y2right(2:end-1);
               x2right=(Xrect+Arect-H)*ones(length(y2right), 1);
               
               x1top=linspace(x_c3(end), x_c4(1), length(x1top)+2);
               x1top=x1top(2:end-1);
               y1top=(Yrect+Brect-H)*ones(length(x1top), 1); 
               
               y2left=linspace(y_c4(end), y_c1(1), length(y2left)+2);
               y2left=y2left(2:end-1);
               x2left=(Xrect+H)*ones(length(y2left), 1);               
               
               
               plot(app.arr, [x1bot(:); x2right(:); x1top(:); x2left(:); x_c1'; x_c2'; x_c3'; x_c4'],...
                   [y1bot(:); y2right(:); y1top(:); y2left(:); y_c1'; y_c2'; y_c3'; y_c4'], '*')

               x=[x_c1'; x1bot(:); x_c2'; x2right(:); x_c3'; x1top(:);  x_c4'; x2left(:)];
               y=[y_c1'; y1bot(:); y_c2'; y2right(:); y_c3'; y1top(:);  y_c4'; y2left(:)];
                   
               m=length(xk);
               
               app.PP.UserData(end+1:end+m, 3)=xk;
               app.PP.UserData(end-m+1:end, 4)=yk;
               
               
               app.PP.UserData(end-m+1:end, 1)=x;
               app.PP.UserData(end-m+1:end, 2)=y;
               
               
               app.PP.UserData(end-m+1:end, 5)=ones(m, 1)*Urect;
               
               app.PP.UserData(end-m+1:end, 6)=ones(m, 1)*2;
               app.PP.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 2);
               app.PP.UserData(end-m+1:end, 8)=ones(m, 1)*Xrect;
               app.PP.UserData(end-m+1:end, 9)=ones(m, 1)*Yrect;
               app.PP.UserData(end-m+1:end, 10)=ones(m, 1)*Arect;
               app.PP.UserData(end-m+1:end, 11)=ones(m, 1)*Brect;
               app.PP.UserData(end-m+1:end, 12)=ones(m, 1)*length(unique([xk yk], 'rows'));
               app.PP.UserData(end-m+1:end, 13)=ones(m, 1)*rrect;
        
               app.PP.UserData=unique(app.PP.UserData,'stable', 'rows');
               text(app.arr, Xrect+Arect/2, Yrect+Brect/2, num2str(app.chargesnumber0.UserData(1, 2)));
               maxValue=max(max(app.PP.UserData(:, 3)), max(app.PP.UserData(:, 4)));
               minValue=min(min(app.PP.UserData(:, 3)), min(app.PP.UserData(:, 4)));
               app.arr.XLim=[minValue, maxValue];
               app.arr.YLim=[minValue, maxValue];
               axis(app.arr, 'square')
        end
    
        function [] = makecylinder_mild(app)
               if isempty(app.OS.UserData)
               app.chargesnumber0.UserData=zeros(1, 5);
               end
               cylinder=app.cylinder0;
               report=[];
               app.checkall=0;
               try
               Arect=eval(cylinder{1});
               Brect=eval(cylinder{2});
               Xrect=0;
               Yrect=eval(cylinder{3});
               Urect=eval(cylinder{4});
               nrect=eval(cylinder{5});
               rrect=eval(cylinder{6});
               array=[Arect; Brect; Xrect; Yrect; Urect; nrect; rrect];
               report=1;
               catch ME
                   report=getReport(ME);
               end
               
               if report~=1
                    uialert(app.UIFigure, 'Обнаружены ошибки в введённых данных.', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;
                    return
                end
 
                if any(arrayfun(@imag, array)) || any(arrayfun(@isnan, array)) || any(arrayfun(@isinf, array)) 
                    uialert(app.UIFigure, 'Параметры должны быть действительными конечными числами', ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end
                
                if Arect<=0 || Brect<=0 || nrect<=0 || nrect>0.5 || rrect<0
                    uialert(app.UIFigure, {'Радиус и высота цилиндра должны быть положительными числами.'; ...
                        'Параметр h/l должен быть положительным числом не более 0,5.'; 'Радиус закругления должен быть положительным числом.'}, ...
                            'Ошибка', 'Modal', true)
                    app.checkall=1;    
                    return                
                end            
                
               if rrect>min(Arect, Brect/2) 
                   uialert(app.UIFigure, 'Радиус закругления больше половины минимальной стороны цилиндра', 'Ошибка ввода', 'Modal', true)
                   app.checkall=1;
                   return
               end
               

               Xc1=Xrect+Arect-rrect;
               Xc2=Xc1;
               Yc1=Yrect+rrect;
               Yc2=Yrect+Brect-rrect;
               r=rrect;
            
               m=1:ceil(pi/2/nrect); %Из отношения l/R=0.02;
               H=(4*pi*rrect)/(max(m)+4*pi); %из отношения h/L=2 
               alfa_c1=linspace(-90, 0, max(m));
               alfa_c2=linspace(0, 90, max(m));
            
               xk_c1=Xc1+r*cosd(alfa_c1);
               yk_c1=Yc1+r*sind(alfa_c1);
               
               xk_c2=Xc2+r*cosd(alfa_c2);
               yk_c2=Yc2+r*sind(alfa_c2);
               
               %%%%%%%%
               x_c1=Xc1+(r-H)*cosd(alfa_c1);
               y_c1=Yc1+(r-H)*sind(alfa_c1);
               
               x_c2=Xc2+(r-H)*cosd(alfa_c2);
               y_c2=Yc2+(r-H)*sind(alfa_c2);
 
               lk=(pi*r/2)/length(xk_c1);
%                l=(pi*(r-H)/2)*length(x_c1); 
        
%                m=nrect;
%                m1=m*(Arect-2*r)/(Arect+Brect-4*r);
%                m1=ceil(m1);
%                m2=m-m1;
%                if mod(m1, 2)~=0 && m1>1
%                    m1bot=ceil(m1/2);
%                    m1top=m1-m1bot;
%                elseif m1>1
%                    m1bot=m1/2;
%                    m1top=m1/2;
%                else 
%                    m1bot=0;
%                    m1top=1;
%                end
%                
%                if mod(m2, 2)~=0 && m2>1
%                    m2right=ceil(m2/2);
%                    m2left=m2-m2right;
%                elseif m2>1
%                    m2right=m2/2;
%                    m2left=m2/2;
%                else
%                    m2right=0;
%                    m2left=m2;
%                end
               if 0<=(xk_c1(1)-lk)
               x1bot=0:lk:xk_c1(1)-lk;
%              x1bot=x1bot(2:end-1);
               y1bot=(Yrect)*ones(length(x1bot), 1);
               else
                   x1bot=[];
                   y1bot=[];
               end
               
               if (yk_c1(end)+lk)<=(yk_c2(1)-lk)
               y2right=yk_c1(end)+lk:lk:yk_c2(1)-lk;
%              y2right=y2right(2:end-1);
               x2right=(Xrect+Arect)*ones(length(y2right), 1);
               else
                   y2right=[];
                   x2right=[];
               end
               
               if 0<=(xk_c2(end)-lk)
               x1top=xk_c2(end)-lk:-lk:0;
%              x1top=x1top(2:end-1);
               y1top=(Yrect+Brect)*ones(length(x1top), 1);
               else
                   x1top=[];
                   y1top=[];
               end
               
               if (Yrect+lk)<=(yk_c2(end)-lk) %%%%%
               y2left=yk_c2(end)-lk:Yrect+lk;
%              y2left=y2left(2:end-1);
               x2left=(Xrect)*ones(length(y2left), 1);
               else
                   y2left=[];
                   x2left=[];
               end
               
               
               xk=[x1bot(:); xk_c1'; x2right(:); xk_c2'; x1top(:); x2left(:)]; 
               yk=[y1bot(:); yk_c1'; y2right(:); yk_c2'; y1top(:); y2left(:)];
               
               app.contourPoints(:, 3)=xk;
               app.contourPoints(:, 4)=yk;
               app.contourPoints(:, 6)=2;
               app.contourPoints(:, 7)=Inf;
               app.contourPoints(:, 8)=Xrect;
               app.contourPoints(:, 9)=Yrect; 
               app.contourPoints(:, 10)=Arect;
               app.contourPoints(:, 11)=Brect;
               app.contourPoints(:, 12)=0;
               app.contourPoints(:, 13)=rrect;              
               ContactChecking(app);
               if app.checkall==1
                    uialert(app.UIFigure, 'Электроды не должны иметь точек пересечения или быть вложенными друг в друга.', ...
                            'Ошибка', 'Modal', true)
                        app.contourPoints=[];
                        return
               end
               app.contourPoints=[];              
               
               app.checkall=0;                 
               
                if isempty(app.OS.UserData) 
                    app.chargesnumber0.UserData(1, 2)=1;
                else
                    app.chargesnumber0.UserData(1, 2)=app.chargesnumber0.UserData(1, 2)+1;
                end 
               plot(app.arr, [x1bot(:); x2right(:); x1top(:); x2left(:); xk_c1'; xk_c2'], [y1bot(:); y2right(:); y1top(:); y2left(:); yk_c1'; yk_c2'], '*')
%                m=nrect;
%                m1=m*(Arect-2*H)/(Arect+Brect-4*H);
%                m1=ceil(m1);
%                m2=m-m1;              
               
               x1bot=linspace(0, x_c1(1), length(x1bot)+1);
               x1bot=x1bot(1:end-1);
               y1bot=(Yrect+H)*ones(length(x1bot), 1);
               
               y2right=linspace(y_c1(end), y_c2(1), length(y2right)+2);
               y2right=y2right(2:end-1);
               x2right=(Xrect+Arect-H)*ones(length(y2right), 1);
               
               x1top=linspace(x_c2(end), 0, length(x1top)+1);
               x1top=x1top(2:end);
               y1top=(Yrect+Brect-H)*ones(length(x1top), 1); 
               
               y2left=linspace(Yrect+Brect-H, Yrect+H, length(y2left)+2);
               y2left=y2left(2:end-1);
               x2left=0*ones(length(y2left), 1);               
               
               
              plot(app.arr, [x1bot(:); x2right(:); x1top(:); x2left(:); x_c1'; x_c2'], [y1bot(:); y2right(:); y1top(:); y2left(:); y_c1'; y_c2'], '*')

               x=[x1bot(:); x_c1'; x2right(:); x_c2'; x1top(:); x2left(:)]; 
               y=[y1bot(:); y_c1'; y2right(:); y_c2'; y1top(:); y2left(:)];
                   
               m=length(xk);
               
               app.OS.UserData(end+1:end+m, 3)=xk;
               app.OS.UserData(end-m+1:end, 4)=yk;
               
               
               app.OS.UserData(end-m+1:end, 1)=x;
               app.OS.UserData(end-m+1:end, 2)=y;
               
               
               app.OS.UserData(end-m+1:end, 5)=ones(m, 1)*Urect;
               
               app.OS.UserData(end-m+1:end, 6)=ones(m, 1)*2;
               app.OS.UserData(end-m+1:end, 7)=ones(m, 1)*app.chargesnumber0.UserData(1, 2);
               app.OS.UserData(end-m+1:end, 8)=ones(m, 1)*Xrect;
               app.OS.UserData(end-m+1:end, 9)=ones(m, 1)*Yrect;
               app.OS.UserData(end-m+1:end, 10)=ones(m, 1)*Arect;
               app.OS.UserData(end-m+1:end, 11)=ones(m, 1)*Brect;
               app.OS.UserData(end-m+1:end, 12)=ones(m, 1)*length(unique([xk yk], 'rows'));
               app.OS.UserData(end-m+1:end, 13)=ones(m, 1)*rrect;
        
               app.OS.UserData=unique(app.OS.UserData, 'stable', 'rows');
               text(app.arr, Xrect+Arect/2, Yrect+Brect/2, num2str(app.chargesnumber0.UserData(1, 2)));
               maxValue=max(max(app.OS.UserData(:, 3)), max(app.OS.UserData(:, 4)));
               minValue=min(min(app.OS.UserData(:, 3)), min(app.OS.UserData(:, 4)));
               app.arr.XLim=[minValue, maxValue];
               app.arr.YLim=[minValue, maxValue];
               axis(app.arr, 'square')              
        end
        

        function [] = drawingPP(app)
            f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется подготовка к построению графиков', 'Cancelable', 'on', ...
               'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');
            numberOfChargedAxes=size(app.PP.UserData, 1);
            xk=app.PP.UserData(:, 3);
            yk=app.PP.UserData(:, 4);
            x=app.PP.UserData(:, 1);
            y=app.PP.UserData(:, 2);
            epsilon=app.changeepsilon.Value;
            constValue=2*pi*8.85e-12*epsilon;
            tau=app.charges;
            E=app.Eelectr;
             
            if isempty(app.areatodraw) || isequal(app.choosearea.Value, 'Границы по умолчанию')
                
            Gax=1/3*min(xk); Gay=0;
            if max(xk)>max(yk)
                delta=max(xk);
                Gbx=1.5*delta;
                Gby=Gay+(Gbx-Gax);
            else
                delta=max(yk);
                Gby=1.2*delta;
                Gbx=Gax+(Gby-Gay);
            end
            line=app.numberoflines.Value;
            xx=linspace(Gax, max(Gbx, Gby), line); yy=linspace(0, max(Gby, Gbx), line);
            line1=line; line2=line;
            else
                paramarea=app.areatodraw;
                x_border=str2num(paramarea{1});
                y_border=str2num(paramarea{2});
                if y_border(1)<0
                        uialert(app.UIFigure, 'Нижняя граница области в плоскопараллельном поле должна быть неотрицательна. Данное значение было заменено на 0.', ...
                            'Предупреждение', ...
                            'Icon', 'warning', 'Modal', true)
                        y_border(1)=0;
                end                    
                line1=str2num(paramarea{3});
                line2=str2num(paramarea{4});
                xx=linspace(x_border(1), x_border(2), line1); yy=linspace(y_border(1), y_border(2), line2); 
            end
 
            [x0, y0]=meshgrid(xx, yy);
            x0=x0(:);
            y0=y0(:);
            p0=zeros(length(x0), 1);
            q0=zeros(length(x0), 1);
            [~, f2, ~]=unique(app.PP.UserData(:, 6:7), 'rows');
            massiv=app.PP.UserData(f2, :);
            for b=1:size(massiv, 1)
                switch massiv(b, 6)
                    case 1
                        for i=1:length(x0)
                                if (x0(i)-massiv(b, 9))^2+(y0(i)-massiv(b, 10))^2<(massiv(b, 8))^2
                                    p0(i)=Inf; q0(i)=massiv(b, 5);
                                end
                        end
                    case 2
                        if massiv(b, 13)==0
                        for i=1:length(x0)
                            if (x0(i)>massiv(b, 8)) && (x0(i)<massiv(b, 8)+massiv(b, 10)) && (y0(i)>massiv(b, 9)) && (y0(i)<massiv(b, 9)+massiv(b, 11))
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end
                        else
                            Xrect=massiv(b, 8); Yrect=massiv(b, 9); Arect=massiv(b, 10); Brect=massiv(b, 11); rr=massiv(b, 13);
                        for i=1:length(x0)
                            if ((x0(i)-(Xrect+rr))^2+(y0(i)-(Yrect+rr))^2<rr^2 && (x0(i)>Xrect && x0(i)<Xrect+rr) && (y0(i)>Yrect && y0(i)<Yrect+rr)) || ...
                                ((x0(i)-(Xrect+Arect-rr))^2+(y0(i)-(Yrect+rr))^2<rr^2 && (x0(i)>Xrect+Arect-rr && x0(i)<Xrect+Arect) && (y0(i)>Yrect && y0(i)<Yrect+rr)) || ...
                                ((x0(i)-(Xrect+rr))^2+(y0(i)-(Yrect+Brect-rr))^2<rr^2 && (x0(i)>Xrect && x0(i)<Xrect+rr) && (y0(i)>Yrect+Brect-rr && y0(i)<Yrect+Brect)) || ...
                                ((x0(i)-(Xrect+Arect-rr))^2+(y0(i)-(Yrect+Brect-rr))^2<rr^2 && (x0(i)>Xrect+Arect-rr && x0(i)<Xrect+Arect) && (y0(i)>Yrect+Brect-rr && y0(i)<Yrect+Brect)) || ...
                                ((x0(i)>Xrect) && (x0(i)<Xrect+Arect) && (y0(i)>Yrect+rr) && (y0(i)<Yrect+Brect-rr)) || ...
                                ((x0(i)>Xrect+rr) && (x0(i)<Xrect+Arect-rr) && (y0(i)>Yrect) && (y0(i)<Yrect+rr)) || ...
                                ((x0(i)>Xrect+rr) && (x0(i)<Xrect+Arect-rr) && (y0(i)>Yrect+Brect-rr) && (y0(i)<Yrect+Brect))
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end                            
                        end
                        
                    case 3
                        for i=1:length(x0)
                                if (x0(i)-massiv(b, 8))^2/(massiv(b, 10))^2+(y0(i)-massiv(b, 9))^2/(massiv(b, 11))^2<1
                                    p0(i)=Inf; q0(i)=massiv(b, 5);
                                end
                        end                        
                end
            end
             for i=1:length(x0)
                f.Message=horzcat('Выполняется вычисление компонент напряжённости и потенциала в точке i=', num2str(i), ' из ', num2str(length(x0))); 
                for j=1:numberOfChargedAxes
                       if f.CancelRequested
                       close(f)
                       return
                       end
                    if ~isinf(p0(i))
                        Ex0(i, j)=((tau(j)/constValue)).*...
                            ((1./((x0(i)-x(j)).^2+(y0(i)-y(j)).^2))...
                            -(1./((x0(i)-x(j)).^2+(y0(i)+y(j)).^2))).*(x0(i)-x(j));
                        Ey0(i, j)=((tau(j)/constValue)).*...
                            (((y0(i)-y(j))./((x0(i)-x(j)).^2+(y0(i)-y(j)).^2))...
                            -((y0(i)+y(j))./((x0(i)-x(j)).^2+(y0(i)+y(j)).^2)));
                        phi0(i, j)=((tau(j)/constValue)).*...
                            log((sqrt((x0(i)-x(j))^2+(y0(i)+y(j))^2)...
                        /sqrt((x0(i)-x(j))^2+(y0(i)-y(j))^2)));                        
                    else
                        Ex0(i, :)=NaN; Ey0(i, :)=NaN; phi0(i, 1)=q0(i); phi0(i, 2:numberOfChargedAxes)=0;
                    end
                end
                E0(i)=sqrt(sum(Ex0(i, :)).^2+sum(Ey0(i, :)).^2);
                phi_end(i)=sum(phi0(i, :));
             end
            f.Message='Выполняется построение графиков';
            xplot=reshape(x0(:), line2, line1);
            yplot=reshape(y0(:), line2, line1);
            Eplot=reshape(E0(:), line2, line1);
            app.x0y0E0=[x0(:), y0(:), E0(:)]; 
            cla(app.force)   
            contour(app.force, xplot, yplot, Eplot, 80)
            axis(app.force, 'equal')
            cla(app.Ecolor)
            surf(app.Ecolor, xplot, yplot, Eplot)
            shading(app.Ecolor, 'interp')
            
            for b=1:size(massiv, 1)
                switch massiv(b, 6)
                    case 1
                             t=0:0.1:360;
                             xc=massiv(b, 9)+massiv(b, 8)*cosd(t); yc=massiv(b, 10)+massiv(b, 8)*sind(t);
                             fill(app.Ecolor, xc, yc, 'b', 'EdgeColor', 'none')
                             fill(app.force, xc, yc, 'b', 'EdgeColor', 'none')                             
                    case 2
                            if massiv(b, 13)==0
                            xc=[massiv(b, 8) massiv(b, 8)+massiv(b, 10) massiv(b, 8)+massiv(b, 10) massiv(b, 8)];
                            yc=[massiv(b, 9) massiv(b, 9) massiv(b, 9)+massiv(b, 11) massiv(b, 9)+massiv(b, 11)];
                            fill(app.Ecolor, xc, yc, 'b', 'EdgeColor', 'none')
                            fill(app.force, xc, yc, 'b', 'EdgeColor', 'none')
                            else
                               rectangle(app.Ecolor, 'Position', [massiv(b, 8) massiv(b, 9) massiv(b, 10) massiv(b, 11)], 'Curvature', massiv(b, 13)/min(massiv(b, 10)/2, massiv(b, 11)/2), ...
                                   'FaceColor', 'b', 'EdgeColor', 'none')
                               rectangle(app.force, 'Position', [massiv(b, 8) massiv(b, 9) massiv(b, 10) massiv(b, 11)], 'Curvature', massiv(b, 13)/min(massiv(b, 10)/2, massiv(b, 11)/2), ...
                                   'FaceColor', 'b', 'EdgeColor', 'none')                               
                            end
                    case 3
                             t=0:0.1:360;
                             xc=massiv(b, 8)+massiv(b, 10)*cosd(t); 
                             yc=massiv(b, 9)+massiv(b, 11)*sind(t);
                             fill(app.Ecolor, xc, yc, 'b', 'EdgeColor', 'none')
                             fill(app.force, xc, yc, 'b', 'EdgeColor', 'none')
                end
            end             
            
            c=colorbar(app.Ecolor);         
            colormap(app.Ecolor, jet);
            axis(app.Ecolor, 'equal')   

            app.x0y0phi0=[x0(:), y0(:), phi_end(:)];
            phiplot=reshape(phi_end(:), line2, line1);
            cla(app.phicolor)
            surf(app.phicolor, xplot, yplot, phiplot)
            shading(app.phicolor, 'interp')
            c=colorbar(app.phicolor);         
            colormap(app.phicolor, jet);
            axis(app.phicolor, 'equal')
            
            nmax=max(massiv(:, 12));
            app.listofelectrodes=[];
            for b=1:size(massiv, 1)
                if massiv(b, 12)~=nmax
                row1=app.PP.UserData(:, 6:7)==massiv(b, 6:7);
                plot(app.EKT, 1:nmax, ...
                interp1(1:massiv(b, 12), E(all(row1, 2)), linspace(1, massiv(b, 12), nmax)))
                else
                row1=app.PP.UserData(:, 6:7)==massiv(b, 6:7);
                plot(app.EKT, 1:nmax, ...
                E(all(row1, 2)))
                end
                 switch massiv(b, 6)
                    case 1
                        app.listofelectrodes{b}=horzcat('Окружность ', num2str(massiv(b, 7)));
                    case 2
                        app.listofelectrodes{b}=horzcat('Прямоугольник ', num2str(massiv(b, 7)));
                    case 3
                        app.listofelectrodes{b}=horzcat('Эллипс ', num2str(massiv(b, 7)));  
                 end                
            end
            legend(app.EKT, app.listofelectrodes)
            close(f)
            alert=0;
            for b=1:size(massiv, 1)
            switch massiv(b, 6)
                case 1
                case 2
                    if massiv(b, 13)==0
                    alert=1;
                    end
                case 3
            end
            end
            if alert==1
                uialert(app.UIFigure, 'В задаче имеются фигуры с особыми точками, в которых напряжённость может быть вычислена некорректно. Рекомендуется скругление кромок.', ...
                    'Предупреждение', ...
                    'Icon', 'warning', 'Modal', true)
            end            
        end
    
        function [] = drawingOS(app)
            f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется подготовка к построению графиков', 'Cancelable', 'on', ...
               'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');           
            numberOfChargedAxes=size(app.OS.UserData, 1);
            rk=app.OS.UserData(:, 3);
            zk=app.OS.UserData(:, 4);
            r=app.OS.UserData(:, 1);
            z=app.OS.UserData(:, 2);            
            Gax=0; Gay=1/3*min(zk);
            epsilon=app.changeepsilon.Value;
            constValue=2*pi^2*8.85e-12*epsilon;
            Q=app.charges;
            E=app.Eelectr;
            
            if isempty(app.areatodraw) || isequal(app.choosearea.Value, 'Границы по умолчанию')
                            
            if max(rk)>max(zk)
                delta=max(rk);
                Gbx=1.5*delta;
                Gby=Gay+(Gbx-Gax);
            else
                delta=max(zk);
                Gby=1.2*delta;
                Gbx=Gax+(Gby-Gay);
            end
            line=app.numberoflines.Value;
            xx=linspace(Gax, max(Gbx, Gby), line); yy=linspace(Gay, max(Gby, Gbx), line);
            line1=line; line2=line;
            
            else
                paramarea=app.areatodraw;
                x_border=str2num(paramarea{1});
                y_border=str2num(paramarea{2});
                if x_border(1)<0
                        uialert(app.UIFigure, 'Левая граница области в осесимметричном поле должна быть неотрицательна. Данное значение было заменено на 0.', ...
                            'Предупреждение', ...
                            'Icon', 'warning', 'Modal', true)
                        x_border(1)=0;
                end                
                line1=str2num(paramarea{3});
                line2=str2num(paramarea{4});
                xx=linspace(x_border(1), x_border(2), line1); yy=linspace(y_border(1), y_border(2), line2);    
            end
            
            [r0, z0]=meshgrid(xx, yy);
            r0=r0(:);
            z0=z0(:);
            p0=zeros(length(r0), 1);
            q0=zeros(length(z0), 1);
            [~, f2, ~]=unique(app.OS.UserData(:, 6:7), 'rows');
            massiv=app.OS.UserData(f2, :);
            for b=1:size(massiv, 1)
                switch massiv(b, 6)
                    case 1
                        for i=1:length(r0)
                                if (r0(i))^2+(z0(i)-massiv(b, 9))^2<(massiv(b, 8))^2
                                    p0(i)=Inf; q0(i)=massiv(b, 5);
                                end
                        end
                    case 2
                        if massiv(b, 13)==0
                        for i=1:length(r0)
                            if (r0(i)>=massiv(b, 8)) && (r0(i)<massiv(b, 8)+massiv(b, 10)) && (z0(i)>massiv(b, 9)) && (z0(i)<massiv(b, 9)+massiv(b, 11))
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end
                        else
                            Xrect=massiv(b, 8);
                            Arect=massiv(b, 10);
                            Yrect=massiv(b, 9);
                            Brect=massiv(b, 11);
                            rr=massiv(b, 13);
                            if Arect>rr  %%%
                        for i=1:length(r0) %%%
                            if ((r0(i)>=Xrect) && (r0(i)<Xrect+Arect-rr) && (z0(i)>Yrect) && (z0(i)<Yrect+Brect)) || ...
                             ((r0(i)>=Xrect+Arect-rr) && (r0(i)<Xrect+Arect) && (z0(i)>Yrect+rr) && (z0(i)<Yrect+Brect-rr)) || ...   
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+Brect-rr))^2<rr^2 && (r0(i)>Xrect+Arect-rr && r0(i)<Xrect+Arect) && (z0(i)>Yrect+Brect-rr && z0(i)<Yrect+Brect)) || ...                               
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+rr))^2<rr^2 && (r0(i)>Xrect+Arect-rr && r0(i)<Xrect+Arect) && (z0(i)>Yrect && z0(i)<Yrect+rr))          
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end  %%%
                            else
                        for i=1:length(r0) %%%
                            if ((r0(i)>=Xrect) && (r0(i)<rr) && (z0(i)>Yrect+rr) && (z0(i)<Yrect+Brect-rr)) || ...  
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+Brect-rr))^2<rr^2 && (r0(i)>=0 && r0(i)<Xrect+Arect) && (z0(i)>Yrect+Brect-rr && z0(i)<Yrect+Brect)) || ...                               
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+rr))^2<rr^2 && (r0(i)>=0 && r0(i)<Xrect+Arect) && (z0(i)>Yrect && z0(i)<Yrect+rr))          
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end                                
                            end
                        end                        
                    case 3
                        for i=1:length(r0)
                                if (r0(i)-massiv(b, 8)-massiv(b, 9)/2)^2+(z0(i)-massiv(b, 10))^2<(massiv(b, 9)/2)^2
                                    p0(i)=Inf; q0(i)=massiv(b, 5);
                                end
                        end
                    case 4
                        for i=1:length(r0)
                            A=massiv(b, 10); B=massiv(b, 11); Y=massiv(b, 9); R1=massiv(b, 13); R2=massiv(b, 14); X=massiv(b, 8);
                            alfa=atan(B/A);
                            proof1=(r0(i)>(A-R1*cot(alfa/2))) && (r0(i)<=A);
                            proof2=(z0(i)-(Y+R1))^2>(R1^2-(r0(i)-(A-R1*cot(alfa/2)))^2);
                            proof3=(r0(i)>=0) && (r0(i)<R2*sin(alfa));
                            proof4=(z0(i)-(Y+B-R2/cos(alfa)))^2>(R2^2-(r0(i))^2);
                            proof5=(z0(i)>(Y+B-R2/cos(alfa)+R2*cos(alfa)));
                            proof6=(z0(i)<(Y+R1+R1*cos(alfa)));
                            if (r0(i)>=X) && (r0(i)<A) && (z0(i)>Y) && (z0(i)<-B/A*...
                                    r0(i)+Y+B) && ~(proof1 && proof2 && proof6) && ~(proof3 && proof4 && proof5)
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end
                    case 5
                            Xrect=massiv(b, 8); Yrect=massiv(b, 9); Arect=massiv(b, 10); Brect=massiv(b, 11); rr=massiv(b, 13);
                        for i=1:length(r0)
                            if ((r0(i)-(Xrect+rr))^2+(z0(i)-(Yrect+rr))^2<=rr^2 && (r0(i)>=Xrect && r0(i)<=Xrect+rr) && (z0(i)>=Yrect && z0(i)<=Yrect+rr)) || ...
                                ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+rr))^2<=rr^2 && (r0(i)>=Xrect+Arect-rr && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect && z0(i)<=Yrect+rr)) || ...
                                ((r0(i)-(Xrect+rr))^2+(z0(i)-(Yrect+Brect-rr))^2<=rr^2 && (r0(i)>Xrect && r0(i)<=Xrect+rr) && (z0(i)>=Yrect+Brect-rr && z0(i)<=Yrect+Brect)) || ...
                                ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+Brect-rr))^2<=rr^2 && (r0(i)>=Xrect+Arect-rr && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+Brect-rr && z0(i)<=Yrect+Brect)) || ...
                                ((r0(i)>=Xrect) && (r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+rr) && (z0(i)<=Yrect+Brect-rr)) || ...
                                ((r0(i)>=Xrect+rr) && (r0(i)<=Xrect+Arect-rr) && (z0(i)>=Yrect) && (z0(i)<=Yrect+rr)) || ...
                                ((r0(i)>=Xrect+rr) && (r0(i)<=Xrect+Arect-rr) && (z0(i)>=Yrect+Brect-rr) && (z0(i)<=Yrect+Brect))
                                p0(i)=Inf; q0(i)=massiv(b, 5);
                            end
                        end                        
                end
            end
             for i=1:length(r0) 
                for j=1:numberOfChargedAxes
                    f.Message=horzcat('Выполняется вычисление компонент напряжённости и потенциала в точке i=', ...
                        num2str(i), ' из ', num2str(length(r0)));                     
                    if f.CancelRequested
                       close(f)
                       return
                    end
                    if ~isinf(p0(i)) && r0(i)~=0 && r(j)~=0
                    k=sqrt(4*r(j)*r0(i)/((z0(i)-z(j))^2+(r(j)+r0(i))^2));
                    kstr=sqrt(1-(k)^2);
                    k=k^2;
                    K=ellipke(k);
                    [~, int2]=ellipke(k); 
                    k=sqrt(k);
                    Er0(i, j)=Q(j)/constValue/4/sqrt(r(j)*r0(i))*...
                    k/r0(i).*...
                    (K+...
                    (((r0(i)-r(j))/2/r(j))*(k)^2/((kstr)^2)-1)*int2);
                    Ez0(i, j)=Q(j)/constValue/8*(z0(i)-z(j))/(r(j)*r0(i))^(3/2)*(k)^3/(kstr)^2*int2;
                    phi0(i, j)=Q(j)/constValue*K/...
                         sqrt(((z0(i)-z(j))^2+(r(j)+r0(i))^2));                      
                    else if ~isinf(p0(i)) && r0(i)==0 && r(j)~=0 
                        Er0(i, j)=0;
                        Ez0(i, j)=Q(j)/4/pi/8.85e-12/epsilon*(z0(i)-z(j))/(sqrt((r(j))^2+(z0(i)-z(j))^2))^3;
                        k=sqrt(4*r(j)*r0(i)/((z0(i)-z(j))^2+(r(j)+r0(i))^2));
                        kstr=sqrt(1-(k)^2);
                        k=k^2;
                        K=ellipke(k);                        
                        phi0(i, j)=Q(j)/constValue*K/...
                         sqrt(((z0(i)-z(j))^2+(r(j)+r0(i))^2));                          
                    else if ~isinf(p0(i)) && r(j)==0
                        Er0(i, j)=Q(j)/4/pi/8.85e-12/epsilon*(r0(i)-r(j))/(sqrt((r0(i)-r(j))^2+(z0(i)-z(j))^2))^3; %\\\
                        Ez0(i, j)=Q(j)/4/pi/8.85e-12/epsilon*(z0(i)-z(j))/(sqrt((r0(i)-r(j))^2+(z0(i)-z(j))^2))^3;
                        phi0(i, j)=Q(j)/4/pi/8.85e-12/epsilon/...
                         sqrt(((z0(i)-z(j))^2+(r(j)-r0(i))^2));                          
                    else
                        Er0(i, :)=NaN; Ez0(i, :)=NaN;
                        phi0(i, 1)=q0(i); phi0(i, 2:numberOfChargedAxes)=0;
                    end
                    end
                    end
                E0(i)=sqrt(sum(Er0(i, :)).^2+sum(Ez0(i, :)).^2);
                phi_end(i)=sum(phi0(i, :));
                end
             end
            f.Message='Выполняется построение графиков';
            app.x0y0E0=[r0(:), z0(:), E0(:)]; 
            rplot=reshape(r0(:), line2, line1);
            zplot=reshape(z0(:), line2, line1);
            Eplot=reshape(E0(:), line2, line1);
            cla(app.force)
            contour(app.force, rplot, zplot, Eplot, 100) 
            axis(app.force, 'equal')
            cla(app.Ecolor)
            surf(app.Ecolor, rplot, zplot, Eplot)
            shading(app.Ecolor, 'interp')
            

            for b=1:size(massiv, 1)
                switch massiv(b, 6)
                    case 1
                              t=-90:0.1:90;
                              rc=massiv(b, 8)*cosd(t); zc=massiv(b, 9)+massiv(b, 8)*sind(t);
                              fill(app.Ecolor, rc, zc, 'b', 'EdgeColor', 'none')
                              fill(app.force, rc, zc, 'b', 'EdgeColor', 'none')                            
                    case 2
                        if massiv(b, 13)==0
                              rc=[0 massiv(b, 8)+massiv(b, 10) massiv(b, 8)+massiv(b, 10) 0];
                              zc=[massiv(b, 9) massiv(b, 9) massiv(b, 9)+massiv(b, 11) massiv(b, 9)+massiv(b, 11)];
                              fill(app.Ecolor, rc, zc, 'b', 'EdgeColor', 'none')
                              fill(app.force, rc, zc, 'b', 'EdgeColor', 'none')
                        else
                              rectangle(app.Ecolor, 'Position', [-massiv(b, 10) massiv(b, 9) 2*massiv(b, 10) massiv(b, 11)], 'Curvature', massiv(b, 13)/min(massiv(b, 10), massiv(b, 11)), ...
                                   'FaceColor', 'b', 'EdgeColor', 'none')
                              rectangle(app.force, 'Position', [-massiv(b, 10) massiv(b, 9) 2*massiv(b, 10) massiv(b, 11)], 'Curvature', massiv(b, 13)/min(massiv(b, 10), massiv(b, 11)), ...
                                   'FaceColor', 'b', 'EdgeColor', 'none')                             
                        end
                    case 3
                             t=0:0.1:360;
                             rc=massiv(b,8)+massiv(b, 9)/2+massiv(b, 9)/2*cosd(t); zc=massiv(b, 10)+massiv(b, 9)/2*sind(t);
                             fill(app.Ecolor, rc, zc, 'b', 'EdgeColor', 'none')
                             fill(app.force, rc, zc, 'b', 'EdgeColor', 'none')
                    case 4
                             A=massiv(b, 10); B=massiv(b, 11); Y=massiv(b, 9); R1=massiv(b, 13); R2=massiv(b, 14);
                             alfa=atan(B/A);
                             rc=[0 0 R2*sin(alfa) A-R1*cot(alfa/2)*cos(alfa) A-R1*cot(alfa/2) A-R1*cot(alfa/2)];
                             zc=[Y Y+B-R2/cos(alfa) Y+B-R2/cos(alfa)+R2*cos(alfa) Y+R1+R1*cos(alfa) Y+R1 Y];
                             fill(app.Ecolor, rc, zc, 'b', 'EdgeColor', 'none')
                             fill(app.force, rc, zc, 'b', 'EdgeColor', 'none')
                             phi=linspace(90-alfa*180/pi, 90, 500)';
                             x = 0+R2*cosd(phi);
                             y = Y+B-R2/cos(alfa)+R2*sind(phi);
                             fill(app.Ecolor, [x; 0], [y; Y+B-R2/cos(alfa)], 'b', 'EdgeColor', 'none')
                             fill(app.force, [x; 0], [y; Y+B-R2/cos(alfa)], 'b', 'EdgeColor', 'none')
                             phi=linspace(-90, 90-alfa*180/pi, 500)';
                             x = A-R1*cot(alfa/2)+R1*cosd(phi);
                             y = Y+R1+R1*sind(phi);
                             fill(app.Ecolor, [x; A-R1*cot(alfa/2)], [y; Y+R1], 'b', 'EdgeColor', 'none')
                             fill(app.force, [x; A-R1*cot(alfa/2)], [y; Y+R1], 'b', 'EdgeColor', 'none')
                    case 5
                             rectangle(app.Ecolor, 'Position', [massiv(b, 8) massiv(b, 9) massiv(b, 10) massiv(b, 11)], 'Curvature', massiv(b, 13)/min(massiv(b, 10)/2, massiv(b, 11)/2), ...
                                   'FaceColor', 'b', 'EdgeColor', 'none')
                             rectangle(app.force, 'Position', [massiv(b, 8) massiv(b, 9) massiv(b, 10) massiv(b, 11)], 'Curvature', massiv(b, 13)/min(massiv(b, 10)/2, massiv(b, 11)/2), ...
                                   'FaceColor', 'b', 'EdgeColor', 'none')   
                end
            end 
            c=colorbar(app.Ecolor);
            colormap(app.Ecolor, jet);
            axis(app.Ecolor, 'equal')

            app.x0y0phi0=[r0(:), z0(:), phi_end(:)];    
            phiplot=reshape(phi_end(:), line2, line1);
            cla(app.phicolor)
            surf(app.phicolor, rplot, zplot, phiplot)
            shading(app.phicolor, 'interp')
            c=colorbar(app.phicolor);
            colormap(app.phicolor, jet);
            axis(app.phicolor, 'equal')
            
            
            app.listofelectrodes=[]; 
            nmax=max(massiv(:, 12));
            for b=1:size(massiv, 1)
                if massiv(b, 12)~=nmax
                row1=app.OS.UserData(:, 6:7)==massiv(b, 6:7);
                plot(app.EKT, 1:nmax, ...
                interp1(1:massiv(b, 12), E(all(row1, 2)), linspace(1, massiv(b, 12), nmax)))
                else
                row1=app.OS.UserData(:, 6:7)==massiv(b, 6:7);
                plot(app.EKT, 1:nmax, ...
                E(all(row1, 2)))
                end
                 switch massiv(b, 6)
                    case 1
                        app.listofelectrodes{b}=horzcat('Шар ', num2str(massiv(b, 7)));
                    case 2
                        app.listofelectrodes{b}=horzcat('Цилиндр ', num2str(massiv(b, 7)));
                    case 3
                        app.listofelectrodes{b}=horzcat('Тор ', num2str(massiv(b, 7)));
                    case 4
                        app.listofelectrodes{b}=horzcat('Конус ', num2str(massiv(b, 7)));
                    case 5
                        app.listofelectrodes{b}=horzcat('Тор с прямоугольным сечением ', num2str(massiv(b, 7)));    
                 end                 
            end
            legend(app.EKT, app.listofelectrodes)      
            close(f)
            alert=0;
            for b=1:size(massiv, 1)
            switch massiv(b, 6)
                case 1
                case 2
                    if massiv(b, 13)==0
                    alert=1;
                    end
                case 3
                case 4
            end
            end
            if alert==1
                uialert(app.UIFigure, 'В задаче имеются фигуры с особыми точками, в которых напряжённость может быть вычислена некорректно. Рекомендуется скругление кромок.', ...
                    'Предупреждение', 'Icon', 'warning', 'Modal', true)
            end           
        end
    
        function E = FindXYE(app)
            if isempty(app.OS.UserData)
            numberOfChargedAxes=size(app.PP.UserData, 1);
            x=app.PP.UserData(:, 1);
            y=app.PP.UserData(:, 2);
            xk=app.calcXYE(1);
            yk=app.calcXYE(2);
            epsilon=app.changeepsilon.Value;
            constValue=2*pi*8.85e-12*epsilon;
            tau=app.charges;
                for j=1:numberOfChargedAxes
                Ex(j)=((tau(j)/constValue)).*...
                    ((1./((xk-x(j)).^2+(yk-y(j)).^2))...
                    -(1./((xk-x(j)).^2+(yk+y(j)).^2))).*(xk-x(j));
                Ey(j)=((tau(j)/constValue)).*...
                    (((yk-y(j))./((xk-x(j)).^2+(yk-y(j)).^2))...
                    -((yk+y(j))./((xk-x(j)).^2+(yk+y(j)).^2)));
                end
            E=sqrt(sum(Ex).^2+sum(Ey).^2);
            
            for j=1:numberOfChargedAxes
                phi0(j)=((tau(j)/constValue)).*...
                log((sqrt((xk-x(j))^2+(yk+y(j))^2)...
              /sqrt((xk-x(j))^2+(yk-y(j))^2)));
            end
                phi_end=sum(phi0);
            if strcmp(app.Switch.Value, 'Нет')
                switch app.Ephi.Value
                    case 'E, В/м'
                        app.showE.Value=num2str(E, '%10.4f');
                    case 'φ, В'
                        app.showE.Value=num2str(phi_end, '%10.4f');
                end
            end
            else
            numberOfChargedAxes=size(app.OS.UserData, 1);
            r=app.OS.UserData(:, 1);
            z=app.OS.UserData(:, 2);
            rk=app.calcXYE(1);
            zk=app.calcXYE(2);
            epsilon=app.changeepsilon.Value;
            constValue=2*pi^2*8.85e-12*epsilon;
            Q=app.charges;
                
                for j=1:numberOfChargedAxes
                    if rk~=0 && r(j)~=0
                    k=sqrt(4*r(j)*rk/((zk-z(j))^2+(r(j)+rk)^2));
                    kstr=sqrt(1-k^2);
                    k=k^2;
                    K=ellipke(k);
                    [~, int2]=ellipke(k);
                    k=sqrt(k);
                    Er(j)=Q(j)/constValue/4/sqrt(r(j)*rk)*...
                        k/rk*(K+(((rk-r(j))/2/r(j))*k^2/kstr^2-1)*int2);   
                    Ez(j)=Q(j)/constValue/8*(zk-z(j))/(r(j)*rk)^(3/2)*(k)^3/(kstr)^2*int2; 
                    elseif rk==0 && r(j)~=0
                        Er(j)=0;
                        Ez(j)=Q(j)/4/pi/8.85e-12/epsilon*(zk-z(j))/(sqrt((r(j))^2+(zk-z(j))^2))^3;
                    else
                        Er(j)=Q(j)/4/pi/8.85e-12/epsilon*(rk-r(j))/(sqrt((rk-r(j))^2+(zk-z(j))^2))^3; %\\\
                        Ez(j)=Q(j)/4/pi/8.85e-12/epsilon*(zk-z(j))/(sqrt((rk-r(j))^2+(zk-z(j))^2))^3; %\\\\
                    end
                end
                E=sqrt(sum(Er).^2+sum(Ez).^2);
            
                for j=1:numberOfChargedAxes
                    if r(j)~=0
                    k=sqrt(4*r(j)*rk/((zk-z(j))^2+(r(j)+rk)^2));
                    k=k^2;
                    K=ellipke(k);
                    phi0(j)=Q(j)/constValue*K/...
                         sqrt(((zk-z(j))^2+(r(j)+rk)^2));                        
                    else 
                         phi0(j)=Q(j)/4/pi/8.85e-12/epsilon/...
                         sqrt(((zk-z(j))^2+(r(j)-rk)^2));     
                    end
                end
                phi_end=sum(phi0);
                if strcmp(app.Switch.Value, 'Нет')
                switch app.Ephi.Value
                    case 'E, В/м'
                        app.showE.Value=num2str(E, '%10.4f');
                    case 'φ, В'
                        app.showE.Value=num2str(phi_end, '%10.4f');
                end
                end
            end
        end
    
        function [] = AllElectrodes(app)
            if isequal(app.addPP.Enable, 'on') || isequal(app.addPP.Enable, 1)
                       for j=1:length(app.circles)
                           app.circle0=app.circles{j};
                           makecircle(app);
                           if app.checkall==1
                               return
                           end
                       end
                       for j=1:length(app.reacts)
                           app.react0=app.reacts{j};
                           report=[];
                           try
                               a=eval(app.react0{7});
                               report=1;
                           catch ME
                               report=getReport(ME);
                           end
                           if report~=1
                               uialert(app.UIFigure, 'Ошибка ввода информации о закруглении.', ...
                                    'Ошибка', 'Modal', true)
                                return
                           end                            
                           if a==0
                               makereact(app);
                           else
                               makereact_mild(app);
                           end
                           if app.checkall==1
                               return
                           end                           
                       end
                       for j=1:length(app.ellipses)
                           app.ellips0=app.ellipses{j};
                           makeellips(app);
                           if app.checkall==1
                               return
                           end                           
                       end
            else
                       for j=1:length(app.balls)
                           app.ball0=app.balls{j};
                           makeball(app);
                           if app.checkall==1
                               return
                           end                           
                       end
                       for j=1:length(app.cylinders)
                           app.cylinder0=app.cylinders{j};
                           report=[];
                           try
                               a=eval(app.cylinder0{6});
                               report=1;
                           catch ME
                               report=getReport(ME);
                           end
                           if report~=1
                            uialert(app.UIFigure, 'Ошибка ввода информации о закруглении.', ...
                                    'Ошибка', 'Modal', true)
                                return
                           end                            
                           if a==0
                               makecylinder(app);
                           else
                               makecylinder_mild(app);
                           end
                           if app.checkall==1
                               return
                           end                         
                       end
                       for j=1:length(app.tors)
                           app.tor0=app.tors{j};
                           maketor(app);
                           if app.checkall==1
                               return
                           end                            
                       end
                       for j=1:length(app.konuses)
                           app.konus0=app.konuses{j};
                           makekonus_mild(app);
                           if app.checkall==1
                               return
                           end                            
                       end
                       for j=1:length(app.torrects)
                           app.torrect0=app.torrects{j};
                           maketorrect(app);
                           if app.checkall==1
                               return
                           end                            
                       end                       
            end
        end
    
        function [] = GradientMethod(app)
             f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется оптимизация...', 'Cancelable', 'on', ...
               'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');            
             eps=str2double(app.optparam{3});
             eps0=1;
             h=str2double(app.optparam{6});
             X={str2num(app.optparam{2})'};
             optorder=length(str2num(app.optparam{2})');
             i=1;
             F1=zeros(optorder, 1);
             app.proofopt=1;
             app.itercounter=[];
             app.precision=[];
             app.ForSaveTable=[];
             app.firstiter=[];
             while eps0(i)>eps
                 f.Message=horzcat('Выполняется оптимизация: итерация №', num2str(i));
                 if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                 end
                   app.param=[];
                   app.param=X{i};
                   Ecenter=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   
                   if i==1 && ~isempty(app.optparam{5})  
                       if app.firstiter<=0
                       uialert(app.UIFigure, ...
                            'Требуемый уровень напряжённости уже достигнут.', ...
                            'Сообщение', 'Modal', true, 'Icon', 'success')
                        app.proofopt=0;
                        return
                       end
                   end   
   
               for j=1:length(F1)
                    
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   
                   app.param(j)=X{i}(j)+h;
                   Eplus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end
                   app.param(j)=X{i}(j);
                   
                   app.param(j)=X{i}(j)-h;
                   Eminus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i}(j);
                   
                   F1(j)=(Eplus-Eminus)/(2*h);
               end
                   
%                a(i)=0.9*min(abs(X{i}))/norm(F1);
%                X{i+1}=X{i}-a(i)*F1;
               a(i)=1;
               if any(abs(a(i)*F1)>=abs(X{i}))
               while any(abs(a(i)*F1)>=abs(X{i}))
                   a(i)=0.5*a(i);
                   X{i+1}=X{i}-a(i)*F1;
               end
               else
                   X{i+1}=X{i}-a(i)*F1;
               end              
               
               app.param=[];
               app.param=X{i+1};
               Enew=ForOptimization(app);
               if app.proofopt==0
                   close(f)
                   return
               end               
               
               while ~(Enew<=Ecenter)
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   a(i)=a(i)*0.5;
                   X{i+1}=X{i}-a(i)*F1;
                   app.param=[];
                   app.param=X{i+1};
                   Enew=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
               end
               
               for j=1:length(F1)
                   epsP(j)=abs((X{i+1}(j)-X{i}(j))/X{i+1}(j));
               end
               if isempty(app.optparam{5})
                   epsE=abs((Enew-Ecenter)/Enew);
               else
                   epsE=abs(app.otheriter/eval(app.optparam{5}));
               end
               eps0(i+1)=max([epsP'; epsE]);
               app.itercounter=num2str(i);
               app.ForSaveTable(i, 1)=i;
               for p=1:optorder
                   app.ForSaveTable(i, p+1)=X{i}(p);
               end
               app.ForSaveTable(i, optorder+2)=Ecenter;
               app.ForSaveTable(i, optorder+3)=Enew;
               app.ForSaveTable(i, optorder+4)=epsE;
               for p=1:optorder
                   app.ForSaveTable(i, p+optorder+4)=epsP(p);
               end
               app.ForSaveTable(i+1, optorder*2+5)=eps0(i+1);
               i=i+1;
               if i>700
                    uialert(app.UIFigure, ...
                        'Превышено допустимое число итераций. Убедитесь в существовании оптимума или выберите другие начальные приближения/метод оптимизации/шаг взятия производной/допустимую погрешность расчёта.', ...
                        'Ошибка', 'Modal', true)
                    app.proofopt=0;
                    return                           
               end
             end
           close(f)
           app.proofopt=1;
           app.precision{1}=num2str(eps0(end));
        end
    
        function [] = BFGS(app)
             f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется оптимизация...', 'Cancelable', 'on', ...
               'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');            
             eps=str2double(app.optparam{3});
             eps0=1;
             h=str2double(app.optparam{6});
             X={str2num(app.optparam{2})'};
             optorder=length(str2num(app.optparam{2})');
             i=1;
             F1=zeros(optorder, 1);
             F10=zeros(optorder, 1);
             H={eye(optorder)};
             app.proofopt=1;
             app.itercounter=[];
             app.precision=[];
             app.ForSaveTable=[];
             app.firstiter=[];
             alfa=1;
             c1=0.00001;
             c2=0.99;
             while eps0(i)>eps 
                 f.Message=horzcat('Выполняется оптимизация: итерация №', num2str(i));
                 if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                 end
                 if i~=1
                    ro{i-1}=1/(yk{i-1}'*sk{i-1});
                    H{i}=(eye(optorder)-ro{i-1}*sk{i-1}*yk{i-1}')*H{i-1}*(eye(optorder)-ro{i-1}*yk{i-1}*sk{i-1}')+ro{i-1}*sk{i-1}*sk{i-1}';
                 end
                 if i==1
                 %Вычисление F в точке на k-й итерации
                   app.param=[];
                   app.param=X{i};
                   Ecenter=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end
                   if ~isempty(app.optparam{5})  
                       if app.firstiter<=0
                       uialert(app.UIFigure, ...
                            'Требуемый уровень напряжённости уже достигнут.', ...
                            'Сообщение', 'Modal', true, 'Icon', 'success')
                        app.proofopt=0;
                        return
                       end
                   end                   
                %%%%%%%%
                %Вычисление градиента F1 в точке на k-й итерации
                  for j=1:length(F1)    
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   app.param(j)=X{i}(j)+h;
                   Eplus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i}(j);
                   
                   app.param(j)=X{i}(j)-h;
                   Eminus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i}(j);
                   
                   F1(j)=(Eplus-Eminus)/(2*h);
                  end
                 else
                     Ecenter=Enew;
                     F1=F10;                    
                 end
               %%%%%%%%%
%                alfa(i)=0.9*min(abs(X{i}))/norm(F1)/norm(H{i});
               alfa(i)=1;
               if any(abs(alfa(i)*H{i}*F1)>=abs(X{i}))
               while any(abs(alfa(i)*H{i}*F1)>=abs(X{i}))
                   alfa(i)=0.5*alfa(i);
                   X{i+1}=abs(X{i}-alfa(i)*H{i}*F1);
               end
               else
                   X{i+1}=abs(X{i}-alfa(i)*H{i}*F1);
               end
               %Вычисление значения F в точке на итерации k+1
               app.param=[];
               app.param=X{i+1};
               Enew=ForOptimization(app); 
               if app.proofopt==0
                   close(f)
                   return
               end
               Ek=app.otheriter;
               %%%%%%%              
               %Вычисление градиента F1 в точке на итерации k+1
                  for j=1:length(F10)    
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   app.param(j)=X{i+1}(j)+h;
                   Eplus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i+1}(j);
                   
                   app.param(j)=X{i+1}(j)-h;
                   Eminus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i+1}(j);
                   
                   F10(j)=(Eplus-Eminus)/(2*h);
                  end
               %%%%%%%
               while ~((Enew<=(Ecenter+c1*alfa(i)*F1'*(-H{i})*F1)) && ...
                     ((F10'*(-H{i}*F1))>=(c2*F1'*(-H{i}*F1))))
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                    if alfa(i)<1e-6
                       break
                    end
                   alfa(i)=alfa(i)*0.7;                                         
                   X{i+1}=abs(X{i}-alfa(i)*H{i}*F1);
               %Вычисление значения F в точке на итерации k+1
%                if alfa(i)==alfa(1)
                   X{i+1}=abs(X{i}-alfa(i)*H{i}*F1);
                   app.param=[];
                   app.param=X{i+1};
                   Enew=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end
                   Ek=app.otheriter;
               %%%%%%%
               %Вычисление градиента F1 в точке на итерации k+1
                  for j=1:length(F10)    
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   app.param(j)=X{i+1}(j)+h;
                   Eplus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i+1}(j);
                   
                   app.param(j)=X{i+1}(j)-h;
                   Eminus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i+1}(j);                  
                   F10(j)=(Eplus-Eminus)/(2*h);
                   end
%                    break
%                 else
%                app.param=[];
%                app.param=X{i+1};
%                cla(app.arr) 
%                app.PP.UserData=[];
%                app.OS.UserData=[];
%                AllElectrodes(app);
%                solveButtonPushed(app, []);
%                Enew=max(app.Eelectr);
%                app.Eelectr=[];              
%                %%%%%%%
%                %Вычисление градиента F1 в точке на итерации k+1
%                   for j=1:length(F10)    
%                    if f.CancelRequested
%                      close(f)
%                      app.proofopt=0;
%                      return
%                    end
%                    app.param(j)=X{i+1}(j)+h;
%                    cla(app.arr) 
%                    app.PP.UserData=[];
%                    app.OS.UserData=[];
%                    AllElectrodes(app);
%                    solveButtonPushed(app, []);
%                    Eplus=max(app.Eelectr);
%                    app.Eelectr=[];
%                    app.param(j)=X{i+1}(j);
%                    
%                    app.param(j)=X{i+1}(j)-h;
%                    cla(app.arr)
%                    app.PP.UserData=[];
%                    app.OS.UserData=[];
%                    AllElectrodes(app);
%                    solveButtonPushed(app, []);
%                    Eminus=max(app.Eelectr);
%                    app.Eelectr=[];
%                    app.param(j)=X{i+1}(j);
%                    
%                    F10(j)=(Eplus-Eminus)/(2*h);
%                   end
%                end
               end
               for j=1:length(F1)
                   epsP(j)=abs((X{i+1}(j)-X{i}(j))/X{i+1}(j));
               end
%                epsE=norm(F1);
               if isempty(app.optparam{5})
                   epsE=abs((Enew-Ecenter)/Enew);
               else
                   epsE=abs(Ek/eval(app.optparam{5}));
               end
               eps0(i+1)=max([epsP'; epsE]);
               sk{i}=X{i+1}-X{i};
               yk{i}=F10-F1;
               eps0(i+1)=max([epsP'; epsE]);
               app.itercounter=num2str(i);
               app.ForSaveTable(i, 1)=i;
               for p=1:optorder
                   app.ForSaveTable(i, p+1)=X{i}(p);
               end
               app.ForSaveTable(i, optorder+2)=Ecenter;
               app.ForSaveTable(i, optorder+3)=Enew;
               app.ForSaveTable(i, optorder+4)=epsE;
               for p=1:optorder
                   app.ForSaveTable(i, p+optorder+4)=epsP(p);
               end
               app.ForSaveTable(i+1, optorder*2+5)=eps0(i+1);               
               i=i+1;             
               if i>700
                    uialert(app.UIFigure, ...
                        'Превышено допустимое число итераций. Убедитесь в существовании оптимума или выберите другие начальные приближения/метод оптимизации/шаг взятия производной/допустимую погрешность расчёта.', ...
                        'Ошибка', 'Modal', true)
                    app.proofopt=0;
                    return                           
               end
             end
           close(f)
           app.proofopt=1;
           app.precision{1}=num2str(eps0(end));
           app.param=X{i};
           Enew=ForOptimization(app);           
        end
    
        function [] = LevenbergMarkvardt(app)
             f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется оптимизация...', 'Cancelable', 'on', ...
               'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');            
             eps=str2double(app.optparam{3});
             eps0=1;
             h=str2double(app.optparam{6});
             X={str2num(app.optparam{2})'};
             optorder=length(str2num(app.optparam{2})');
             i=1;
             F1=zeros(optorder, 1);
             delta=1e1;
             app.proofopt=1;
             app.itercounter=[];
             app.precision=[];
             app.ForSaveTable=[];
             app.firstiter=[];
             while eps0(i)>eps
                 f.Message=horzcat('Выполняется оптимизация: итерация №', num2str(i));
                 if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                 end
                 %Вычисление F в точке на k-й итерации
                   app.param=[];
                   app.param=X{i};
                   Ecenter=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end
                   if i==1 && ~isempty(app.optparam{5})  
                       if app.firstiter<=0
                       uialert(app.UIFigure, ...
                            'Требуемый уровень напряжённости уже достигнут.', ...
                            'Сообщение', 'Modal', true, 'Icon', 'success')
                        app.proofopt=0;
                        return
                       end
                   end                   
                %%%%%%%%
                %Вычисление градиента F1 в точке на k-й итерации
                  for j=1:length(F1)    
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   
                   app.param(j)=X{i}(j)+h;
                   Eplus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i}(j);
                   
                   app.param(j)=X{i}(j)-h;
                   Eminus=ForOptimization(app);
                   if app.proofopt==0
                       close(f)
                       return
                   end                   
                   app.param(j)=X{i}(j);
                   
                   F1(j)=(Eplus-Eminus)/(2*h);
                   F2(j, j)=(Eplus-2*Ecenter+Eminus)/h^2;
                  end                 
                  app.hesse=F2;
               %%%%%%%%%
               %Вычисление гессиана F2 в точке на k-й итерации
               F2=Hessian(app);
               if isempty(F2)
                   close(f)
                   return
               end
               %%%%%%
               if i~=1
                   delta(i)=delta(i-1)/2;
                   while any(abs(((F1'/(F2+eye(optorder)*delta(i)))'))>=abs(X{i}))
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end                       
                         delta(i)=delta(i)*1.1;
                   end
               end
               if i==1
                   while any(abs(((F1'/(F2+eye(optorder)*delta(i)))'))>=abs(X{i}))
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end                       
                         delta(i)=delta(i)*1.1;
                   end
               end
               X{i+1}=abs(X{i}-(F1'/(F2+eye(optorder)*delta(i)))');
               %Вычисление значения F в точке на итерации k+1
               app.param=[];
               app.param=X{i+1};
               Enew=ForOptimization(app);
               if app.proofopt==0
                   close(f)
                   return
               end               
               %%%%%%%
               while ~(Enew<=Ecenter)
                   if f.CancelRequested
                     close(f)
                     app.proofopt=0;
                     return
                   end
                   delta(i)=delta(i)*2;                       
                   X{i+1}=abs(X{i}-(F1'/(F2+eye(optorder)*delta(i)))');
               %Вычисление значения F в точке на итерации k+1
               app.param=[];
               app.param=X{i+1};
               Enew=ForOptimization(app);
               if app.proofopt==0
                   close(f)
                   return
               end               
               %%%%%%%
               end             
               for j=1:length(F1)
                   epsP(j)=abs((X{i+1}(j)-X{i}(j))/X{i+1}(j));
               end
               if isempty(app.optparam{5})
                   epsE=abs((Enew-Ecenter)/Enew);
               else
                   epsE=abs(app.otheriter/eval(app.optparam{5}));
               end
               eps0(i+1)=max([epsP'; epsE]);
               app.itercounter=num2str(i);
               app.ForSaveTable(i, 1)=i;
               for p=1:optorder
                   app.ForSaveTable(i, p+1)=X{i}(p);
               end
               app.ForSaveTable(i, optorder+2)=Ecenter;
               app.ForSaveTable(i, optorder+3)=Enew;
               app.ForSaveTable(i, optorder+4)=epsE;
               for p=1:optorder
                   app.ForSaveTable(i, p+optorder+4)=epsP(p);
               end
               app.ForSaveTable(i+1, optorder*2+5)=eps0(i+1);               
               i=i+1;             
               if i>700
                    uialert(app.UIFigure, ...
                        'Превышено допустимое число итераций. Убедитесь в существовании оптимума или выберите другие начальные приближения/метод оптимизации/шаг взятия производной/допустимую погрешность расчёта.', ...
                        'Ошибка', 'Modal', true)
                    app.proofopt=0;
                    return                           
               end
             end
           close(f)
           app.proofopt=1;
           app.precision{1}=num2str(eps0(end));           
        end
        
        function F2 = Hessian(app)
            h=str2double(app.optparam{6});
            F2=app.hesse;
            X{:}=app.param(:);
            for i=1:length(F2)
                for j=1:length(F2)
                    if j>i                        
                       app.param=X{:}+h;
                       E1=ForOptimization(app);
                       if app.proofopt==0
                           F2=[];
                           return
                       end                       
                       app.param=X{:};
                       
                       app.param(i)=X{:}(i)+h;
                       E2=ForOptimization(app);
                       if app.proofopt==0
                           F2=[];
                           return
                       end                       
                       app.param=X{:};
                                              
                       app.param(j)=X{:}(j)+h;
                       E3=ForOptimization(app);
                       if app.proofopt==0
                           F2=[];
                           return
                       end                       

                       app.param=X{:};
                       E4=ForOptimization(app);
                       if app.proofopt==0
                           F2=[];
                           return
                       end                       
                       
                       F2(i, j)=(E1-E2-E3+E4)/h^2;
                       F2(j, i)=F2(i, j);
                    end
                end
            end
        end
    
        function E = ForOptimization(app)
               app.firstiter=[];
               app.otheriter=[];
               app.checkall=0;
               cla(app.arr)
               app.PP.UserData=[];
               app.OS.UserData=[];
               AllElectrodes(app);
               if app.checkall==1
                    uialert(app.UIFigure, ...
                        'Параметры электродов в ходе оптимизации приняли недопустимые значения, из-за чего геометрия не может быть построена. Убедитесь в существовании оптимума или выберите другие начальные приближения/метод оптимизации/шаг взятия производной.', ...
                        'Ошибка', 'Modal', true)
                    Y=app.optparam{2};
                    app.param=str2num(Y);
                    cla(app.arr)
                    app.PP.UserData=[];
                    app.OS.UserData=[];                    
                    AllElectrodes(app);
                    E=[];
                    app.checkall=0;
                    app.proofopt=0;
                    return
               end
               X=app.optparam{4};
               if ~isempty(app.optparam{5})
                   report=[];
                   try
                   Elevel=eval(app.optparam{5});
                   if imag(Elevel) || isnan(Elevel) || isinf(Elevel) || Elevel<=0
                        uialert(app.UIFigure, 'Требуемый уровень напряжённости должен быть конечным положительным действительным числом.', ...
                                'Ошибка', 'Modal', true)
                        E=[];
                        app.checkall=0;
                        app.proofopt=0;
                        Y=app.optparam{2};
                        app.param=str2num(Y);
                        cla(app.arr)
                        app.PP.UserData=[];
                        app.OS.UserData=[];
                        AllElectrodes(app);                        
                    return                
                   end
                   report=1;
                   catch ME
                       report=getReport(ME);
                   end
                   if report~=1 
                        uialert(app.UIFigure, 'Требуемый уровень напряжённости должен быть конечным положительным действительным числом.', ...
                                'Ошибка', 'Modal', true)
                        E=[];
                        app.checkall=0;
                        app.proofopt=0;
                        Y=app.optparam{2};
                        app.param=str2num(Y);
                        cla(app.arr)
                        app.PP.UserData=[];
                        app.OS.UserData=[];                        
                        AllElectrodes(app);                        
                    return                
                    end                    
               else
                   Elevel=0;
               end
               if isempty(app.optparam{4})
                   solveButtonPushed(app, []);
                   E0=max(app.Eelectr)-Elevel;
                   if ~isempty(app.optparam{5})
                       app.firstiter=E0;
                       app.otheriter=E0;
                   end
                   E=abs(E0);
                   
                   app.Eelectr=[];
               else
                   solveButtonPushed(app, []);
                   report=[];
                   try
                   c=strsplit(X);
                   c=reshape(c, 2, length(c)/2);
                   for i=1:size(c, 2)
                       app.calcXYE(1)=eval(c{1, i});
                       app.calcXYE(2)=eval(c{2, i});
                    if any(arrayfun(@imag, app.calcXYE)) || any(arrayfun(@isnan, app.calcXYE)) || any(arrayfun(@isinf, app.calcXYE)) 
                        uialert(app.UIFigure, 'Точки, в которых ведётся оптимизация, должны быть действительными конечными числами.', ...
                                'Ошибка', 'Modal', true)
                        E=[];
                        app.firstiter=[];
                        app.otheriter=[];
                        app.checkall=0;
                        app.proofopt=0;
                        Y=app.optparam{2};
                        app.param=str2num(Y);
                        cla(app.arr)
                        app.PP.UserData=[];
                        app.OS.UserData=[];
                        AllElectrodes(app);                        
                    return                
                    end
                       E(i)=FindXYE(app);
                   end
                   E0=max(E)-Elevel;
                   E=abs(E0);
                   if ~isempty(app.optparam{5})
                       app.firstiter=E0;
                       app.otheriter=E0;
                   end
                   app.Eelectr=[];
                   app.calcXYE=[];
                   report=1;
                   catch ME
                       report=getReport(ME);
                   end
                    if report~=1 
                        uialert(app.UIFigure, 'Точки, в которых ведётся оптимизация, должны быть действительными конечными числами.', ...
                                'Ошибка', 'Modal', true)
                        E=[];
                        app.checkall=0;
                        app.proofopt=0;
                        Y=app.optparam{2};
                        app.param=str2num(Y);
                        cla(app.arr)
                        app.PP.UserData=[];
                        app.OS.UserData=[];
                        app.firstiter=[];
                        app.otheriter=[];                        
                        AllElectrodes(app);                        
                    return                
                    end                   
               end  
        end
    
        function [] = ContactChecking(app)
            if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                return
            end               
            if ~isempty(app.PP.UserData)
                if size(app.PP.UserData, 2)>size(app.contourPoints, 2)
                    app.contourPoints(:, end:end+size(app.PP.UserData, 2)-size(app.contourPoints, 2))=0;
                elseif size(app.PP.UserData, 2)<size(app.contourPoints, 2)
                    app.PP.UserData(:, end:end-size(app.PP.UserData, 2)+size(app.contourPoints, 2))=0;
                end
                ext=[app.PP.UserData; app.contourPoints];
                [~, f2, ~]=unique(ext(:, 6:7), 'rows');   
                massiv=ext(f2, :);
            for b=1:size(massiv, 1)
                x0=[];
                y0=[];
                x0=ext(any(ext(:, 6:7)~=massiv(b, 6:7), 2), 3);
                y0=ext(any(ext(:, 6:7)~=massiv(b, 6:7), 2), 4);
                switch massiv(b, 6)
                    case 1
                        for i=1:length(x0)
                                if (x0(i)-massiv(b, 9))^2+(y0(i)-massiv(b, 10))^2<=(massiv(b, 8))^2
                                    app.checkall=1;
                                    return
                                end
                        end
                    case 2
                        if massiv(b, 13)==0
                        for i=1:length(x0)
                                if (x0(i)>=massiv(b, 8)) && (x0(i)<=massiv(b, 8)+massiv(b, 10)) && (y0(i)>=massiv(b, 9)) && (y0(i)<=massiv(b, 9)+massiv(b, 11))
                                   app.checkall=1;
                                   return
                                end
                        end
                        else
                            Xrect=massiv(b, 8); Yrect=massiv(b, 9); Arect=massiv(b, 10); Brect=massiv(b, 11); rr=massiv(b, 13);
                        for i=1:length(x0)
                            if ((x0(i)-(Xrect+rr))^2+(y0(i)-(Yrect+rr))^2<=rr^2 && (x0(i)>=Xrect && x0(i)<=Xrect+rr) && (y0(i)>=Yrect && y0(i)<=Yrect+rr)) || ...
                                ((x0(i)-(Xrect+Arect-rr))^2+(y0(i)-(Yrect+rr))^2<=rr^2 && (x0(i)>=Xrect+Arect-rr && x0(i)<=Xrect+Arect) && (y0(i)>=Yrect && y0(i)<=Yrect+rr)) || ...
                                ((x0(i)-(Xrect+rr))^2+(y0(i)-(Yrect+Brect-rr))^2<=rr^2 && (x0(i)>Xrect && x0(i)<=Xrect+rr) && (y0(i)>=Yrect+Brect-rr && y0(i)<=Yrect+Brect)) || ...
                                ((x0(i)-(Xrect+Arect-rr))^2+(y0(i)-(Yrect+Brect-rr))^2<=rr^2 && (x0(i)>=Xrect+Arect-rr && x0(i)<=Xrect+Arect) && (y0(i)>=Yrect+Brect-rr && y0(i)<=Yrect+Brect)) || ...
                                ((x0(i)>=Xrect) && (x0(i)<=Xrect+Arect) && (y0(i)>=Yrect+rr) && (y0(i)<=Yrect+Brect-rr)) || ...
                                ((x0(i)>=Xrect+rr) && (x0(i)<=Xrect+Arect-rr) && (y0(i)>=Yrect) && (y0(i)<=Yrect+rr)) || ...
                                ((x0(i)>=Xrect+rr) && (x0(i)<=Xrect+Arect-rr) && (y0(i)>=Yrect+Brect-rr) && (y0(i)<=Yrect+Brect))
                                app.checkall=1;
                                return
                            end
                        end                            
                        end  
                    case 3
                        for i=1:length(x0)
                                if (x0(i)-massiv(b, 8))^2/(massiv(b, 10))^2+(y0(i)-massiv(b, 9))^2/(massiv(b, 11))^2<=1
                                    app.checkall=1;
                                end
                        end                        
                end
            end
            
            else
                
            if size(app.OS.UserData, 2)>size(app.contourPoints, 2)
                app.contourPoints(:, end:end+size(app.OS.UserData, 2)-size(app.contourPoints, 2))=0;
            elseif size(app.OS.UserData, 2)<size(app.contourPoints, 2)
                app.OS.UserData(:, end:end-size(app.OS.UserData, 2)+size(app.contourPoints, 2))=0;
            end
            ext=[app.OS.UserData; app.contourPoints];
            [~, f2, ~]=unique(ext(:, 6:7), 'rows');   
            massiv=ext(f2, :);           
            for b=1:size(massiv, 1)
                r0=[];
                z0=[];
                r0=ext(any(ext(:, 6:7)~=massiv(b, 6:7), 2), 3);
                z0=ext(any(ext(:, 6:7)~=massiv(b, 6:7), 2), 4);                
                switch massiv(b, 6)
                    case 1
                        for i=1:length(r0)
                                if (r0(i))^2+(z0(i)-massiv(b, 9))^2<=(massiv(b, 8))^2
                                    app.checkall=1;
                                end
                        end
                    case 2
                        if massiv(b, 13)==0
                        for i=1:length(r0)
                            if (r0(i)>=massiv(b, 8)) && (r0(i)<=massiv(b, 8)+massiv(b, 10)) && (z0(i)>=massiv(b, 9)) && (z0(i)<=massiv(b, 9)+massiv(b, 11))
                                app.checkall=1;
                            end
                        end
                        else
                            Xrect=massiv(b, 8);
                            Arect=massiv(b, 10);
                            Yrect=massiv(b, 9);
                            Brect=massiv(b, 11);
                            rr=massiv(b, 13);
                            if Arect>rr  %%%
                        for i=1:length(r0) %%%
                            if ((r0(i)>=Xrect) && (r0(i)<=Xrect+Arect-rr) && (z0(i)>=Yrect) && (z0(i)<=Yrect+Brect)) || ...
                             ((r0(i)>=Xrect+Arect-rr) && (r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+rr) && (z0(i)<=Yrect+Brect-rr)) || ...   
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+Brect-rr))^2<=rr^2 && (r0(i)>=Xrect+Arect-rr && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+Brect-rr && z0(i)<=Yrect+Brect)) || ...                               
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+rr))^2<=rr^2 && (r0(i)>=Xrect+Arect-rr && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect && z0(i)<=Yrect+rr))          
                                app.checkall=1;
                            end
                        end  %%%
                            else
                        for i=1:length(r0) %%%
                            if ((r0(i)>=Xrect) && (r0(i)<=rr) && (z0(i)>=Yrect+rr) && (z0(i)<=Yrect+Brect-rr)) || ...  
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+Brect-rr))^2<=rr^2 && (r0(i)>=0 && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+Brect-rr && z0(i)<=Yrect+Brect)) || ...                               
                      ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+rr))^2<=rr^2 && (r0(i)>=0 && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect && z0(i)<=Yrect+rr))          
                                app.checkall=1;
                            end
                        end                                
                            end
                        end                        
                    case 3
                        for i=1:length(r0)
                                if (r0(i)-massiv(b, 8)-massiv(b, 9)/2)^2+(z0(i)-massiv(b, 10))^2<=(massiv(b, 9)/2)^2
                                    app.checkall=1;
                                end
                        end
                    case 4
                        for i=1:length(r0)
                            A=massiv(b, 10); B=massiv(b, 11); Y=massiv(b, 9); R1=massiv(b, 13); R2=massiv(b, 14); X=massiv(b, 8);
                            alfa=atan(B/A);
                            proof1=(r0(i)>=(A-R1*cot(alfa/2))) && (r0(i)<=A);
                            proof2=(z0(i)-(Y+R1))^2>=(R1^2-(r0(i)-(A-R1*cot(alfa/2)))^2);
                            proof3=(r0(i)>=0) && (r0(i)<=R2*sin(alfa));
                            proof4=(z0(i)-(Y+B-R2/cos(alfa)))^2>=(R2^2-(r0(i))^2);
                            proof5=(z0(i)>=(Y+B-R2/cos(alfa)+R2*cos(alfa)));
                            proof6=(z0(i)<=(Y+R1+R1*cos(alfa)));
                            if (r0(i)>=X) && (r0(i)<=A) && (z0(i)>=Y) && (z0(i)<=-B/A*...
                                    r0(i)+Y+B) && ~(proof1 && proof2 && proof6) && ~(proof3 && proof4 && proof5)
                                app.checkall=1;
                            end
                        end
                    case 5
                            Xrect=massiv(b, 8); Yrect=massiv(b, 9); Arect=massiv(b, 10); Brect=massiv(b, 11); rr=massiv(b, 13);
                        for i=1:length(r0)
                            if ((r0(i)-(Xrect+rr))^2+(z0(i)-(Yrect+rr))^2<=rr^2 && (r0(i)>=Xrect && r0(i)<=Xrect+rr) && (z0(i)>=Yrect && z0(i)<=Yrect+rr)) || ...
                                ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+rr))^2<=rr^2 && (r0(i)>=Xrect+Arect-rr && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect && z0(i)<=Yrect+rr)) || ...
                                ((r0(i)-(Xrect+rr))^2+(z0(i)-(Yrect+Brect-rr))^2<=rr^2 && (r0(i)>Xrect && r0(i)<=Xrect+rr) && (z0(i)>=Yrect+Brect-rr && z0(i)<=Yrect+Brect)) || ...
                                ((r0(i)-(Xrect+Arect-rr))^2+(z0(i)-(Yrect+Brect-rr))^2<=rr^2 && (r0(i)>=Xrect+Arect-rr && r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+Brect-rr && z0(i)<=Yrect+Brect)) || ...
                                ((r0(i)>=Xrect) && (r0(i)<=Xrect+Arect) && (z0(i)>=Yrect+rr) && (z0(i)<=Yrect+Brect-rr)) || ...
                                ((r0(i)>=Xrect+rr) && (r0(i)<=Xrect+Arect-rr) && (z0(i)>=Yrect) && (z0(i)<=Yrect+rr)) || ...
                                ((r0(i)>=Xrect+rr) && (r0(i)<=Xrect+Arect-rr) && (z0(i)>=Yrect+Brect-rr) && (z0(i)<=Yrect+Brect))
                                app.checkall=1;
                                return
                            end
                        end                        
                end
            end                
            end
        end

    end


    methods (Access = private)

        % Button pushed function: addPP
        function addPPButtonPushed(app, event)
        msg = 'Выберите тип электрода';
        title = 'Выбор электрода';
        selection = uiconfirm(app.UIFigure,msg,title,...
           'Options',{'Окружность','Прямоугольник','Эллипс', 'Отмена'},...
           'DefaultOption', 1,'CancelOption', 4);
       if isempty(app.PP.UserData)
           app.chargesnumber0.UserData=zeros(1, 3);
       end  
       switch selection
           case 'Окружность'
                prompt = {'Введите радиус окружности, м:','Введите абсциссу центра окружности, м:', ...
                    'Введите ординату центра окружности, м:', 'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
                dlgtitle = 'Ввод параметров окружности';
                dims = [1 40];
                definput = {'0.2','1', '1', '5000', '50'};
                circle=inputdlg(prompt,dlgtitle,dims, definput);
                if isempty(circle)
                    return
                end
                app.circle0=circle;
                makecircle(app);
                if app.checkall==1
                    app.circle0=[];
                    app.checkall=0;
                    return
                else
                   app.circles{end+1}=circle;
                   app.Lamp.Color='r';
                   app.addOS.Enable=0;
                end
                
           case 'Прямоугольник'
               prompt = {'Введите длину прямоугольника (по оси х), м:','Введите высоту прямоугольника (по оси у), м:', ...
                'Введите абсциссу левого нижнего края прямоугольника, м:', 'Введите ординату левого нижнего края прямоугольника, м:', ...
                'Введите потенциал электрода, В:', 'Введите параметр h/l при закруглении или число ЭЗ при его отсутствии):', 'Введите радиус закругления (0 при отсутствии), м:'};
               dlgtitle = 'Ввод параметров прямоугольника';
               dims = [1 100];
               definput = {'0.2','1', '2', '1', '0', '0.05', ''};
               react=inputdlg(prompt,dlgtitle,dims,definput);
               if isempty(react)
                  return
               end
               report=[];
               try
                   a=eval(react{7});
                   report=1;
               catch ME
                   report=getReport(ME);
               end
               if report~=1
                uialert(app.UIFigure, 'Ошибка ввода информации о закруглении.', ...
                        'Ошибка', 'Modal', true)
                    return
               end
               app.react0=react;
               
               if a==0
                   makereact(app);
                if app.checkall==1
                    app.react0=[];
                    app.checkall=0;
                    return
                else
                   app.reacts{end+1}=react;
                   app.Lamp.Color='r';
                   app.addOS.Enable=0;
                end                   
               else
                   makereact_mild(app);
                if app.checkall==1
                    app.react0=[];
                    app.checkall=0;
                    return
                else
                   app.reacts{end+1}=react;
                   app.Lamp.Color='r';
                   app.addOS.Enable=0;
                end                   
               end

           case 'Эллипс'
               prompt = {'Введите первую полуось эллипса (по оси х), м:','Введите вторую полуось эллипса (по оси у), м:', ...
                'Введите абсциссу центра эллипса, м:', 'Введите ординату центра эллипса, м:', ...
                'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
               dlgtitle = 'Ввод параметров эллипса';
               dims = [1 50];
               definput = {'0.3','0.5', '3', '1', '0', '300'};
               ellips=inputdlg(prompt,dlgtitle,dims,definput);
               if isempty(ellips)
                  return
               end
               app.ellips0=ellips;
               makeellips(app);
                if app.checkall==1
                    app.ellips0=[];
                    app.checkall=0;
                    return
                else
                   app.ellipses{end+1}=ellips;
                   app.Lamp.Color='r';
                   app.addOS.Enable=0;
                end               
       end
        end

        % Button pushed function: solve
        function solveButtonPushed(app, event)
            if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                return
            end
            if isequal(app.Switch.Value, 'Нет')
            f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется расчёт поля...', 'Cancelable', 'on', ...
               'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');
            end
            if isempty(app.OS.UserData)
            cla(app.Ecolor)
            cla(app.force)
            cla(app.EKT)
            numberOfChargedAxes=size(app.PP.UserData, 1);
            xk=app.PP.UserData(:, 3);
            yk=app.PP.UserData(:, 4);
            x=app.PP.UserData(:, 1);
            y=app.PP.UserData(:, 2);
            voltages=app.PP.UserData(:, 5);
            tic
            for i=1:numberOfChargedAxes
                for j=1:numberOfChargedAxes
                       if isequal(app.Switch.Value, 'Нет')
                          if f.CancelRequested
                          close(f)
                          return
                          end
                       end
                     potential_matrix(i, j)=log((sqrt((xk(i)-x(j))^2+(yk(i)+y(j))^2)...
                        /sqrt((xk(i)-x(j))^2+(yk(i)-y(j))^2)));
                end
            end
            epsilon=app.changeepsilon.Value;
            constValue=2*pi*8.85e-12*epsilon;
%             tol=1e-6; tol_int=[1e-5 1e-4 1e-3 1e-2];
%             [tau, flag]=lsqr(potential_matrix, constValue*voltages, tol);
%             if flag~=0
%                 for jh=1:length(tol_int)
%                     [tau, flag]=lsqr(potential_matrix, constValue*voltages, tol_int(jh));
%                 if flag==0
%                 app.showEps.Value=num2str(tol_int(jh));
%                 break
%                 end
%                 end
%             else app.showEps.Value=num2str(tol);
%             end
            %%%%%%%%%%% Регуляризация %%%%%%%%%%%%
            Ap=potential_matrix';
            alfa=1e-3;
            tau=linsolve(Ap*potential_matrix+alfa*eye(length(potential_matrix)), Ap*constValue*voltages); 
            %%%%%%%%%%%%%%%%%
            tol=norm(constValue*voltages-potential_matrix*tau)/norm(constValue*voltages);
            if isempty(app.giveinfo)
            app.showEps.Value=num2str(tol);
            app.showTime.Value=num2str(toc);
            app.showCond.Value=num2str(cond(potential_matrix), '%10.3e');
            end
            app.Lamp.Color='g';
            for i=1:numberOfChargedAxes
                for j=1:numberOfChargedAxes
                       if isequal(app.Switch.Value, 'Нет')
                          if f.CancelRequested
                          close(f)
                          return
                          end
                       end
                Ex(i, j)=((tau(j)/constValue)).*...
                    ((1./((xk(i)-x(j)).^2+(yk(i)-y(j)).^2))...
                    -(1./((xk(i)-x(j)).^2+(yk(i)+y(j)).^2))).*(xk(i)-x(j));
                Ey(i, j)=((tau(j)/constValue)).*...
                    (((yk(i)-y(j))./((xk(i)-x(j)).^2+(yk(i)-y(j)).^2))...
                    -((yk(i)+y(j))./((xk(i)-x(j)).^2+(yk(i)+y(j)).^2)));
                end
                E(i)=sqrt(sum(Ex(i, :)).^2+sum(Ey(i, :)).^2);
            end
            if isempty(app.giveinfo)
            app.showEmax.Value=num2str(max(E(:)), '%10.3f');
            end
            app.Eelectr=E';
            app.charges=tau;
            switch app.Switch.Value
                case 'Да'
                    return
                case 'Нет'
                    close(f)
                    drawingPP(app);
            end
            else %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Расчёт осесимметричного поля %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            cla(app.Ecolor)
            cla(app.force)
            cla(app.EKT)
            numberOfChargedAxes=size(app.OS.UserData, 1);
            rk=app.OS.UserData(:, 3);
            zk=app.OS.UserData(:, 4);
            r=app.OS.UserData(:, 1);
            z=app.OS.UserData(:, 2);
            voltages=app.OS.UserData(:, 5);
            tic            
            for i=1:numberOfChargedAxes    %%%%%%%%% Создание матрицы потенциальных коэффициентов
                for j=1:numberOfChargedAxes
                       if isequal(app.Switch.Value, 'Нет')
                          if f.CancelRequested
                          close(f)
                          return
                          end
                       end
                    if r(j)==0 %\\\\\\\
                        potential_matrix(i, j)=pi/2/sqrt(((zk(i)-z(j))^2+(rk(i)-r(j))^2)); %\\\\\\
                    else %\\\\\\\
                    k=sqrt(4*r(j)*rk(i)/((zk(i)-z(j))^2+(r(j)+rk(i))^2));
                    k=k^2;
                    K=ellipke(k);
                    potential_matrix(i, j)=K/...
                         sqrt(((zk(i)-z(j))^2+(r(j)+rk(i))^2));
                    k=sqrt(k);
                    end %\\\\\\
                end
            end
            epsilon=app.changeepsilon.Value;
            constValue=2*pi^2*8.85e-12*epsilon;
%             tol=1e-6; tol_int=[1e-5 1e-4 1e-3 1e-2];
%            [Q, flag]=lsqr(potential_matrix, constValue*voltages, tol);
 %           if flag~=0
  %              for jh=1:length(tol_int)
   %                 [Q, flag]=lsqr(potential_matrix, constValue*voltages, tol_int(jh));
    %            if flag==0
     %           app.showEps.Value=num2str(tol_int(jh));
      %          break
       %         end
        %        end
         %   else 
          %      app.showEps.Value=num2str(tol);
           % end
            %%%%%%%%%%% Регуляризация %%%%%%%%%%%%
             Ap=potential_matrix';
             alfa=1e-3;
             Q=linsolve(Ap*potential_matrix+alfa*eye(length(potential_matrix)), Ap*constValue*voltages); 
             %%%%%%%%%%%%%%%%%
            tol=norm(constValue*voltages-potential_matrix*Q)/norm(constValue*voltages);
            if isempty(app.giveinfo)
            app.showEps.Value=num2str(tol);
            app.showTime.Value=num2str(toc);
            app.showCond.Value=num2str(cond(potential_matrix), '%10.3e');
            end
            app.Lamp.Color='g';
            for i=1:numberOfChargedAxes
                for j=1:numberOfChargedAxes
                       if isequal(app.Switch.Value, 'Нет')
                          if f.CancelRequested
                          close(f)
                          return
                          end
                       end
                    if rk(i)~=0 && r(j)~=0
                    k=sqrt(4*r(j)*rk(i)/((zk(i)-z(j))^2+(r(j)+rk(i))^2));
                    kstr=sqrt(1-k^2);
                    k=k^2;
                    K=ellipke(k);
                    [~, int2]=ellipke(k);
                    k=sqrt(k);
                    Er(i, j)=Q(j)/constValue/4/sqrt(r(j)*rk(i))*...
                        k/rk(i)*(K+(((rk(i)-r(j))/2/r(j))*k^2/kstr^2-1)*int2);   
                    Ez(i, j)=Q(j)/constValue/8*(zk(i)-z(j))/(r(j)*rk(i))^(3/2)*(k)^3/(kstr)^2*int2; 
                    elseif rk(i)==0 && r(j)~=0
                        Er(i, j)=0;
                        Ez(i, j)=Q(j)/4/pi/8.85e-12/epsilon*(zk(i)-z(j))/(sqrt((r(j))^2+(zk(i)-z(j))^2))^3;
                    else
                        Er(i, j)=Q(j)/4/pi/8.85e-12/epsilon*(rk(i)-r(j))/(sqrt((rk(i)-r(j))^2+(zk(i)-z(j))^2))^3; %\\\
                        Ez(i, j)=Q(j)/4/pi/8.85e-12/epsilon*(zk(i)-z(j))/(sqrt((rk(i)-r(j))^2+(zk(i)-z(j))^2))^3; %\\\\
                    end
                end
                E(i)=sqrt(sum(Er(i, :)).^2+sum(Ez(i, :)).^2);
            end
            if isempty(app.giveinfo)
            app.showEmax.Value=num2str(max(E(:)), '%10.3f');
            end
            app.Eelectr=E';
            app.charges=Q;
            switch app.Switch.Value
                case 'Да'
                    return
                case 'Нет'
                    close(f)
                    drawingOS(app);
            end
            end
        end

        % Callback function
        function chargesnumberValueChanged(app, event)
%             if ~isempty(app.PP.UserData)
%                 cla(app.arr)
%                 app.Lamp.Color='r';
%                 [~, f2, ~]=unique(app.PP.UserData(:, 6:7), 'rows');
%                 massiv=app.PP.UserData(f2, :);
%                 app.PP.UserData=[];
%                 for i=1:size(massiv, 1)
%                     switch massiv(i, 6)
%                         case 1
%                             app.circle0=cellstr(num2str([(massiv(i, 8:10))'; massiv(i, 5)]));
%                             [~, ~, ~, ~]=makecircle(app);
%                         case 2
%                             app.react0=cellstr(num2str(([(massiv(i, 10:11))'; (massiv(i, 8:9))'; massiv(i, 5)])));
%                             [~, ~, ~, ~]=makereact(app);
%                         case 3
%                             app.ellips0=cellstr(num2str(([(massiv(i, 10:11))'; (massiv(i, 8:9))'; massiv(i, 5)])));
%                             [~, ~, ~, ~]=makeellips(app);                           
%                     end
%                 end
%             else if ~isempty(app.OS.UserData)
%                 cla(app.arr)
%                 app.Lamp.Color='r';
%                 [~, f2, ~]=unique(app.OS.UserData(:, 6:7), 'rows');
%                 massiv=app.OS.UserData(f2, :);
%                 app.OS.UserData=[];
%                 for i=1:size(massiv, 1)
%                     switch massiv(i, 6)
%                         case 1
%                             app.ball0=cellstr(num2str([(massiv(i, 8:9))'; massiv(i, 5)]));
%                             [~, ~, ~, ~]=makeball(app);
%                         case 2
%                             app.cylinder0=cellstr(num2str(([(massiv(i, 10:11))'; (massiv(i, 9))'; massiv(i, 5)])));
%                             [~, ~, ~, ~]=makecylinder(app);
%                         case 3
%                             app.tor0=cellstr(num2str(([(massiv(i, 8:10))'; massiv(i, 5)])));
%                             [~, ~, ~, ~]=maketor(app);
%                         case 4
%                             app.konus0=cellstr(num2str(([(massiv(i, 10:11))'; massiv(i, 9); massiv(i, 5)])));
%                             [~, ~, ~, ~]=makekonus(app);                             
%                     end
%                 end                    
%             end
%             end
        end

        % Button pushed function: changeplace
        function changeplaceButtonPushed(app, event)
            if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                return
            end
            if ~isempty(app.PP.UserData)
            massiv=[];
            app.listofelectrodes=[];
            [~, f2, ~]=unique(app.PP.UserData(:, 6:7), 'rows');
            massiv=app.PP.UserData(f2, :);
            for i=1:size(massiv, 1)
                switch massiv(i, 6)
                    case 1
                        app.listofelectrodes{i}=horzcat('Окружность ', num2str(massiv(i, 7)));
                    case 2
                        app.listofelectrodes{i}=horzcat('Прямоугольник ', num2str(massiv(i, 7)));
                    case 3
                        app.listofelectrodes{i}=horzcat('Эллипс ', num2str(massiv(i, 7)));                        
                end
            end
            
            else
                
            massiv=[];
            app.listofelectrodes=[];
            [~, f2, ~]=unique(app.OS.UserData(:, 6:7), 'rows');
            massiv=app.OS.UserData(f2, :);
            for i=1:size(massiv, 1)
                switch massiv(i, 6)
                    case 1
                        app.listofelectrodes{i}=horzcat('Шар ', num2str(massiv(i, 7)));
                    case 2
                        app.listofelectrodes{i}=horzcat('Цилиндр ', num2str(massiv(i, 7)));
                    case 3
                        app.listofelectrodes{i}=horzcat('Тор ', num2str(massiv(i, 7)));
                    case 4
                        app.listofelectrodes{i}=horzcat('Конус ', num2str(massiv(i, 7)));
                    case 5
                        app.listofelectrodes{i}=horzcat('Тор с прямоугольным сечением ', num2str(massiv(i, 7)));
                end               
            end
            end
            
            fig = uifigure;
            dd=uidropdown(fig, 'Items', [{'Выберите электрод:'} app.listofelectrodes], 'Position', [260, 218, 150, 22], ...
                     'Value', {'Выберите электрод:'}, 'ValueChangedFcn',@(dd,event) selection(dd));
            

            function selection(dd)
            val=dd.Value;
            for i=1:length(app.listofelectrodes)
                s(i)=strcmp(app.listofelectrodes{i}, val);
            end
            q=find(s==1);
            if ~isempty(app.PP.UserData)
                switch massiv(q, 6) 
                    
                    case 1
                    prompt = {'Введите радиус окружности, м:','Введите абсциссу центра окружности, м:', ...
                    'Введите ординату центра окружности, м:', 'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
                    dlgtitle = 'Ввод параметров окружности';
                    dims = [1 40];
                    definput=app.circles{massiv(q, 7)};
                    circle=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(circle)
                        close(fig)
                        return
                    end
                    app.circles{massiv(q, 7)}=circle;
                    app.PP.UserData=[];
                    app.chargesnumber0.UserData=[];    
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.circles{massiv(q, 7)}=definput;
                        app.PP.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)
                    
                    case 2
                    prompt = {'Введите длину прямоугольника (по оси х), м:','Введите высоту прямоугольника (по оси у), м:', ...
                    'Введите абсциссу левого нижнего края прямоугольника, м:', 'Введите ординату левого нижнего края прямоугольника, м:', ...
                    'Введите потенциал электрода, В:', 'Введите параметр h/l при закруглении или число ЭЗ при его отсутствии):', 'Введите радиус закругления (0 при отсутствии), м:'};
                    dlgtitle = 'Ввод параметров прямоугольника';
                    dims = [1 100];
                    definput=app.reacts{massiv(q, 7)};  
                    react=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(react)
                        close(fig)
                        return
                    end
                    app.reacts{massiv(q, 7)}=react;
                    app.PP.UserData=[];
                    app.chargesnumber0.UserData=[];
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.reacts{massiv(q, 7)}=definput;
                        app.PP.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)

                    case 3
                    prompt = {'Введите первую полуось эллипса (по оси х), м:','Введите вторую полуось эллипса (по оси у), м:', ...
                    'Введите абсциссу центра эллипса, м:', 'Введите ординату центра эллипса, м:', ...
                    'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
                    dlgtitle = 'Ввод параметров эллипса';
                    dims = [1 50];
                    definput=app.ellipses{massiv(q, 7)};
                    ellips=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(ellips)
                        close(fig)
                        return
                    end
                    app.ellipses{massiv(q, 7)}=ellips;
                    app.PP.UserData=[];
                    app.chargesnumber0.UserData=[];    
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.ellipses{massiv(q, 7)}=definput;
                        app.PP.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)
                end
         
            else
               switch massiv(q, 6) 
                   case 1
                    prompt = {'Введите радиус шара, м:', 'Введите ординату центра шара, м:', 'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
                    dlgtitle = 'Ввод параметров шара';
                    dims = [1 40];
                    definput = app.balls{massiv(q, 7)};
                    ball=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(ball)
                        close(fig)
                        return
                    end
                    app.balls{massiv(q, 7)}=ball;
                    app.OS.UserData=[];
                    app.chargesnumber0.UserData=[];    
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.balls{massiv(q, 7)}=definput;
                        app.OS.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)    
                    
                    case 2
                    prompt = {'Введите радиус цилиндра, м:','Введите высоту цилиндра, м:', ...
                    'Введите ординату нижнего края цилиндра, м:', 'Введите потенциал электрода, В:', 'Введите параметр h/l при закруглении или число ЭЗ при его отсутствии:', ...
                    'Введите радиус закругления (при отсутствии 0), м:'};
                    dlgtitle = 'Ввод параметров цилиндра';
                    dims = [1 100];
                    definput=app.cylinders{massiv(q, 7)};
                    cylinder=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(cylinder)
                        close(fig)
                        return
                    end
                    app.cylinders{massiv(q, 7)}=cylinder;
                    app.OS.UserData=[];
                    app.chargesnumber0.UserData=[];
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.cylinders{massiv(q, 7)}=definput;
                        app.OS.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;               
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)
                    
                    case 3
                    prompt = {'Введите малый радиус тора, м:', 'Введите высоту тора, м:', 'Введите ординату центра тора, м:', ...
                    'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
                    dlgtitle = 'Ввод параметров тора';
                    dims = [1 40];
                    definput=app.tors{massiv(q, 7)};
                    tor=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(tor)
                        close(fig)
                        return
                    end
                    app.tors{massiv(q, 7)}=tor;
                    app.OS.UserData=[];
                    app.chargesnumber0.UserData=[];    
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.tors{massiv(q, 7)}=definput;
                        app.OS.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)
                    
                    case 4
                    prompt = {'Введите радиус основания конуса, м:','Введите высоту конуса, м:', ...
                    'Введите ординату основания конуса, м:', 'Введите потенциал электрода, В:', 'Введите радиус закругления основания конуса, м:', ...
                    'Введите радиус закругления острия конуса, м:'};
                    dlgtitle = 'Ввод параметров конуса';
                    dims = [1 50];
                    definput=app.konuses{massiv(q, 7)};
                    konus=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(konus)
                        close(fig)
                        return
                    end
                    app.konuses{massiv(q, 7)}=konus;
                    app.OS.UserData=[];
                    app.chargesnumber0.UserData=[];    
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.konuses{massiv(q, 7)}=definput;
                        app.OS.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)
                   case 5
                   prompt = {'Введите длину прямоугольника (по оси х), м:','Введите высоту прямоугольника (по оси у), м:', ...
                    'Введите абсциссу левого нижнего края прямоугольника, м:', 'Введите ординату левого нижнего края прямоугольника, м:', ...
                    'Введите потенциал электрода, В:', 'Введите параметр h/l:', 'Введите радиус закругления, м:'};
                   dlgtitle = 'Ввод параметров прямоугольника';
                   dims = [1 100];
                    definput=app.torrects{massiv(q, 7)};
                    react=inputdlg(prompt,dlgtitle,dims,definput);
                    if isempty(react)
                        close(fig)
                        return
                    end
                    app.torrects{massiv(q, 7)}=react;
                    app.OS.UserData=[];
                    app.chargesnumber0.UserData=[];    
                    cla(app.arr)
                    AllElectrodes(app);
                    if app.checkall==1
                        app.torrects{massiv(q, 7)}=definput;
                        app.OS.UserData=[];
                        app.chargesnumber0.UserData=[];    
                        cla(app.arr)
                        AllElectrodes(app);
                        app.checkall=0;
                    else
                        app.Lamp.Color='r';
                    end
                    close(fig)                       
               end
            end
            end
        end

        % Button pushed function: deleteEL
        function deleteELButtonPushed(app, event)
            if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                return
            end
            massiv=[];
            app.listofelectrodes=[];
            if ~isempty(app.PP.UserData)
            [~, f2, ~]=unique(app.PP.UserData(:, 6:7), 'rows');
            massiv=app.PP.UserData(f2, :);
            for i=1:size(massiv, 1)
                switch massiv(i, 6)
                    case 1
                        app.listofelectrodes{i}=horzcat('Окружность ', num2str(massiv(i, 7)));
                    case 2
                        app.listofelectrodes{i}=horzcat('Прямоугольник ', num2str(massiv(i, 7)));
                    case 3
                        app.listofelectrodes{i}=horzcat('Эллипс ', num2str(massiv(i, 7)));  
                end
            end
            
            else
                
            [~, f2, ~]=unique(app.OS.UserData(:, 6:7), 'rows');
            massiv=app.OS.UserData(f2, :);
            for i=1:size(massiv, 1)
                switch massiv(i, 6)
                    case 1
                        app.listofelectrodes{i}=horzcat('Шар ', num2str(massiv(i, 7)));
                    case 2
                        app.listofelectrodes{i}=horzcat('Цилиндр ', num2str(massiv(i, 7)));
                    case 3
                        app.listofelectrodes{i}=horzcat('Тор ', num2str(massiv(i, 7)));
                    case 4
                        app.listofelectrodes{i}=horzcat('Конус ', num2str(massiv(i, 7)));
                    case 5
                        app.listofelectrodes{i}=horzcat('Тор с прямоугольным сечением ', num2str(massiv(i, 7)));                        
                end
            end                
            end
            
            fig = uifigure;
            dd=uidropdown(fig, 'Items', [{'Выберите электрод:'} app.listofelectrodes], 'Position', [260, 218, 150, 22], ...
                     'Value', {'Выберите электрод:'}, 'ValueChangedFcn',@(dd,event) selection(dd));
            
            function selection(dd)
            val=dd.Value;
            for i=1:length(app.listofelectrodes)
                s(i)=strcmp(app.listofelectrodes{i}, val);
            end
            q=find(s==1);
            
            if ~isempty(app.PP.UserData)            
            switch massiv(q, 6)
                case 1
                    app.circles(massiv(q, 7))=[];
                case 2
                    app.reacts(massiv(q, 7))=[];
                case 3
                    app.ellipses(massiv(q, 7))=[];
            end
            app.PP.UserData=[];
            app.chargesnumber0.UserData=[];
            cla(app.arr)
            AllElectrodes(app);
            app.Lamp.Color='r';
            close(fig)
            
            else
                
            switch massiv(q, 6)
                case 1
                    app.balls(massiv(q, 7))=[];
                case 2
                    app.cylinders(massiv(q, 7))=[];
                case 3
                    app.tors(massiv(q, 7))=[];
                case 4
                    app.konuses(massiv(q, 7))=[];
                case 5
                    app.torrects(massiv(q, 7))=[];
            end   
            app.OS.UserData=[];
            app.chargesnumber0.UserData=[];
            cla(app.arr)
            AllElectrodes(app);
            app.Lamp.Color='r'; 
            close(fig)
            end
            end
        end

        % Button pushed function: deletedata
        function deletedataButtonPushed(app, event)
%             if isempty(app.PP.UserData) && isempty(app.OS.UserData)
%                 app.addOS.Enable='on';
%                 app.addPP.Enable='on';                
%                 return
%             else
            cla(app.force)
            cla(app.arr)
            cla(app.Ecolor)
            cla(app.phicolor)
            cla(app.EKT)
            cla(app.opt12ndE)
            app.Lamp.Color='g';
            app.PP.UserData=[];
            app.chargesnumber0.UserData=[];
            app.circle0=[];
            app.react0=[];
            app.ellips0=[];
            app.charges=[];   
            app.Eelectr=[];            
            app.listofelectrodes=[];
            app.showCond.Value='';
            app.showEmax.Value='';
            app.showEps.Value='';
            app.showTime.Value='';
            app.addOS.Enable='on';
            app.addPP.Enable='on';
            app.OS.UserData=[];
            app.ball0=[];
            app.cylinder0=[];
            app.tor0=[];
            app.konus0=[];
            app.torrect0=[];
            app.circles=[];
            app.reacts=[];
            app.ellipses=[];
            app.balls=[];
            app.cylinders=[];
            app.tors=[];
            app.konuses=[];
            app.torrects=[];
            app.optresults.Data=[];
            app.calcXYE=[];
            app.showE.Value='';
            app.hesse=[];
            app.giveinfo=[];
            app.checkall=[];
            app.proofopt=[];
            app.itercounter='';
            app.precision='';
            app.generalinfo=[];
            app.contourPoints=[];
            app.ForSaveTable=[];
            app.firstiter=[];
            app.otheriter;
%             end
        end

        % Button pushed function: addOS
        function addOSButtonPushed(app, event)
        fig = uifigure;
        dd=uidropdown(fig, 'Items', [{'Выберите электрод:'} {'Шар','Цилиндр','Тор', 'Конус', 'Тор с прямоугольным сечением'}], 'Position', [260, 218, 150, 22], ...
                 'Value', {'Выберите электрод:'}, 'ValueChangedFcn',@(dd,event) selection(dd));   
           function selection(dd)
           selection=dd.Value;
           if isempty(app.OS.UserData)
           app.chargesnumber0.UserData=zeros(1, 5);
           end  
       switch selection
           case 'Шар'
                prompt = {'Введите радиус шара, м:', 'Введите ординату центра шара, м:', 'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
                dlgtitle = 'Ввод параметров шара';
                dims = [1 40];
                definput = {'0.2','1', '5000', '40'};
                ball=inputdlg(prompt,dlgtitle,dims,definput);
                if isempty(ball)
                    return
                end
                app.ball0=ball;
                close(fig)
                makeball(app);
                if app.checkall==1
                    app.ball0=[];
                    app.checkall=0;
                    return
                else
                    app.balls{end+1}=ball;
                    app.Lamp.Color='r';
                    app.addPP.Enable=0;
                end
                
           case 'Цилиндр'
               prompt = {'Введите радиус цилиндра, м:','Введите высоту цилиндра, м:', ...
                'Введите ординату нижнего края цилиндра, м:', 'Введите потенциал электрода, В:', 'Введите параметр h/l при закруглении или число ЭЗ при его отсутствии:', ...
                'Введите радиус закругления (при отсутствии 0), м:'};
               dlgtitle = 'Ввод параметров цилиндра';
               dims = [1 100];
               definput = {'0.3','1', '1.5', '0', '0.05', ''};
               cylinder=inputdlg(prompt,dlgtitle,dims,definput);
               if isempty(cylinder)
                  return
               end
               report=[];
               try
                   a=eval(cylinder{6});
                   report=1;
               catch ME
                   report=getReport(ME);
               end
               if report~=1
                uialert(app.UIFigure, 'Ошибка ввода информации о закруглении.', ...
                        'Ошибка', 'Modal', true)
                    return
               end                
               app.cylinder0=cylinder;
               close(fig)
               if a==0
                   makecylinder(app);
                   if app.checkall==1
                       app.cylinder0=[];
                       app.checkall=0;
                       return
                   else
                       app.cylinders{end+1}=cylinder;            
                       app.Lamp.Color='r';
                       app.addPP.Enable=0;
                   end
               else
                   makecylinder_mild(app);
                   if app.checkall==1
                       app.cylinder0=[];
                       app.checkall=0;
                       return
                   else
                       app.cylinders{end+1}=cylinder;            
                       app.Lamp.Color='r';
                       app.addPP.Enable=0;
                   end                   
               end

           case 'Тор'
               prompt = {'Введите малый радиус тора, м:', 'Введите высоту тора, м:', 'Введите ординату центра тора, м:', ...
                'Введите потенциал электрода, В:', 'Введите число ЭЗ:'};
               dlgtitle = 'Ввод параметров тора';
               dims = [1 40];
               definput = {'0.5','0.4', '1', '5000', '100'};
               tor=inputdlg(prompt,dlgtitle,dims,definput);
               if isempty(tor)
                  return
               end
               app.tor0=tor;
               close(fig)
               maketor(app);
               if app.checkall==1
                   app.tor0=[];
                   app.checkall=0;
                   return
               else
                   app.tors{end+1}=tor;
                   app.Lamp.Color='r';
                   app.addPP.Enable=0;
               end
           case 'Конус'
               prompt = {'Введите радиус основания конуса, м:','Введите высоту конуса, м:', ...
                'Введите ординату основания конуса, м:', 'Введите потенциал электрода, В:', 'Введите радиус закругления основания конуса, м:', ...
                'Введите радиус закругления острия конуса, м:'};
               dlgtitle = 'Ввод параметров конуса';
               dims = [1 50];
               definput = {'0.5','1', '4', '0', '0.1', '0.1'}; 
               konus=inputdlg(prompt,dlgtitle,dims,definput);
               if isempty(konus)
                  return
               end
               app.konus0=konus;
               close(fig)
               makekonus_mild(app);
               if app.checkall==1
                   app.konus0=[];
                   app.checkall=0;
                   return
               else
                   app.konuses{end+1}=konus;
                   app.Lamp.Color='r';
                   app.addPP.Enable=0;               
               end
           case 'Тор с прямоугольным сечением'
               prompt = {'Введите длину прямоугольника (по оси х), м:','Введите высоту прямоугольника (по оси у), м:', ...
                'Введите абсциссу левого нижнего края прямоугольника, м:', 'Введите ординату левого нижнего края прямоугольника, м:', ...
                'Введите потенциал электрода, В:', 'Введите параметр h/l:', 'Введите радиус закругления, м:'};
               dlgtitle = 'Ввод параметров прямоугольника';
               dims = [1 100];
               definput = {'0.2','1', '2', '1', '0', '0.05', ''};
               react=inputdlg(prompt,dlgtitle,dims,definput);
               if isempty(react)
                  return
               end
               app.torrect0=react;
               close(fig)
               maketorrect(app);
               if app.checkall==1
                   app.torrect0=[];
                   app.checkall=0;
                   return
               else
                   app.torrects{end+1}=react;
                   app.Lamp.Color='r';
                   app.addPP.Enable=0;
               end               
       end
       end
        end

        % Value changed function: numberoflines
        function numberoflinesValueChanged(app, event)
            if (~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)) && ~isempty(app.showEmax.Value)
                app.Lamp.Color='r';
            end  
        end

        % Value changed function: changeepsilon
        function changeepsilonValueChanged(app, event)
            if (~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)) && ~isempty(app.showEmax.Value)
                app.Lamp.Color='r';
            end  
        end

        % Button pushed function: saveresults
        function saveresultsButtonPushed(app, event)
            if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                uialert(app.UIFigure, 'Нет данных для сохранения.', 'Ошибка', 'Modal', true)
                return
            end

            if isequal(app.Lamp.Color(1), 1)
                uialert(app.UIFigure, 'Данные были изменены, или расчёт не был проведён.', 'Ошибка', 'Modal', true)
                return
            end
            msg='Какие данные Вы хотите сохранить?';
            title='Сохранение данных';
            selection=uiconfirm(app.UIFigure, msg, title,...
           'Options', {'Напряжённости на электродах','Напряжённости в расчётной области', 'Потенциалы в расчётной области', 'Отмена'},...
           'DefaultOption', 1, 'CancelOption', 4);
           switch selection
               case 'Напряжённости на электродах'
                   if ~isempty(app.PP.UserData)
                       koordx=app.PP.UserData(:, 3);
                       koordy=app.PP.UserData(:, 4);
                       table0=table(koordx, koordy, app.Eelectr);
                   else
                       koordx=app.OS.UserData(:, 3);
                       koordy=app.OS.UserData(:, 4);
                       table0=table(koordx, koordy, app.Eelectr);
                   end
                   table0.Properties.VariableNames={'xk_m','yk_m','E_V_per_m'};
                   data=table0;
                   [filename, folder]=uiputfile('.txt', 'Имя файла', 'EonElectrodes.txt');
                   if isequal(filename, 0) || isequal(folder, 0)
                       uialert(app.UIFigure, 'Не указан путь назначения.', 'Ошибка', 'Modal', true)
                       return
                   else
                writetable(data, fullfile(folder, filename), 'FileType', 'text', 'WriteVariableNames', true, 'WriteRowNames', false, ...
                       'Delimiter', 'space')
                   end
               case 'Отмена'
                   return
               case 'Напряжённости в расчётной области'
                       koordx=app.x0y0E0(:, 1);
                       koordy=app.x0y0E0(:, 2);
                       E0=app.x0y0E0(:, 3);
                       table0=table(koordx, koordy, E0);
                   table0.Properties.VariableNames={'x_m','y_m','E_V_per_m'};
                   data=table0;
                   [filename, folder]=uiputfile('.txt', 'Имя файла', 'EinArea.txt');
                   if isequal(filename, 0) || isequal(folder, 0)
                       uialert(app.UIFigure, 'Не указан путь назначения.', 'Ошибка', 'Modal', true)
                       return
                   else
                    writetable(data, fullfile(folder, filename), 'FileType', 'text', 'WriteVariableNames', true, 'WriteRowNames', false, ...
                       'Delimiter', 'space')
                   end
               case 'Потенциалы в расчётной области'
                       koordx=app.x0y0phi0(:, 1);
                       koordy=app.x0y0phi0(:, 2);
                       phi0=app.x0y0phi0(:, 3);
                       table0=table(koordx, koordy, phi0);
                   table0.Properties.VariableNames={'x_m','y_m','phi_V'};
                   data=table0;
                   [filename, folder]=uiputfile('.txt', 'Имя файла', 'potentials.txt');
                   if isequal(filename, 0) || isequal(folder, 0)
                       uialert(app.UIFigure, 'Не указан путь назначения.', 'Ошибка', 'Modal', true)
                       return
                   else
                    writetable(data, fullfile(folder, filename), 'FileType', 'text', 'WriteVariableNames', true, 'WriteRowNames', false, ...
                       'Delimiter', 'space')
                   end                   
           end            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switchposition = app.Switch.Value;
            switch switchposition
                case 'Нет'
                    app.solve.Enable=1;
                    app.saveresults.Enable=1;
                    app.AddOpt.Enable=0;
                    app.Opt.Enable=0;
                    if ~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)
                       app.Lamp.Color='r';
                    else
                       app.Lamp.Color='g';
                    end
                    app.saveoptresults.Enable='off';
                case 'Да'
%                     if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                    app.AddOpt.Enable='on';
                    app.solve.Enable='off';
                    app.saveresults.Enable='off';
%                      else
%                         uialert(app.UIFigure, 'Геометрия должна быть задана после включения режима оптимизации.', 'Ошибка', 'Modal', true)
%                         app.Switch.Value='Нет';
%                     end
            end
        end

        % Button pushed function: AddOpt
        function AddOptButtonPushed(app, event)
                prompt = {'Перечислите обозначения (через пробел по образцу):', 'Введите начальные приближения:', ...
                    'Введите максимально допустимую погрешность оптимизации:', ...
                    'Минимизация максимальной из напряжённостей (во всей системе - пустое поле, в точках - координаты х и у через пробел):', ...
                    'Минимизация напряжённости до заданного значения, В/м (число или пустое поле):', 'Шаг взятия производной:'};
                dlgtitle = 'Ввод параметров оптимизации';
                dims = [1 120];
                if isempty(app.optparam)
                    definput = {'app.param(1) app.param(2)','', '1e-6', '', '', '1e-3'};
                else
                    definput = app.optparam;
                end
                paramopt=inputdlg(prompt,dlgtitle,dims,definput);
                if isempty(paramopt)
                    return
                end
                report=[];
                if ~isempty(paramopt)
                    c=strsplit(paramopt{1});
                    try
                    for i=1:length(c)
                    b{i}=horzcat(c{i}(1:10), c{i}(end));
                    d{i}=c{i}(11:end-1); 
                    end
                    if ~all(cellfun(@(x) isequal(x, 'app.param()'), b)) || ~all(isequal(d, strsplit(num2str(1:length(c)))))
                        uialert(app.UIFigure, 'Обнаружены ошибки в перечисленных обозначениях оптимизируемых параметров.', ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, 'Обнаружены ошибки в перечисленных обозначениях оптимизируемых параметров.', ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                end
                report=[];
                if ~isempty(paramopt)
                    x=strsplit(paramopt{2});
                    try
                    s=cellfun(@str2double, x);
                    app.param=s;
                    if length(s)~=length(c) || any(arrayfun(@imag, s)) || any(arrayfun(@isnan, s)) || any(arrayfun(@isinf, s)) 
                        uialert(app.UIFigure, {'Количество начальных приближений должно совпадать с числом оптимизируемых параметров.'; ...
                            'Начальные приближения должны быть конечными действительными числами.'}, ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, {'Количество начальных приближений должно совпадать с числом оптимизируемых параметров.'; ...
                            'Начальные приближения должны быть конечными действительными числами.'}, ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                end
                report=[];
                if ~isempty(paramopt)
                    try
                    z=[strsplit(paramopt{3}); strsplit(paramopt{6})];    
                    k=cellfun(@str2double, z);
                    if length(k)~=2 || any(arrayfun(@imag, k)) || any(arrayfun(@isnan, k)) || any(arrayfun(@isinf, k)) || ~(k(1)>=1e-7 && k(1)<=1e-2) || ~(k(2)>=1e-6 && k(2)<=1e-1)
                        uialert(app.UIFigure, {'Требуемая погрешность оптимизации и шаг производной должны быть конечными действительными числами.'; ...
                            'Требуемая погрешность оптимизации должна быть не более 0,01 и не менее 1e-7.'; 'Шаг производной должен быть не более 0,1 и не менее 1е-6.'}, ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, {'Требуемая погрешность оптимизации и шаг производной должны быть конечными действительными числами.'; ...
                            'Требуемая погрешность оптимизации должна быть не более 0,01 и не менее 1e-7.'; 'Шаг производной должен быть не более 0,1 и не менее 1е-6.'}, ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                end
                 report=[];
                 if ~isempty(paramopt) && ~isempty(paramopt{4})
                    try
                    g=strsplit(paramopt{4});    
                    if mod(length(g), 2)~=0
                        uialert(app.UIFigure, 'Обнаружены неполные пары введённых координат, общее число которых должно быть кратно 2.', ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, 'Обнаружены неполные пары введённых координат, общее число которых должно быть кратно 2.', ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                 end
                 
                 report=[];
                 if ~isempty(paramopt) && ~isempty(paramopt{5})
                    try
                    o=eval(paramopt{5});    
%                     if length(o)~=1
%                         uialert(app.UIFigure, 'Требуемый уровень напряжённости должен быть представлен одним числом.', ...
%                                 'Ошибка', 'Modal', true)
%                         app.optparam=definput;
%                         app.Opt.Enable=0;
%                         return                           
%                     end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, 'Обнаружены ошибки в задании требуемого уровня напряжённости.', ...
                                'Ошибка', 'Modal', true)
                        app.optparam=definput;
                        app.Opt.Enable=0;
                        return                           
                    end
                end                 

                app.optparam=paramopt;
                app.Opt.Enable=1;
                for i=1:length(paramopt)
                    param_cmp(i)=isequal(paramopt{i}, definput{i});
                end
                if ~all(param_cmp) && (~isempty(app.OS.UserData) || ~isempty(app.PP.UserData))
                    app.Lamp.Color='r';
                end
                
        end

        % Button pushed function: Opt
        function OptPushed(app, event)
             if isempty(app.OS.UserData) && isempty(app.PP.UserData)
                 uialert(app.UIFigure, 'Не задана геометрия электродов.', ...
                         'Ошибка', 'Modal', true)
                     return
             end
             method=app.methodchoose.SelectedObject;
             switch method.Text
                 case 'Метод Левенберга-Марквардта'
                     LevenbergMarkvardt(app);
                 case 'Метод градиентного спуска'
                     GradientMethod(app);
                 case 'Метод BFGS'
                     BFGS(app);
             end                            
                   if app.proofopt~=0
                   app.Lamp.Color='g';
                   app.giveinfo=0;
                   app.optresults.Data=[(1:length(app.param))' app.param];
                   app.saveoptresults.Enable='on';
                   f=uiprogressdlg(app.UIFigure, 'Title', 'Статус', 'Message', 'Выполняется построение графиков...', 'Cancelable', 'on', ...
                     'CancelText', 'Отмена', 'Value', 0, 'Indeterminate', 'on');                    
                   switch length(app.param)
                       case 1
                           Dopt=app.param;
                           Dplot=linspace(0.5*Dopt, 1.5*Dopt, 50);
                           flag=[];
                           for i=1:length(Dplot)
                               f.Message=horzcat('Выполняется построение графиков: точка №', num2str(i), ' из ', num2str(length(Dplot)));
                                 if f.CancelRequested
                                     close(f)
                                     app.param(1)=Dopt;
                                     cla(app.arr)
                                     app.PP.UserData=[];
                                     app.OS.UserData=[];
                                     AllElectrodes(app);
                                     app.giveinfo=[];
                                     return
                                 end                               
                               app.param(1)=Dplot(i);
                               E_collect=ForOptimization(app);
                               if ~isempty(E_collect)
                                   Eplot(i)=E_collect;
                               else
                                  flag=i;
                                  Dplot(i)=[];
                                  uialert(app.UIFigure, ...
                                  'В программе предусмотрено построение графика зависимости напряжённости от 0,5 до 1,5 оптимизируемого параметра. К сожалению, расчёт поля удалось произвести не при каждом значении из данного диапазона.', ...
                                  'Предупреждение', 'Icon', 'warning', 'Modal', true)
                               break
                               end
                           end
                           cla(app.opt12ndE)
                           cla(app.arr)
                           app.param(1)=Dopt;
                           app.PP.UserData=[];
                           app.OS.UserData=[];
                           AllElectrodes(app);
                           f.Message='Выполняется построение графиков...';
%                            if ~isempty(app.optparam{5})
%                                Elevel=eval(app.optparam{5});
%                            else
%                                Elevel=0;
%                            end
                           if isempty(flag)
                               plot(app.opt12ndE, Dplot, Eplot)
                           elseif flag~=1
                               plot(app.opt12ndE, Dplot(1:i-1), Eplot(1:i-1))
                           end
                           app.opt12ndE.XLabel.String='D1';
                           app.opt12ndE.YLabel.String='E, В/м';
                           app.opt12ndE.View=[0 90];
                       case 2
                           Dopt1=app.param(1);
                           Dopt2=app.param(2);
                           flag=[];
                           b=[];
                           [X, Y]=meshgrid(linspace(0.7*Dopt1, 1.3*Dopt1, 15), linspace(0.7*Dopt2, 1.3*Dopt2, 15));
%                            x=X(:);
%                            y=Y(:);
                           for i=1:length(X)
                               for j=1:length(Y)
                                 f.Message=horzcat('Выполняется построение графиков: точка №', num2str((i-1)*length(X)+j), ' из ', num2str(length(X)^2)); 
                                 if f.CancelRequested
                                     close(f)
                                     app.param(1)=Dopt1;
                                     app.param(2)=Dopt2;
                                     cla(app.arr)
                                     app.PP.UserData=[];
                                     app.OS.UserData=[];
                                     AllElectrodes(app);                                     
                                     app.giveinfo=[];
                                     return
                                 end                               
                               app.param(1)=X(i, j);
                               app.param(2)=Y(i, j);
                               E_collect=ForOptimization(app);
                               if ~isempty(E_collect)
                                   Eplot(i, j)=E_collect;
                               else
                                  flag=[i j];
                                  uialert(app.UIFigure, ...
                                  'В программе предусмотрено построение графика зависимости напряжённости от 0,7 до 1,3 оптимизируемых параметров. К сожалению, расчёт поля удалось произвести не при каждом значении из данного диапазона.', ...
                                  'Предупреждение', 'Icon', 'warning', 'Modal', true)
                                   b=1;
                                   break
                                   end
                               end
                               if b==1
                                   break
                               end
                           end
                           cla(app.opt12ndE)
                           cla(app.arr)
                           app.param(1)=Dopt1;
                           app.param(2)=Dopt2;
                           app.PP.UserData=[];
                           app.OS.UserData=[];
                           AllElectrodes(app);
%                            if ~isempty(app.optparam{5})
%                                Elevel=eval(app.optparam{5});
%                            else
%                                Elevel=0;
%                            end                           
                           f.Message='Выполняется построение графиков...';
                           if isempty(b)
                               contour(app.opt12ndE, X, Y, Eplot, 100, 'ShowText', 'on')
                           elseif all(flag>=2)
                               contour(app.opt12ndE, X(1:i-1, :), Y(1:i-1, j), Eplot(1:i-1, :), 100, 'ShowText', 'on')
                           end                            
%                            Eplot=reshape(Eplot, 15, 15);
%                          shading(app.opt12ndE, 'interp')
%                          c=colorbar(app.opt12ndE);         
%                          colormap(app.opt12ndE, jet);
%                            a1=X;
%                            a2=Y;
%                            a3=Eplot;
                           app.opt12ndE.XLabel.String='D1';
                           app.opt12ndE.YLabel.String='D2';
%                          app.opt12ndE.View=[-37.5 30];
                           app.opt12ndE.View=[0 90];
                   end
                   close(f)
                    else 
% isempty(app.firstiter) 
                         app.Lamp.Color='r';
                         app.saveoptresults.Enable='off';
%                    elseif ~isempty(app.firstiter)
%                          app.saveoptresults.Enable='on';
                   end
                   app.giveinfo=[];
        end

        % Cell edit callback: optresults
        function optresultsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;    
        end

        % Value changed function: choosearea
        function chooseareaValueChanged(app, event)
            position = app.choosearea.Value;
            switch position
                case 'Задать область построения'
                prompt = {'Введите x-координаты границы области (через пробел в метрах)', ...
                    'Введите y-координаты границы области (через пробел в метрах)', ...
                    'Введите число расчётных точек по оси х', 'Введите число расчётных точек по оси у'};
                dlgtitle = 'Ввод параметров области построения графиков';
                dims = [1 40];
                if isempty(app.areatodraw)
                    definput = {'','', '', '', ''};
                else
                    definput = app.areatodraw;
                end
                paramarea=inputdlg(prompt,dlgtitle,dims,definput);
                
                if isempty(paramarea)
                    app.choosearea.Value='Границы по умолчанию';
                    return
                end
                
                report=[];
                if ~isempty(paramarea)
                    x=strsplit(paramarea{1});
                    y=strsplit(paramarea{2});
                    try
                    s1=cellfun(@str2double, x);
                    s2=cellfun(@str2double, y);
                    if length(s1)~=2 || length(s2)~=2 || any(arrayfun(@imag, s1)) || any(arrayfun(@isnan, s1)) || any(arrayfun(@isinf, s1)) || ...
                            any(arrayfun(@imag, s2)) || any(arrayfun(@isnan, s2)) || any(arrayfun(@isinf, s2)) || s1(1)>=s1(2) || s2(1)>=s2(2) 
                        uialert(app.UIFigure, 'Граничные координаты области х и у должны содержать 2 конечных действительных числа, первое из которых меньше второго.', ...
                                'Ошибка', 'Modal', true)
                        app.areatodraw=definput;
                        app.choosearea.Value='Границы по умолчанию';
                        return                           
                    end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, 'Граничные координаты области х и у должны содержать 2 конечных действительных числа.', ...
                                'Ошибка', 'Modal', true)
                        app.areatodraw=definput;
                        app.choosearea.Value='Границы по умолчанию';
                        return                           
                    end
                end                
                
                report=[];
                if ~isempty(paramarea)
                    x1=strsplit(paramarea{3});
                    y1=strsplit(paramarea{4});
                    try
                    s3=cellfun(@str2double, x1);
                    s4=cellfun(@str2double, y1);
                    if length(s3)~=1 || length(s4)~=1 || any(arrayfun(@imag, s3)) || any(arrayfun(@isnan, s3)) || any(arrayfun(@isinf, s3)) || ...
                            any(arrayfun(@imag, s4)) || any(arrayfun(@isnan, s4)) || any(arrayfun(@isinf, s4)) || s3<=1 || s4<=1 || ...
                        mod(s3, 1) || mod(s4, 1)
                        uialert(app.UIFigure, 'Число расчётных точек по осям должно быть задано натуральным числом не менее 2.', ...
                                'Ошибка', 'Modal', true)
                        app.areatodraw=definput;
                        app.choosearea.Value='Границы по умолчанию';
                        return                           
                    end
                    report=1;
                    catch ME
                        report=getReport(ME);
                    end
                    if report~=1
                        uialert(app.UIFigure, 'Число расчётных точек по осям должно быть задано натуральным числом не менее 2.', ...
                                'Ошибка', 'Modal', true)
                        app.areatodraw=definput;
                        app.choosearea.Value='Границы по умолчанию';
                        return                           
                    end
                end                  
                     
                if any(cellfun(@isempty, paramarea))
                    uialert(app.UIFigure, 'Присутствуют незаполненные поля.', 'Ошибка', 'Modal', true)
                    app.areatodraw=definput;
                    app.choosearea.Value='Границы по умолчанию';
                    return
                else                    
                    app.areatodraw=paramarea; 
                end
                if ~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)
                app.Lamp.Color='r';
                end
                case 'Границы по умолчанию'
                    if ~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)
                    app.Lamp.Color='r';
                    end
            end
        end

        % Value changed function: insertX
        function insertXValueChanged(app, event)
            if (~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)) && isequal(app.Lamp.Color, [0 1 0])
                if (isequal(app.addOS.Enable, 1) || isequal(app.addOS.Enable, 'on')) && app.insertX.Value<0
                    uialert(app.UIFigure, 'В осесимметричном поле радиальная координата должна быть неотрицательной.', 'Ошибка', 'Modal', true)
                    app.insertX.Value=0;
                    app.showE.Value='';                    
                    return
                end                
                app.calcXYE(1) = app.insertX.Value;
                if length(app.calcXYE)~=2
                    app.calcXYE(2)=0;
                end
                [~]=FindXYE(app);
            else
                uialert(app.UIFigure, 'Поле не было посчитано или отсутствует геометрия электродов.', 'Ошибка', 'Modal', true)
                app.insertX.Value=0;
                app.showE.Value='';
                return
            end
        end

        % Value changed function: insertY
        function insertYValueChanged(app, event)
            if (~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)) && isequal(app.Lamp.Color, [0 1 0])
                 if (isequal(app.addPP.Enable, 1) || isequal(app.addPP.Enable, 'on')) && app.insertY.Value<0
                    uialert(app.UIFigure, 'В плоскопараллельном поле у-координата должна быть неотрицательной.', 'Ошибка', 'Modal', true)
                    app.insertY.Value=0;
                    app.showE.Value='';                    
                    return
                end                
                app.calcXYE(2) = app.insertY.Value;
                [~]=FindXYE(app);
            else
                uialert(app.UIFigure, 'Поле не было посчитано или отсутствует геометрия электродов.', 'Ошибка', 'Modal', true)
                app.insertY.Value=0;
                app.showE.Value='';
                return
            end
        end

        % Value changed function: Ephi
        function EphiValueChanged(app, event)
        if ~strcmp(app.Switch.Value, 'Нет')
           uialert(app.UIFigure, 'Расчёт поля в точке доступен только при выключенном режиме оптимизации.', 'Ошибка', 'Modal', true)
           return
        end
        if isempty(app.calcXYE)
        app.calcXYE(1)=0;
        app.calcXYE(2)=0;
        end
        if (~isempty(app.PP.UserData) || ~isempty(app.OS.UserData)) && isequal(app.Lamp.Color, [0 1 0])
            [~]=FindXYE(app);
        else
            uialert(app.UIFigure, 'Поле не было посчитано или отсутствует геометрия электродов.', 'Ошибка', 'Modal', true)
            app.showE.Value='';
        end
        end

        % Selection changed function: methodchoose
        function methodchooseSelectionChanged(app, event)
            if (~isempty(app.OS.UserData) || ~isempty(app.PP.UserData)) && (isequal(app.Opt.Enable, 1) || isequal(app.Opt.Enable, 'on'))
                app.Lamp.Color='r';
            end
        end

        % Button pushed function: saveoptresults
        function saveoptresultsButtonPushed(app, event)
            if isempty(app.PP.UserData) && isempty(app.OS.UserData)
                uialert(app.UIFigure, 'Нет данных для сохранения.', 'Ошибка', 'Modal', true)
                return
            end

            if isequal(app.Lamp.Color(1), 1)
                uialert(app.UIFigure, 'Данные были изменены, или расчёт не был проведён.', 'Ошибка', 'Modal', true)
                return
            end
            msg='Какие данные Вы хотите сохранить?';
            title='Сохранение данных';
            selection=uiconfirm(app.UIFigure, msg, title,...
           'Options', {'Общая информация о проведённой оптимизации','Таблица со сведениями об итерациях', 'Отмена'},...
           'DefaultOption', 1, 'CancelOption', 3);
            switch selection
               case 'Общая информация о проведённой оптимизации'
                   app.generalinfo{1}=app.itercounter;
                   app.generalinfo{2}=app.optparam{2};
                   if isempty(app.optparam{4})
                       app.generalinfo{4}='everywhere';
                   else
                       app.generalinfo{4}=app.optparam{4};
                   end
                   app.generalinfo{5}=app.optparam{6};
                   app.generalinfo{6}=app.optparam{3};
                   app.generalinfo{7}=app.precision{1};
                   app.generalinfo{8}=app.methodchoose.SelectedObject.Text;
                   app.generalinfo{3}=num2str(app.optresults.Data(:, 2)');
                   if isempty(app.optparam{5})
                       app.generalinfo{9}='minimum';
                   else
                       app.generalinfo{9}=app.optparam{5};
                   end
                   tableWithInformations=table(app.generalinfo');  
                   tableWithInformations.Properties.RowNames={'Number_of_iterations1:'; ...
                       'd_initial:'; 'd_optimal:'; 'Location_of_finding:'; 'Step_of_derivation:'; 'Requested_precision:'; 'Achieved_precision:'; ...
                       'Method:'; 'Requested_Electrical_Field:'};
                   data=tableWithInformations;
                   [filename, folder]=uiputfile('.txt', 'Имя файла', 'GeneralInformation.txt');
                   if isequal(filename, 0) || isequal(folder, 0)
                       uialert(app.UIFigure, 'Не указан путь назначения.', 'Ошибка', 'Modal', true)
                       return
                   else
                writetable(data, fullfile(folder, filename), 'FileType', 'text', 'WriteVariableNames', false, 'WriteRowNames', true, ...
                       'Delimiter', 'space')
                   end
               case 'Отмена'
                   return
               case 'Таблица со сведениями об итерациях'
                   g=app.ForSaveTable(2:end, end);
                   table0=app.ForSaveTable(1:end-1, :);
                   table0(1:end, end)=g;
%                    table0.Properties.VariableNames{1}='Iteration';
%                    for i=1:length(str2num(app.optparam{2}))
%                       table0.Properties.VariableNames{i+1}=horzcat('Parameter', num2str(i));
%                    end
%                    table0.Properties.VariableNames{end+1}='Ei_in_V_pro_meter';
%                    table0.Properties.VariableNames{end+1}='Enew_in_V_pro_meter';
%                    table0.Properties.VariableNames{end+1}='E_error';
%                    x=length(table0.Properties.VariableNames);
%                    for i=1:length(str2num(app.optparam{2}))
%                        table0.Properties.VariableNames{x+i}=horzcat('Error of parameter', num2str(i));
%                    end
%                    table0.Properties.VariableNames{end+1}='Maximal Error';

                   y{1}='Iteration';
                   for i=1:length(str2num(app.optparam{2}))
                      y{i+1}=horzcat('Parameter', num2str(i));
                   end
                   y{end+1}='Ei_in_V_pro_meter';
                   y{end+1}='Enew_in_V_pro_meter';
                   y{end+1}='E_error';
        
                   x=length(y);
                   for i=1:length(str2num(app.optparam{2}))
                       y{x+i}=horzcat('Error_of_parameter', num2str(i));
                   end
                   y{end+1}='Maximal_Error';
                   data=array2table(table0);
                   data.Properties.VariableNames=y;
                   [filename, folder]=uiputfile('.txt', 'Имя файла', 'TableAboutIterations.txt');
                   if isequal(filename, 0) || isequal(folder, 0)
                       uialert(app.UIFigure, 'Не указан путь назначения.', 'Ошибка', 'Modal', true)
                       return
                   else
                    writetable(data, fullfile(folder, filename), 'FileType', 'text', 'WriteVariableNames', true, 'WriteRowNames', false, ...
                       'Delimiter', 'space')
                   end                  
           end             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.graphs = uitabgroup(app.UIFigure);
            app.arrange = uitab(app.graphs);
            app.arrange.Title = 'Расстановка ЭЗ';
            app.arr = uiaxes(app.arrange);
            title(app.arr, 'Система электродов и эквивалентные заряды')
            xlabel(app.arr, 'x, м')
            ylabel(app.arr, 'y, м')
            app.E = uitab(app.graphs);
            app.E.Title = 'Модуль напряжённости электрического поля';
            app.Ecolor = uiaxes(app.E);
            title(app.Ecolor, 'Модуль напряжённости электрического поля')
            xlabel(app.Ecolor, 'x, м')
            ylabel(app.Ecolor, 'у, м')
            app.EonKT = uitab(app.graphs);
            app.EonKT.Title = 'Напряжённость в контурных точках';
            app.EKT = uiaxes(app.EonKT);
            title(app.EKT, 'Напряжённость в контурных точках')
            xlabel(app.EKT, 'Направление по поверхности электрода')
            ylabel(app.EKT, 'Напряжённость Е, В/м')
            app.phi = uitab(app.graphs);
            app.phi.Title = 'Распределение потенциала';
            app.phicolor = uiaxes(app.phi);
            title(app.phicolor, 'Распределение электрического потенциала')
            xlabel(app.phicolor, 'y, м')
            ylabel(app.phicolor, 'y, м')
            app.forcelines = uitab(app.graphs);
            app.forcelines.Title = 'Силовые линии';
            app.force = uiaxes(app.forcelines);
            title(app.force, 'Силовые линии поля')
            xlabel(app.force, 'x, м')
            ylabel(app.force, 'у, м')
            app.opt12nd = uitab(app.graphs);
            app.opt12nd.Title = 'Оптимизация';
            app.opt12ndE = uiaxes(app.opt12nd);
            title(app.opt12ndE, 'Зависимость напряжённости Е от оптимизируемых параметров D (одномерная/двухмерная оптимизация)')
            xlabel(app.opt12ndE, 'D1')
            ylabel(app.opt12ndE, 'D2')
            app.ELinsert = uipanel(app.UIFigure);
            app.ELinsert.Title = 'Ввод геометрической конфигурации';
            app.PP = uilabel(app.ELinsert);
            app.PP.Text = 'Плоскопараллельное поле';
            app.OS = uilabel(app.ELinsert);
            app.OS.Text = 'Осесимметричное поле';
            app.addPP = uibutton(app.ELinsert, 'push');
            app.addPP.ButtonPushedFcn = createCallbackFcn(app, @addPPButtonPushed, true);
            app.addPP.Text = 'Добавить электрод';
            app.addOS = uibutton(app.ELinsert, 'push');
            app.addOS.ButtonPushedFcn = createCallbackFcn(app, @addOSButtonPushed, true);
            app.addOS.Text = 'Добавить электрод';
            app.changeplace = uibutton(app.ELinsert, 'push');
            app.changeplace.ButtonPushedFcn = createCallbackFcn(app, @changeplaceButtonPushed, true);
            app.changeplace.Text = {'Посмотреть/Изменить '; 'параметры электрода'};
            app.deleteEL = uibutton(app.ELinsert, 'push');
            app.deleteEL.ButtonPushedFcn = createCallbackFcn(app, @deleteELButtonPushed, true);
            app.deleteEL.Text = 'Удалить электрод';
            app.fieldcalculation = uipanel(app.UIFigure);
            app.fieldcalculation.Title = 'Расчёт поля/построение графиков';
            app.deletedata = uibutton(app.fieldcalculation, 'push');
            app.deletedata.ButtonPushedFcn = createCallbackFcn(app, @deletedataButtonPushed, true);
            app.deletedata.Text = {'Стереть данные/'; 'сменить вид поля'};
            app.solve = uibutton(app.fieldcalculation, 'push');
            app.solve.ButtonPushedFcn = createCallbackFcn(app, @solveButtonPushed, true);
            app.solve.Text = 'Рассчитать';
            app.saveresults = uibutton(app.fieldcalculation, 'push');
            app.saveresults.ButtonPushedFcn = createCallbackFcn(app, @saveresultsButtonPushed, true);
            app.saveresults.Text = {'Сохранить результаты'; 'расчёта'};
            app.Label_8 = uilabel(app.fieldcalculation);
            app.Label_8.Text = {'Диэлектрическая '; 'проницаемость среды'};
            app.changeepsilon = uieditfield(app.fieldcalculation, 'numeric');
            app.changeepsilon.Limits = [1 1000];
            app.changeepsilon.ValueChangedFcn = createCallbackFcn(app, @changeepsilonValueChanged, true);
            app.changeepsilon.Value = 1;
            app.numberoflines = uieditfield(app.fieldcalculation, 'numeric');
            app.numberoflines.Limits = [50 1000000];
            app.numberoflines.ValueChangedFcn = createCallbackFcn(app, @numberoflinesValueChanged, true);
            app.numberoflines.Value = 50;
            app.choosearea = uiswitch(app.fieldcalculation, 'rocker');
            app.choosearea.Items = {'Границы по умолчанию', 'Задать область построения'};
            app.choosearea.ValueChangedFcn = createCallbackFcn(app, @chooseareaValueChanged, true);
            app.choosearea.Value = 'Границы по умолчанию';
            app.Label_10 = uilabel(app.fieldcalculation);
            app.Label_10.Text = 'с числом расчётных точек по осям';
            app.insertX = uieditfield(app.fieldcalculation, 'numeric');
            app.insertX.ValueChangedFcn = createCallbackFcn(app, @insertXValueChanged, true);
            app.xLabel = uilabel(app.fieldcalculation);
            app.xLabel.Text = 'x, м';
            app.insertY = uieditfield(app.fieldcalculation, 'numeric');
            app.insertY.ValueChangedFcn = createCallbackFcn(app, @insertYValueChanged, true);
            app.yLabel = uilabel(app.fieldcalculation);
            app.yLabel.Text = 'y, м';
            app.Ephi = uiswitch(app.fieldcalculation, 'slider');
            app.Ephi.Items = {'E, В/м', 'φ, В'};
            app.Ephi.ValueChangedFcn = createCallbackFcn(app, @EphiValueChanged, true);
            app.Ephi.Value = 'E, В/м';
            app.showE = uieditfield(app.fieldcalculation, 'text');
            app.showE.Editable = 'off';
            app.Label_11 = uilabel(app.fieldcalculation);
            app.Label_11.Text = {'Вычислить поле '; 'в точке:'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Оптимизационный модуль';
            app.AddOpt = uibutton(app.Panel_2, 'push');
            app.AddOpt.ButtonPushedFcn = createCallbackFcn(app, @AddOptButtonPushed, true);
            app.AddOpt.Enable = 'off';
            app.AddOpt.Text = {'Ввести/изменить '; 'параметры оптимизации'};
            app.Opt = uibutton(app.Panel_2, 'push');
            app.Opt.ButtonPushedFcn = createCallbackFcn(app, @OptPushed, true);
            app.Opt.Enable = 'off';
            app.Opt.Text = 'Оптимизировать';
            app.optresults = uitable(app.Panel_2);
            app.optresults.ColumnName = {'№ параметра'; 'Оптимальное значение'};
            app.optresults.RowName = {};
            app.optresults.CellEditCallback = createCallbackFcn(app, @optresultsCellEdit, true);
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = 'Оптимизация';
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'Нет', 'Да'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Нет';
            app.methodchoose = uibuttongroup(app.Panel_2);
            app.methodchoose.SelectionChangedFcn = createCallbackFcn(app, @methodchooseSelectionChanged, true);
            app.methodchoose.Title = 'Метод оптимизации';
            app.Levenberg = uiradiobutton(app.methodchoose);
            app.Levenberg.Text = 'Метод Левенберга-Марквардта';
            app.Levenberg.Value = true;
            app.Grad = uiradiobutton(app.methodchoose);
            app.Grad.Text = 'Метод градиентного спуска';
            app.BFGSmethod = uiradiobutton(app.methodchoose);
            app.BFGSmethod.Text = 'Метод BFGS';
            app.saveoptresults = uibutton(app.Panel_2, 'push');
            app.saveoptresults.ButtonPushedFcn = createCallbackFcn(app, @saveoptresultsButtonPushed, true);
            app.saveoptresults.Enable = 'off';
            app.saveoptresults.Text = {'Сохранить результаты'; 'оптимизации'};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Информация о расчёте поля';
            app.EmaxLabel = uilabel(app.Panel);
            app.EmaxLabel.Text = {'Максимальная напряжённость'; 'на электродах Emax, В/м'};
            app.showEmax = uieditfield(app.Panel, 'text');
            app.showEmax.Editable = 'off';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = {'Относительная погрешность'; 'расчёта'};
            app.showEps = uieditfield(app.Panel, 'text');
            app.showEps.Editable = 'off';
            app.tEditFieldLabel = uilabel(app.Panel);
            app.tEditFieldLabel.Text = 'Время расчёта t, с';
            app.showTime = uieditfield(app.Panel, 'text');
            app.showTime.Editable = 'off';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = {'Число обусловленности '; 'матрицы'};
            app.showCond = uieditfield(app.Panel, 'text');
            app.showCond.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Статус';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MEZ

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end