classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Vielfachmesser                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VielfachmesserTab              matlab.ui.container.Tab
        DozentenansichtausButton       matlab.ui.control.Button
        DozentenansichteinButton       matlab.ui.control.Button
        VLabel_2                       matlab.ui.control.Label
        mALabel_2                      matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        mALabel                        matlab.ui.control.Label
        Gauge_2                        matlab.ui.control.SemicircularGauge
        Gauge_1                        matlab.ui.control.SemicircularGauge
        WiderstandDropDown             matlab.ui.control.DropDown
        WiderstandDropDownLabel        matlab.ui.control.Label
        MesspunktelschenButton         matlab.ui.control.Button
        MesspunktespeichernCheckBox    matlab.ui.control.CheckBox
        Label_Bemerkung                matlab.ui.control.Label
        SchaltungDropDown              matlab.ui.control.DropDown
        SchaltungDropDownLabel         matlab.ui.control.Label
        AP                             matlab.ui.control.Image
        KabelanzeigenCheckBox          matlab.ui.control.CheckBox
        Messbereichsschalter_2         matlab.ui.control.DiscreteKnob
        Messbereichsschalter_1         matlab.ui.control.DiscreteKnob
        Spannungsregelung              matlab.ui.control.Knob
        Bild_Kabel6                    matlab.ui.control.Image
        Bild_Kabel5                    matlab.ui.control.Image
        Bild_Kabel4                    matlab.ui.control.Image
        Bild_Kabel3                    matlab.ui.control.Image
        Bild_Kabel2                    matlab.ui.control.Image
        Bild_Kabel1                    matlab.ui.control.Image
        Bild_Widerstand                matlab.ui.control.Image
        Bild_Schaltung                 matlab.ui.control.Image
        MesspunktLabel                 matlab.ui.control.Label
        PowerSwitch                    matlab.ui.control.RockerSwitch
        EditField_Spannungswert        matlab.ui.control.NumericEditField
        Lamp                           matlab.ui.control.Lamp
        EditField_V                    matlab.ui.control.EditField
        Bild_DC_Quelle                 matlab.ui.control.Image
        Voltmeter                      matlab.ui.control.Image
        Amperemeter                    matlab.ui.control.Image
        Messgert2                      matlab.ui.control.Label
        SpannungsquelleLabel           matlab.ui.control.Label
        Messgert1                      matlab.ui.control.Label
        StromundSpannungsmessunganeinemWiderstandRLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        HinweiseTab                    matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        BedienungTab                   matlab.ui.container.Tab
        Image2                         matlab.ui.control.Image
        Widerstandsbestimmung          matlab.ui.container.Tab
        Abweichung5Label               matlab.ui.control.Label
        Abweichung5Label_2             matlab.ui.control.Label
        FehlerLabel                    matlab.ui.control.Label
        WiderstandrealkEditField       matlab.ui.control.NumericEditField
        WiderstandrealkEditFieldLabel  matlab.ui.control.Label
        InnenwiderstandVoltmeterMEditField  matlab.ui.control.NumericEditField
        InnenwiderstandVoltmeterMEditFieldLabel  matlab.ui.control.Label
        InnenwiderstandAmperemeterEditField  matlab.ui.control.NumericEditField
        InnenwiderstandAmperemeterEditFieldLabel  matlab.ui.control.Label
        StromAEditField                matlab.ui.control.NumericEditField
        StromAEditFieldLabel           matlab.ui.control.Label
        SpannungVEditField             matlab.ui.control.NumericEditField
        SpannungVEditFieldLabel        matlab.ui.control.Label
        WiderstandberechnetkEditField  matlab.ui.control.NumericEditField
        WiderstandberechnetkEditFieldLabel  matlab.ui.control.Label
        WiderstandberechnenundberprfenButton  matlab.ui.control.Button
        CopyrightTab                   matlab.ui.container.Tab
    end

    
    properties (Access = private)
        name
        name1
        Messwerte
        Passwort_=1234;
        p=1e-2; %Pausenzeit Analogzeiger
        p2=1e-5;    %Pausenzeit Digitalanzeige
        
        %Definition der 5 verschiedenen Widerstände
        
        R1=60e3; %Ohm
        R2=85e3; %Ohm
        R3=100e3; %Ohm
        R4=210e3; %Ohm
        R5=480e3; %Ohm


        %Messpunkt wird im Digramm nur angezeigt, wenn Strom und Spannugsanzeigeam Voltmeter größer als untere Grenze bzw. kleiner als obere Grenze ist 
        
        untere_Grenze=2;
        obere_Grenze=30;
        
        pointHandle;
        
        measPoints = zeros(2,1);
        
        
        auxapp % Description
    end
    
    methods (Access = private)
        
        function updatePlot(app)
                
            %Ermittlung des Widerstands entsprechend Dropdown                 
            if strcmp(app.WiderstandDropDown.Value,'1')
                R=app.R1;
            elseif strcmp(app.WiderstandDropDown.Value,'2')
                R=app.R2;
            elseif strcmp(app.WiderstandDropDown.Value,'3')
                R=app.R3;
            elseif strcmp(app.WiderstandDropDown.Value,'4')
                R=app.R4;
            elseif strcmp(app.WiderstandDropDown.Value,'5')
                R=app.R5;
            end


            %Ermittlung Innenwiderstand, Messbereich und Strom oder Spannugsbereich von Messgerät 1
            Ri1=40e6; 
            Messbereich1='0';
            Schalterstellung1=0;
            
            if strcmp(app.Messbereichsschalter_1.Value,'0')
                Ri1=40e6;
                Messbereich1='0';                
            elseif strcmp(app.Messbereichsschalter_1.Value,'60 mA')
                Ri1=3;
                Messbereich1='Strom';
                Schalterstellung1=60;
            elseif strcmp(app.Messbereichsschalter_1.Value,'15 mA')
                Ri1=12;
                Messbereich1='Strom';
                Schalterstellung1=15;
            elseif strcmp(app.Messbereichsschalter_1.Value,'3 mA')
                Ri1=60;
                Messbereich1='Strom';
                Schalterstellung1=3;
            elseif strcmp(app.Messbereichsschalter_1.Value,'0.6 mA')
                Ri1=283.3;
                Messbereich1='Strom';
                Schalterstellung1=0.6;
            elseif strcmp(app.Messbereichsschalter_1.Value,'0.12 mA')
                Ri1=1000;
                Messbereich1='Strom';
                Schalterstellung1=0.12;
            elseif strcmp(app.Messbereichsschalter_1.Value,'300 V')
                Ri1=6e6;
                Messbereich1='Spannung';
                Schalterstellung1=300;
            elseif strcmp(app.Messbereichsschalter_1.Value,'150 V')
                Ri1=3e6;
                Messbereich1='Spannung';
                Schalterstellung1=150;
            elseif strcmp(app.Messbereichsschalter_1.Value,'60 V')
                Ri1=1.2e6;
                Messbereich1='Spannung';
                Schalterstellung1=60;
            elseif strcmp(app.Messbereichsschalter_1.Value,'30 V')
                Ri1=0.6e6;
                Messbereich1='Spannung';
                Schalterstellung1=30;
            elseif strcmp(app.Messbereichsschalter_1.Value,'15 V')
                Ri1=0.3e6;
                Messbereich1='Spannung';
                Schalterstellung1=15;
            end


            %Ermittlung Innenwiderstand, Messbereich und Strom oder Spannugsbereich von Messgerät 2
            Ri2=40e6; %Innenwiderstand Messgerät 2
            Messbereich2='0';
            Schalterstellung2=0;
            
            if strcmp(app.Messbereichsschalter_2.Value,'0')                
                Ri2=40e6;
                Messbereich2='0';                
            elseif strcmp(app.Messbereichsschalter_2.Value,'60 mA')
                Ri2=3;
                Messbereich2='Strom';
                Schalterstellung2=60;
            elseif strcmp(app.Messbereichsschalter_2.Value,'15 mA')
                Ri2=12;
                Messbereich2='Strom';
                Schalterstellung2=15;
            elseif strcmp(app.Messbereichsschalter_2.Value,'3 mA')
                Ri2=60;
                Messbereich2='Strom';
                Schalterstellung2=3;
            elseif strcmp(app.Messbereichsschalter_2.Value,'0.6 mA')
                Ri2=283.3;
                Messbereich2='Strom';
                Schalterstellung2=0.6;
            elseif strcmp(app.Messbereichsschalter_2.Value,'0.12 mA')
                Ri2=1000;
                Messbereich2='Strom';
                Schalterstellung2=0.12;
            elseif strcmp(app.Messbereichsschalter_2.Value,'300 V')
                Ri2=6e6;
                Messbereich2='Spannung';
                Schalterstellung2=300;
            elseif strcmp(app.Messbereichsschalter_2.Value,'150 V')
                Ri2=3e6;
                Messbereich2='Spannung';
                Schalterstellung2=150;
            elseif strcmp(app.Messbereichsschalter_2.Value,'60 V')
                Ri2=1.2e6;
                Messbereich2='Spannung';
                Schalterstellung2=60;
            elseif strcmp(app.Messbereichsschalter_2.Value,'30 V')
                Ri2=0.6e6;
                Messbereich2='Spannung';
                Schalterstellung2=30;
            elseif strcmp(app.Messbereichsschalter_2.Value,'15 V')
                Ri2=0.3e6;
                Messbereich2='Spannung';
                Schalterstellung2=15;
            end


            %Berechnung des Strom und Spannungswerts
            if strcmp(app.SchaltungDropDown.Value,'1')
                I1=app.Spannungsregelung.Value/(Ri1+R)*1000; %Stromwert (mA)
                I2=app.Spannungsregelung.Value/Ri2*1000; %Stromwert (mA)
                
                U1=Ri1*I1/1000; %Spannung (V)
                U2=Ri2*I2/1000; %Spannung (V)
                                                    
                
            elseif strcmp(app.SchaltungDropDown.Value,'2')
                I1=app.Spannungsregelung.Value/(Ri1+(Ri2*R/(Ri2+R)))*1000; %Stromwert (mA)
                I2=app.Spannungsregelung.Value*R/(Ri1*Ri2+Ri1*R+Ri2*R)*1000; %Stromwert (mA)
                
                U1=Ri1*I1/1000; %Spannung (V)
                U2=Ri2*I2/1000; %Spannung (V)
                
            end
            
            %Umrechnung des Strom bzw. Spannugswerts für den jeweiligen Messbereich für Messgerät 1
            if strcmp(Messbereich1, 'Strom')
                gauge1=I1*30/Schalterstellung1;
            elseif strcmp(Messbereich1, 'Spannung')
                gauge1=U1*30/Schalterstellung1;
            elseif strcmp(Messbereich1, '0')
                gauge1=0;
            end
            
            %Umrechnung des Strom bzw. Spannugswerts für den jeweiligen Messbereich für Messgerät 2
            if strcmp(Messbereich2, 'Strom')
                gauge2=I2*30/Schalterstellung2;
            elseif strcmp(Messbereich2, 'Spannung')
                gauge2=U2*30/Schalterstellung2;
            elseif strcmp(Messbereich2, '0')
                 gauge2=0;
            end


            Differenz_1=gauge1-app.Gauge_1.Value;
            Differenz_2=gauge2-app.Gauge_2.Value;


            if strcmp(app.PowerSwitch.Value, 'On')
                
                app.Lamp.Color = 'g';
                
                set(app.Messbereichsschalter_1,'Enable','off');
                set(app.Messbereichsschalter_2,'Enable','off');
                set(app.Spannungsregelung,'Enable','off');
                set(app.PowerSwitch,'Enable','off');
                set(app.SchaltungDropDown,'Enable','off');
                set(app.Label_Bemerkung,'Visible','on');
                set(app.KabelanzeigenCheckBox,'Enable','off');
                set(app.MesspunktespeichernCheckBox,'Enable','off');
                set(app.MesspunktelschenButton,'Enable','off');
%                 set(app.MesswertelschenButton,'Enable','off');
%                 set(app.MesswertesortierenButton,'Enable','off');
%                 set(app.MesswerteexportierenButton,'Enable','off');
                set(app.WiderstandberechnenundberprfenButton,'Enable','off');
                set(app.WiderstandDropDown,'Enable','off');
                set(app.DozentenansichteinButton,'Enable','off');
                set(app.DozentenansichtausButton,'Enable','off');


                %Digitalanzeige Spannungsquelle
                
                app.EditField_Spannungswert.FontColor='g';
                app.EditField_V.Value='V';
                
                Anzeigewert_alt=app.EditField_Spannungswert.Value;
                Anzeigewert_neu=app.Spannungsregelung.Value;
                
                Anzeigewert_Differenz=Anzeigewert_neu-Anzeigewert_alt;
                
                if Anzeigewert_Differenz>0
               
                
                    for i=Anzeigewert_alt:0.5:Anzeigewert_neu
                        app.EditField_Spannungswert.Value=i;
                        pause(app.p2);
                    end
                    
                elseif Anzeigewert_Differenz<0
                    
                    for i=Anzeigewert_alt:-0.5:Anzeigewert_neu
                        app.EditField_Spannungswert.Value=i;
                        pause(app.p2);
                    end
                    
                end
                
                app.EditField_Spannungswert.Value=app.Spannungsregelung.Value;


                % plot UI line
                if strcmp(num2str(app.Passwort_),app.name1)
            x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'b');
            else
                
                x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'Color','none');
                end
                
                set(app.MesspunktLabel,'Visible','off');
                set(app.AP,'Visible','off');
               
                
                % Update gauges

             
%                      if Differenz_1>0
                      if gauge1>30 && app.Gauge_1.Value<=30
                          diff_1=linspace(app.Gauge_1.Value, 35, 100);
                      elseif gauge1<=30 && app.Gauge_1.Value>30
                          diff_1=linspace(35, gauge1, 100);
                      else
                          diff_1=linspace(app.Gauge_1.Value, gauge1, 100);
                      end
                      
                      if gauge2>30 && app.Gauge_2.Value<=30
                          diff_2=linspace(app.Gauge_2.Value, 35, 100);
                      elseif gauge2<=30 && app.Gauge_2.Value>30
                          diff_2=linspace(35, gauge2, 100);
                      else
                          diff_2=linspace(app.Gauge_2.Value, gauge2, 100);
                      end                      
                      
                      for i=1:length(diff_1)
                              app.Gauge_1.Value=diff_1(i);
                              app.Gauge_2.Value=diff_2(i);
                              pause(app.p);
                      end
                        
                    % while app.Gauge_1.Value<gauge1 
                    %         app.Gauge_1.Value=app.Gauge_1.Value+Differenz_1/30;
                    %         app.Gauge_2.Value=app.Gauge_2.Value+Differenz_2/30;
                    %         pause(app.p);
                    % end
                    %           
                    %   elseif Differenz_1<0
                    % 
                    % 
                    % while app.Gauge_1.Value>gauge1 
                    %         app.Gauge_1.Value=app.Gauge_1.Value+Differenz_1/30;
                    %         app.Gauge_2.Value=app.Gauge_2.Value+Differenz_2/30;
                    %         pause(app.p);
                    % end
                    % 
                    %   elseif Differenz_2>0
                    % 
                    % 
                    % while app.Gauge_2.Value<gauge2 
                    %         app.Gauge_1.Value=app.Gauge_1.Value+Differenz_1/30;
                    %         app.Gauge_2.Value=app.Gauge_2.Value+Differenz_2/30;
                    %         pause(app.p);
                    % end
                    % 
                    %   elseif Differenz_2<0 
                    % 
                    % 
                    %   while app.Gauge_2.Value>gauge2 
                    %        app.Gauge_1.Value=app.Gauge_1.Value+Differenz_1/30;
                    %        app.Gauge_2.Value=app.Gauge_2.Value+Differenz_2/30;
                    %        pause(app.p);
                    %   end
                    %                  
                    %   end
                    % 
                    % 
                    % 
                    app.Gauge_1.Value=gauge1;
                    app.Gauge_2.Value=gauge2;
                
                gauge1_tooltip=sprintf('%.1f',gauge1);
                gauge2_tooltip=sprintf('%.1f',gauge2);
                
                if gauge1==0
                    app.Gauge_1.Tooltip='0';
                elseif 0<gauge1 && gauge1<2
                    app.Gauge_1.Tooltip='<2';
                elseif gauge1>30
                    app.Gauge_1.Tooltip='>30';
                else
                    app.Gauge_1.Tooltip=gauge1_tooltip;
                end
                
                
                if gauge2==0
                    app.Gauge_2.Tooltip='0';
                elseif 0<gauge2 && gauge2<2
                    app.Gauge_2.Tooltip='<2';
                elseif gauge2>30
                    app.Gauge_2.Tooltip='>30';
                else
                    app.Gauge_2.Tooltip=gauge2_tooltip;
                end
                
                
                % Update UI plot
                if not(isempty(app.pointHandle))
                    set(app.pointHandle,'SizeData',200,'MarkerEdgeColor','#8c9192','MarkerFaceColor','#8c9192','marker','x','LineWidth',1.5);
                    
                end
                
                if gauge1>=app.untere_Grenze && gauge1<=app.obere_Grenze && gauge2>=app.untere_Grenze && gauge2<=app.obere_Grenze
                    
                    hold(app.UIAxes2,'on');
                    
                    set(app.MesspunktLabel,'Visible','on');
                    set(app.AP,'Visible','on');
%                     set(app.KeineMesswertevorhandenLabel,'Visible','off');
                    
                    if strcmp(Messbereich1,'Strom') && strcmp(Messbereich2,'Spannung')                


                        app.pointHandle = scatter(app.UIAxes2,U2,I1*1000,40,'MarkerEdgeColor','#A2142F',...
                        'MarkerFaceColor','#A2142F',...
                        'LineWidth',1.5);


                        if app.MesspunktespeichernCheckBox.Value == 1
                            if sum(app.measPoints) == 0
                                idx = 1;
                            else
                                idx = size(app.measPoints, 2) + 1;
                            end
                        
                            app.measPoints(1,idx) = U2;
                            app.measPoints(2,idx) = I1*1000;


                               app.Messwerte=app.measPoints';
%                             app.UITable.Data=Messwerte;
                      
                        end
                        
                    else
                        set(app.MesspunktLabel,'Visible','off');
                        set(app.AP,'Visible','off');
                    end               


                else
                    set(app.MesspunktLabel,'Visible','off');
                    set(app.AP,'Visible','off');
                
                end


                if app.MesspunktespeichernCheckBox.Value == 0
                    hold(app.UIAxes2,'off');
                    
                    app.pointHandle = [];
                end
                
                % gui on
                
                
                set(app.Messbereichsschalter_1,'Enable','on');
                set(app.Messbereichsschalter_2,'Enable','on');
                set(app.Spannungsregelung,'Enable','on');
                set(app.PowerSwitch,'Enable','on');
                set(app.KabelanzeigenCheckBox,'Enable','on');
                set(app.MesspunktespeichernCheckBox,'Enable','on');
                set(app.MesspunktelschenButton,'Enable','on');
%                 set(app.MesswertelschenButton,'Enable','on');
%                 set(app.MesswertesortierenButton,'Enable','on');
%                 set(app.MesswerteexportierenButton,'Enable','on');
                set(app.WiderstandberechnenundberprfenButton,'Enable','on');
                set(app.DozentenansichteinButton,'Enable','on');
                set(app.DozentenansichtausButton,'Enable','on');
                
                
            else
                app.Lamp.Color = 'r';
                
                set(app.Messbereichsschalter_1,'Enable','off');
                set(app.Messbereichsschalter_2,'Enable','off');
                set(app.Spannungsregelung,'Enable','off');
                set(app.PowerSwitch,'Enable','off');
                set(app.KabelanzeigenCheckBox,'Enable','off');
                set(app.MesspunktespeichernCheckBox,'Enable','off');
                set(app.MesspunktelschenButton,'Enable','off');
%                 set(app.MesswertelschenButton,'Enable','off');
%                 set(app.MesswertesortierenButton,'Enable','off');
%                 set(app.MesswerteexportierenButton,'Enable','off');
                set(app.WiderstandberechnenundberprfenButton,'Enable','off');
                set(app.DozentenansichteinButton,'Enable','off');
                set(app.DozentenansichtausButton,'Enable','off');
                
                app.Gauge_1.Tooltip='0';
                app.Gauge_2.Tooltip='0';
                
                app.EditField_Spannungswert.Value=0;
                app.EditField_Spannungswert.FontColor='k';
                
                app.EditField_V.Value='';
                
                % plot UI line
                if app.MesspunktespeichernCheckBox.Value == 0
                    hold(app.UIAxes2,'off');
                    
                    app.pointHandle = [];
                end
                
                if strcmp(num2str(app.Passwort_),app.name1)
            x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'b');
            else
                
                 x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'Color','none');
                end
                
                set(app.MesspunktLabel,'Visible','off');
                set(app.AP,'Visible','off');


                % set gauges to zero
               
                                 
                  if app.Gauge_1.Value<=30
                      diff_1=linspace(app.Gauge_1.Value, 0, 100);
                  else
                      diff_1=linspace(35, 0, 100);
                  end
                  
                  if app.Gauge_2.Value<=30
                      diff_2=linspace(app.Gauge_2.Value, 0, 100);
                  else
                      diff_2=linspace(35, 0, 100);
                  end  
                  for i=1:length(diff_1)
                        app.Gauge_1.Value=diff_1(i);
                        app.Gauge_2.Value=diff_2(i);
                        pause(app.p);
                  end
%                 while app.Gauge_1.Value>0 ||app.Gauge_2.Value>0
%                     app.Gauge_1.Value=app.Gauge_1.Value-gauge1/30;
%                     app.Gauge_2.Value=app.Gauge_2.Value-gauge2/30;
%                     pause(app.p);
%                 end
%                 
%                 app.Gauge_1.Value=0;
%                 app.Gauge_2.Value=0;
                
                % gui on
                set(app.Messbereichsschalter_1,'Enable','on');
                set(app.Messbereichsschalter_2,'Enable','on');
                set(app.Spannungsregelung,'Enable','on');
                set(app.PowerSwitch,'Enable','on');
                set(app.Label_Bemerkung,'Visible','off');
                set(app.KabelanzeigenCheckBox,'Enable','on');
                set(app.SchaltungDropDown,'Enable','on');
                set(app.MesspunktespeichernCheckBox,'Enable','on');
                set(app.MesspunktelschenButton,'Enable','on');
%                 set(app.MesswertelschenButton,'Enable','on');
%                 set(app.MesswertesortierenButton,'Enable','on');
%                 set(app.MesswerteexportierenButton,'Enable','on');
                set(app.WiderstandberechnenundberprfenButton,'Enable','on');
                set(app.WiderstandDropDown,'Enable','on');
                set(app.DozentenansichteinButton,'Enable','on');
                set(app.DozentenansichtausButton,'Enable','on');
                
            end
            
        end
    end
    
    methods (Access = public)
        
        function importdata(app,name)
            app.name1=name;


        end
    end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
%             x=0:0.1:30;
%             f=x*1e6/app.R1;
%             plot(app.UIAxes2,x,f,'b');


        end

        % Callback function
        function ButtonPushed(app, event)
         
           
        end

        % Callback function
        function Messbereichsschalter_StromValueChanged(app, event)
           

        end

        % Callback function
        function VerbindenButtonPushed(app, event)


        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)


            app.updatePlot();
            
            
            return;
            
            value = app.PowerSwitch.Value;


        end

        % Callback function
        function Messbereichsschalter_SpannungValueChanged(app, event)
           
        end

        % Value changed function: Spannungsregelung
        function SpannungsregelungValueChanged(app, event)
            if strcmp(app.PowerSwitch.Value, 'On')


            app.updatePlot();
            end
            
            return;


        end

        % Value changed function: Messbereichsschalter_1
        function Messbereichsschalter_1ValueChanged2(app, event)


            if strcmp(app.PowerSwitch.Value, 'On')
            app.updatePlot();
            end
            
            return;


            value = app.Messbereichsschalter_1.Value;


        end

        % Value changed function: Messbereichsschalter_2
        function Messbereichsschalter_2ValueChanged2(app, event)


            if strcmp(app.PowerSwitch.Value, 'On')
            app.updatePlot();
            end
            
            return;
            
            value = app.Messbereichsschalter_2.Value;


        end

        % Value changed function: SchaltungDropDown
        function SchaltungDropDownValueChanged(app, event)
            value = app.SchaltungDropDown.Value;
            
                app.WiderstandberechnetkEditField.Value=0;   
                app.WiderstandrealkEditField.Value=0;
                set(app.Abweichung5Label,'Visible','off');
                set(app.Abweichung5Label_2,'Visible','off');
                set(app.FehlerLabel,'Visible','off');
                set(app.WiderstandberechnetkEditField,'Enable','off');
                set(app.WiderstandrealkEditField,'Enable','off');
            
            
            if strcmp(app.WiderstandDropDown.Value,'1')
                R=app.R1;
            elseif strcmp(app.WiderstandDropDown.Value,'2')
                R=app.R2;
            elseif strcmp(app.WiderstandDropDown.Value,'3')
                R=app.R3;
            elseif strcmp(app.WiderstandDropDown.Value,'4')
                R=app.R4;
            elseif strcmp(app.WiderstandDropDown.Value,'5')
                R=app.R5;
            end
            
            
            if strcmp(value,'1')
                
                
                app.Bild_Schaltung = uiimage(app.VielfachmesserTab);
                app.Bild_Schaltung.Position = [32 4 445 257];
                app.Bild_Schaltung.ImageSource = 'stromrichtig.png';
                
                set(app.InnenwiderstandAmperemeterEditField,'Enable','on');
                set(app.InnenwiderstandVoltmeterMEditField,'Enable','off');
                
                if app.KabelanzeigenCheckBox.Value==1
                    
                    set(app.Bild_Kabel1,'Visible','On');
                    set(app.Bild_Kabel2,'Visible','On');
                    set(app.Bild_Kabel3,'Visible','On');
                    set(app.Bild_Kabel4,'Visible','On');
                    set(app.Bild_Kabel5,'Visible','On');
                    set(app.Bild_Kabel6,'Visible','Off');
                    
                end


            elseif strcmp(value,'2')
                
                app.Bild_Schaltung = uiimage(app.VielfachmesserTab);
                app.Bild_Schaltung.Position = [32 4 445 257];
                app.Bild_Schaltung.ImageSource = 'spannungsrichtig.png';
                
                set(app.InnenwiderstandAmperemeterEditField,'Enable','off');
                set(app.InnenwiderstandVoltmeterMEditField,'Enable','on');


                if app.KabelanzeigenCheckBox.Value==1
                    
                    set(app.Bild_Kabel1,'Visible','On');
                    set(app.Bild_Kabel2,'Visible','Off');
                    set(app.Bild_Kabel3,'Visible','On');
                    set(app.Bild_Kabel4,'Visible','On');
                    set(app.Bild_Kabel5,'Visible','On');
                    set(app.Bild_Kabel6,'Visible','On');
                    
                end
                
            end
            
            hold(app.UIAxes2,'off');
            
%              app.name1
%             num2str(app.Passwort_)
            if strcmp(num2str(app.Passwort_),app.name1)
            x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'b');
            else
                
                 x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'Color','none');
            end
            
            hold(app.UIAxes2,'off');
            
            
            app.pointHandle = [];
            
            %app.UITable.Data=[];
            
            app.measPoints = zeros(2,1);
            
        end

        % Value changed function: KabelanzeigenCheckBox
        function KabelanzeigenCheckBoxValueChanged(app, event)
            value = app.KabelanzeigenCheckBox.Value;
            
            set(app.KabelanzeigenCheckBox,'Enable','off');
                    set(app.PowerSwitch,'Enable','off');
                    set(app.Spannungsregelung,'Enable','off');
                    set(app.Messbereichsschalter_1,'Enable','off');
                    set(app.Messbereichsschalter_2,'Enable','off');
                    set(app.SchaltungDropDown,'Enable','off');
                    set(app.WiderstandDropDown,'Enable','off');
                    set(app.MesspunktespeichernCheckBox,'Enable','off');
                    set(app.MesspunktelschenButton,'Enable','off');
                    set(app.WiderstandberechnenundberprfenButton,'Enable','off');
                    set(app.DozentenansichteinButton,'Enable','off');
                    set(app.DozentenansichtausButton,'Enable','off');
            
            if value==1
                
                
                if strcmp(app.SchaltungDropDown.Value,'1')
                    
                    
                    set(app.Bild_Kabel1,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel2,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel3,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel4,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel5,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel6,'Visible','Off');
                    
                elseif strcmp(app.SchaltungDropDown.Value,'2')


                    set(app.Bild_Kabel1,'Visible','On');
                    pause(1);
                    %set(app.Bild_Kabel2,'Visible','Off');
                    %pause(1);
                    set(app.Bild_Kabel3,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel6,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel4,'Visible','On');
                    pause(1);
                    set(app.Bild_Kabel5,'Visible','On');
                    
                    
                end
                
            elseif  value==0
                
                set(app.Bild_Kabel1,'Visible','Off');
                set(app.Bild_Kabel2,'Visible','Off');
                set(app.Bild_Kabel3,'Visible','Off');
                set(app.Bild_Kabel4,'Visible','Off');
                set(app.Bild_Kabel5,'Visible','Off');
                set(app.Bild_Kabel6,'Visible','Off');
                
                
            end
            
                    set(app.KabelanzeigenCheckBox,'Enable','on');
                    set(app.PowerSwitch,'Enable','on');
                    set(app.Spannungsregelung,'Enable','on');
                    set(app.Messbereichsschalter_1,'Enable','on');
                    set(app.Messbereichsschalter_2,'Enable','on');
                    
                    
                    set(app.MesspunktespeichernCheckBox,'Enable','on');
                    set(app.MesspunktelschenButton,'Enable','on');
                    set(app.WiderstandberechnenundberprfenButton,'Enable','on');
                    set(app.DozentenansichteinButton,'Enable','on');
                set(app.DozentenansichtausButton,'Enable','on');


           if strcmp(app.PowerSwitch.Value, 'Off')
                    set(app.SchaltungDropDown,'Enable','on');
                    set(app.WiderstandDropDown,'Enable','on');                        
           end
            
            
        end

        % Callback function
        function ResizeButtonPushed(app, event)
           
        end

        % Size changed function: Vielfachmesser
        function VielfachmesserSizeChanged(app, event)
            position = app.Vielfachmesser.Position;


        end

        % Value changed function: MesspunktespeichernCheckBox
        function MesspunktespeichernCheckBoxValueChanged(app, event)
            if strcmp(app.PowerSwitch.Value, 'On')
            app.updatePlot();
            end
            
            return;
            value = app.MesspunktespeichernCheckBox.Value;


        end

        % Button pushed function: MesspunktelschenButton
        function MesspunktelschenButtonPushed(app, event)
           
            if strcmp(app.WiderstandDropDown.Value,'1')
                R=app.R1;
            elseif strcmp(app.WiderstandDropDown.Value,'2')
                R=app.R2;
            elseif strcmp(app.WiderstandDropDown.Value,'3')
                R=app.R3;
            elseif strcmp(app.WiderstandDropDown.Value,'4')
                R=app.R4;
            elseif strcmp(app.WiderstandDropDown.Value,'5')
                R=app.R5;
            end
            
            hold(app.UIAxes2,'off');
            
            if strcmp(num2str(app.Passwort_),app.name1)
            x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'b');
            else
                
                 x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'Color','none');
            end
            
            hold(app.UIAxes2,'off');
            
            set(app.MesspunktLabel,'Visible','off');
            set(app.AP,'Visible','off');
            
            
            app.pointHandle = [];
            
            %app.UITable.Data=[];
            
            app.measPoints = zeros(2,1);
            
            app.Messwerte=[];
        end

        % Callback function
        function MesswertesortierenButtonPushed(app, event)
%             u=app.UITable.Data;
%             app.UITable.Data=[];
%             Messwerte=sort(u);
%             app.UITable.Data=Messwerte;
            
        end

        % Callback function
        function MesswerteexportierenButtonPushed(app, event)
%              set(app.KeinDateinamegewhltLabel,'Visible','off');
%             
%              export=app.UITable.Data;
%                           
%              if isempty(export)
%                  set(app.KeineMesswertevorhandenLabel,'Visible','on');
%                  return
%              end
%              
%              export=round(export*100)/100;
%              
%              [file, path] = uiputfile('Daten.xlsx','Speichern');
%              
%              if file == 0
%                 set(app.KeinDateinamegewhltLabel,'Visible','on');
%                 return
%             
%              end
%              
%              xlswrite([path file],{'Spannung (V)'},'Tabelle1','A1'); 
%              xlswrite([path file],{'Strom (uA)'},'Tabelle1','B1'); 
%              xlswrite([path file],export,'Tabelle1','A2'); 
              
        end

        % Button pushed function: WiderstandberechnenundberprfenButton
        function WiderstandberechnenundberprfenButtonPushed(app, event)
            set(app.FehlerLabel,'Visible','off');
            set(app.WiderstandrealkEditFieldLabel,'Visible','off');
            set(app.WiderstandrealkEditField,'Visible','off');   
            
            if strcmp(app.WiderstandDropDown.Value,'1')
                R=app.R1;
            elseif strcmp(app.WiderstandDropDown.Value,'2')
                R=app.R2;
            elseif strcmp(app.WiderstandDropDown.Value,'3')
                R=app.R3;
            elseif strcmp(app.WiderstandDropDown.Value,'4')
                R=app.R4;
            elseif strcmp(app.WiderstandDropDown.Value,'5')
                R=app.R5;
            end


            U=app.SpannungVEditField.Value;
            I=app.StromAEditField.Value*1e-6;
            Ri1=app.InnenwiderstandAmperemeterEditField.Value;
            Ri2=app.InnenwiderstandVoltmeterMEditField.Value*1e6;
            
             
            if strcmp(app.SchaltungDropDown.Value,'1')
                                    
                R_berechnet=1e-3*(U-Ri1*I)/I;
            
            elseif strcmp(app.SchaltungDropDown.Value,'2')
                
                R_berechnet=1e-3*U/(I-(U/Ri2));
            end


           set(app.WiderstandberechnetkEditField,'Enable','on');
           set(app.WiderstandrealkEditField,'Enable','on');


            if isnan(R_berechnet) || isinf(R_berechnet)
                set(app.FehlerLabel,'Visible','on');
                app.WiderstandberechnetkEditField.Value=0;   
                app.WiderstandrealkEditField.Value=0;
                set(app.Abweichung5Label,'Visible','off');
                set(app.Abweichung5Label_2,'Visible','off');
                set(app.WiderstandberechnetkEditField,'Enable','off');
                set(app.WiderstandrealkEditField,'Enable','off');
                
                return
            end


            app.WiderstandberechnetkEditField.Value=R_berechnet;   
            app.WiderstandrealkEditField.Value=R/1000;


            if abs(R_berechnet-(R/1000))/(R/1000)*100<=5
                set(app.Abweichung5Label,'Visible','off');
                set(app.Abweichung5Label_2,'Visible','on');
                set(app.WiderstandrealkEditFieldLabel,'Visible','on');
                set(app.WiderstandrealkEditField,'Visible','on');
                
              
            else
                set(app.Abweichung5Label_2,'Visible','off');
                set(app.Abweichung5Label,'Visible','on');
                set(app.WiderstandrealkEditFieldLabel,'Visible','off');
                set(app.WiderstandrealkEditField,'Visible','off');
            end
             
             
        end

        % Value changed function: WiderstandDropDown
        function WiderstandDropDownValueChanged(app, event)
            value = app.WiderstandDropDown.Value;
            
                app.WiderstandberechnetkEditField.Value=0;   
                app.WiderstandrealkEditField.Value=0;
                set(app.Abweichung5Label,'Visible','off');
                set(app.Abweichung5Label_2,'Visible','off');
                set(app.FehlerLabel,'Visible','off');
                set(app.WiderstandberechnetkEditField,'Enable','off');
                set(app.WiderstandrealkEditField,'Enable','off');
                set(app.WiderstandrealkEditFieldLabel,'Visible','off');
                set(app.WiderstandrealkEditField,'Visible','off');   


            if strcmp(app.WiderstandDropDown.Value,'1')
                R=app.R1;
                
                app.Bild_Widerstand = uiimage(app.VielfachmesserTab);
                app.Bild_Widerstand.Position = [772 386 101 301];
                app.Bild_Widerstand.ImageSource = 'Widerstand_60k.png';
               
            elseif strcmp(app.WiderstandDropDown.Value,'2')
                R=app.R2;
                
                app.Bild_Widerstand = uiimage(app.VielfachmesserTab);
                app.Bild_Widerstand.Position = [772 386 101 301];
                app.Bild_Widerstand.ImageSource = 'Widerstand_85k.png';
            elseif strcmp(app.WiderstandDropDown.Value,'3')
                R=app.R3;
                
                app.Bild_Widerstand = uiimage(app.VielfachmesserTab);
                app.Bild_Widerstand.Position = [772 386 101 301];
                app.Bild_Widerstand.ImageSource = 'Widerstand_100k.png';
            elseif strcmp(app.WiderstandDropDown.Value,'4')
                R=app.R4;
                
                app.Bild_Widerstand = uiimage(app.VielfachmesserTab);
                app.Bild_Widerstand.Position = [772 386 101 301];
                app.Bild_Widerstand.ImageSource = 'Widerstand_210k.png';
            elseif strcmp(app.WiderstandDropDown.Value,'5')
                R=app.R5;
                
                app.Bild_Widerstand = uiimage(app.VielfachmesserTab);
                app.Bild_Widerstand.Position = [772 386 101 301];
                app.Bild_Widerstand.ImageSource = 'Widerstand_480k.png';
            end
            
            hold(app.UIAxes2,'off');
            
            if strcmp(num2str(app.Passwort_),app.name1)
            x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'b');
            else
                
                 x=0:0.1:30;
            f=x*1e6/R;
            plot(app.UIAxes2,x,f,'Color','none');
            end
            
            hold(app.UIAxes2,'off');
            
            set(app.MesspunktLabel,'Visible','off');
            set(app.AP,'Visible','off');
            
            
            app.pointHandle = [];
            
            %app.UITable.Data=[];
            
            app.measPoints = zeros(2,1);
            
        end

        % Callback function
        function DozentenansichtCheckBoxValueChanged(app, event)
        
             
        end

        % Button pushed function: DozentenansichteinButton
        function DozentenansichteinButtonPushed(app, event)
            app.auxapp=auxapp(app);
           
            
        end

        % Button pushed function: DozentenansichtausButton
        function DozentenansichtausButtonPushed(app, event)
         
            app.name1=0;
%         hold(app.UIAxes2,'on');
         cla(app.UIAxes2);
         
            if not(isempty(app.Messwerte))
            
            j1=app.Messwerte(1:end,1);
            j2=app.Messwerte(1:end,2);


         app.pointHandle = scatter(app.UIAxes2,j1,j2,40,'SizeData',200,'MarkerEdgeColor','#8c9192','MarkerFaceColor','#8c9192','marker','x','LineWidth',1.5);
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Vielfachmesser = uifigure('Visible', 'off');
            app.Vielfachmesser.SizeChangedFcn = createCallbackFcn(app, @VielfachmesserSizeChanged, true);
            app.TabGroup = uitabgroup(app.Vielfachmesser);
            app.VielfachmesserTab = uitab(app.TabGroup);
            app.VielfachmesserTab.Title = 'Vielfachmesser';
            app.UIAxes2 = uiaxes(app.VielfachmesserTab);
            xlabel(app.UIAxes2, 'Spannung (V)')
            ylabel(app.UIAxes2, 'Strom (μA)')
            app.UIAxes2.XLim = [0 30];
            app.UIAxes2.YLim = [0 500];
            app.UIAxes2.XTick = [0 5 10 15 20 25 30];
            app.UIAxes2.YTick = [0 50 100 150 200 250 300 350 400 450 500];
            app.UIAxes2.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'; '400'; '450'; '500'};
            app.StromundSpannungsmessunganeinemWiderstandRLabel = uilabel(app.VielfachmesserTab);
            app.StromundSpannungsmessunganeinemWiderstandRLabel.Text = 'Strom- und Spannungsmessung an einem Widerstand R';
            app.Messgert1 = uilabel(app.VielfachmesserTab);
            app.Messgert1.Text = 'Messgerät 1';
            app.SpannungsquelleLabel = uilabel(app.VielfachmesserTab);
            app.SpannungsquelleLabel.Text = 'Spannungsquelle';
            app.Messgert2 = uilabel(app.VielfachmesserTab);
            app.Messgert2.Text = 'Messgerät 2';
            app.Amperemeter = uiimage(app.VielfachmesserTab);
            app.Amperemeter.ImageSource = 'Vielfachmesser - Kopie2.png';
            app.Voltmeter = uiimage(app.VielfachmesserTab);
            app.Voltmeter.ImageSource = 'Vielfachmesser - Kopie2.png';
            app.Bild_DC_Quelle = uiimage(app.VielfachmesserTab);
            app.Bild_DC_Quelle.ImageSource = 'DC Quelle.png';
            app.EditField_V = uieditfield(app.VielfachmesserTab, 'text');
            app.EditField_V.Editable = 'off';
            app.Lamp = uilamp(app.VielfachmesserTab);
            app.EditField_Spannungswert = uieditfield(app.VielfachmesserTab, 'numeric');
            app.EditField_Spannungswert.ValueDisplayFormat = '%.1f';
            app.EditField_Spannungswert.Editable = 'off';
            app.PowerSwitch = uiswitch(app.VielfachmesserTab, 'rocker');
            app.PowerSwitch.Items = {'0', '|'};
            app.PowerSwitch.ItemsData = {'Off', 'On'};
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.MesspunktLabel = uilabel(app.VielfachmesserTab);
            app.MesspunktLabel.Visible = 'off';
            app.MesspunktLabel.Text = 'Messpunkt';
            app.Bild_Schaltung = uiimage(app.VielfachmesserTab);
            app.Bild_Schaltung.ImageSource = 'stromrichtig.png';
            app.Bild_Widerstand = uiimage(app.VielfachmesserTab);
            app.Bild_Widerstand.ImageSource = 'Widerstand_60k.png';
            app.Bild_Kabel1 = uiimage(app.VielfachmesserTab);
            app.Bild_Kabel1.Visible = 'off';
            app.Bild_Kabel1.ImageSource = 'Kabel 1.png';
            app.Bild_Kabel2 = uiimage(app.VielfachmesserTab);
            app.Bild_Kabel2.Visible = 'off';
            app.Bild_Kabel2.ImageSource = 'Kabel 2.png';
            app.Bild_Kabel3 = uiimage(app.VielfachmesserTab);
            app.Bild_Kabel3.Visible = 'off';
            app.Bild_Kabel3.ImageSource = 'Kabel 3.png';
            app.Bild_Kabel4 = uiimage(app.VielfachmesserTab);
            app.Bild_Kabel4.Visible = 'off';
            app.Bild_Kabel4.ImageSource = 'Kabel 4.png';
            app.Bild_Kabel5 = uiimage(app.VielfachmesserTab);
            app.Bild_Kabel5.Visible = 'off';
            app.Bild_Kabel5.ImageSource = 'Kabel 5.png';
            app.Bild_Kabel6 = uiimage(app.VielfachmesserTab);
            app.Bild_Kabel6.Visible = 'off';
            app.Bild_Kabel6.ImageSource = 'Kabel 6.png';
            app.Spannungsregelung = uiknob(app.VielfachmesserTab, 'continuous');
            app.Spannungsregelung.Limits = [0 30];
            app.Spannungsregelung.MajorTicks = [0 5 10 15 20 25 30];
            app.Spannungsregelung.ValueChangedFcn = createCallbackFcn(app, @SpannungsregelungValueChanged, true);
            app.Messbereichsschalter_1 = uiknob(app.VielfachmesserTab, 'discrete');
            app.Messbereichsschalter_1.Items = {'0,12', '0,6', '3', '15', '60', '0', '300', '150', '60', '30', '15'};
            app.Messbereichsschalter_1.ItemsData = {'0.12 mA', '0.6 mA', '3 mA', '15 mA', '60 mA', '0', '300 V', '150 V', '60 V', '30 V', '15 V'};
            app.Messbereichsschalter_1.ValueChangedFcn = createCallbackFcn(app, @Messbereichsschalter_1ValueChanged2, true);
            app.Messbereichsschalter_1.Value = '0';
            app.Messbereichsschalter_2 = uiknob(app.VielfachmesserTab, 'discrete');
            app.Messbereichsschalter_2.Items = {'0,12', '0,6', '3', '15', '60', '0', '300', '150', '60', '30', '15'};
            app.Messbereichsschalter_2.ItemsData = {'0.12 mA', '0.6 mA', '3 mA', '15 mA', '60 mA', '0', '300 V', '150 V', '60 V', '30 V', '15 V'};
            app.Messbereichsschalter_2.ValueChangedFcn = createCallbackFcn(app, @Messbereichsschalter_2ValueChanged2, true);
            app.Messbereichsschalter_2.Value = '0';
            app.KabelanzeigenCheckBox = uicheckbox(app.VielfachmesserTab);
            app.KabelanzeigenCheckBox.ValueChangedFcn = createCallbackFcn(app, @KabelanzeigenCheckBoxValueChanged, true);
            app.KabelanzeigenCheckBox.Text = 'Kabel anzeigen';
            app.AP = uiimage(app.VielfachmesserTab);
            app.AP.Visible = 'off';
            app.AP.ImageSource = 'AP.png';
            app.SchaltungDropDownLabel = uilabel(app.VielfachmesserTab);
            app.SchaltungDropDownLabel.Text = 'Schaltung';
            app.SchaltungDropDown = uidropdown(app.VielfachmesserTab);
            app.SchaltungDropDown.Items = {'stromrichtig', 'spannungsrichtig', ''};
            app.SchaltungDropDown.ItemsData = {'1', '2'};
            app.SchaltungDropDown.ValueChangedFcn = createCallbackFcn(app, @SchaltungDropDownValueChanged, true);
            app.SchaltungDropDown.Value = '1';
            app.Label_Bemerkung = uilabel(app.VielfachmesserTab);
            app.Label_Bemerkung.Visible = 'off';
            app.Label_Bemerkung.Text = {'Die Schaltung und der Widerstand'; 'können nur geändert werden, wenn '; 'die Spannungsquelle ausgeschaltet ist!'};
            app.MesspunktespeichernCheckBox = uicheckbox(app.VielfachmesserTab);
            app.MesspunktespeichernCheckBox.ValueChangedFcn = createCallbackFcn(app, @MesspunktespeichernCheckBoxValueChanged, true);
            app.MesspunktespeichernCheckBox.Text = 'Messpunkte speichern';
            app.MesspunktelschenButton = uibutton(app.VielfachmesserTab, 'push');
            app.MesspunktelschenButton.ButtonPushedFcn = createCallbackFcn(app, @MesspunktelschenButtonPushed, true);
            app.MesspunktelschenButton.Text = 'Messpunkte löschen';
            app.WiderstandDropDownLabel = uilabel(app.VielfachmesserTab);
            app.WiderstandDropDownLabel.Text = 'Widerstand';
            app.WiderstandDropDown = uidropdown(app.VielfachmesserTab);
            app.WiderstandDropDown.Items = {'R1', 'R2', 'R3', 'R4', 'R5'};
            app.WiderstandDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.WiderstandDropDown.ValueChangedFcn = createCallbackFcn(app, @WiderstandDropDownValueChanged, true);
            app.WiderstandDropDown.Value = '1';
            app.Gauge_1 = uigauge(app.VielfachmesserTab, 'semicircular');
            app.Gauge_1.Limits = [0 30];
            app.Gauge_1.Tooltip = {'0'};
            app.Gauge_2 = uigauge(app.VielfachmesserTab, 'semicircular');
            app.Gauge_2.Limits = [0 30];
            app.Gauge_2.Tooltip = {'0'};
            app.mALabel = uilabel(app.VielfachmesserTab);
            app.mALabel.Text = 'mA';
            app.VLabel = uilabel(app.VielfachmesserTab);
            app.VLabel.Text = 'V';
            app.mALabel_2 = uilabel(app.VielfachmesserTab);
            app.mALabel_2.Text = 'mA';
            app.VLabel_2 = uilabel(app.VielfachmesserTab);
            app.VLabel_2.Text = 'V';
            app.DozentenansichteinButton = uibutton(app.VielfachmesserTab, 'push');
            app.DozentenansichteinButton.ButtonPushedFcn = createCallbackFcn(app, @DozentenansichteinButtonPushed, true);
            app.DozentenansichteinButton.Text = 'Dozentenansicht ein';
            app.DozentenansichtausButton = uibutton(app.VielfachmesserTab, 'push');
            app.DozentenansichtausButton.ButtonPushedFcn = createCallbackFcn(app, @DozentenansichtausButtonPushed, true);
            app.DozentenansichtausButton.Text = 'Dozentenansicht aus';
            app.HinweiseTab = uitab(app.TabGroup);
            app.HinweiseTab.Title = 'Hinweise';
            app.Image = uiimage(app.HinweiseTab);
            app.Image.ImageSource = 'Text.png';
            app.BedienungTab = uitab(app.TabGroup);
            app.BedienungTab.Title = 'Bedienung';
            app.Image2 = uiimage(app.BedienungTab);
            app.Image2.ImageSource = 'Bedienung.png';
            app.Widerstandsbestimmung = uitab(app.TabGroup);
            app.Widerstandsbestimmung.Title = 'Aufgabe Widerstandsbestimmung';
            app.WiderstandberechnenundberprfenButton = uibutton(app.Widerstandsbestimmung, 'push');
            app.WiderstandberechnenundberprfenButton.ButtonPushedFcn = createCallbackFcn(app, @WiderstandberechnenundberprfenButtonPushed, true);
            app.WiderstandberechnenundberprfenButton.Text = 'Widerstand berechnen und überprüfen';
            app.WiderstandberechnetkEditFieldLabel = uilabel(app.Widerstandsbestimmung);
            app.WiderstandberechnetkEditFieldLabel.Text = 'Widerstand berechnet (kΩ)';
            app.WiderstandberechnetkEditField = uieditfield(app.Widerstandsbestimmung, 'numeric');
            app.WiderstandberechnetkEditField.Editable = 'off';
            app.WiderstandberechnetkEditField.Enable = 'off';
            app.SpannungVEditFieldLabel = uilabel(app.Widerstandsbestimmung);
            app.SpannungVEditFieldLabel.Text = 'Spannung (V)';
            app.SpannungVEditField = uieditfield(app.Widerstandsbestimmung, 'numeric');
            app.StromAEditFieldLabel = uilabel(app.Widerstandsbestimmung);
            app.StromAEditFieldLabel.Text = 'Strom (μA)';
            app.StromAEditField = uieditfield(app.Widerstandsbestimmung, 'numeric');
            app.InnenwiderstandAmperemeterEditFieldLabel = uilabel(app.Widerstandsbestimmung);
            app.InnenwiderstandAmperemeterEditFieldLabel.Text = 'Innenwiderstand Amperemeter (Ω) ';
            app.InnenwiderstandAmperemeterEditField = uieditfield(app.Widerstandsbestimmung, 'numeric');
            app.InnenwiderstandVoltmeterMEditFieldLabel = uilabel(app.Widerstandsbestimmung);
            app.InnenwiderstandVoltmeterMEditFieldLabel.Text = 'Innenwiderstand Voltmeter (MΩ)';
            app.InnenwiderstandVoltmeterMEditField = uieditfield(app.Widerstandsbestimmung, 'numeric');
            app.InnenwiderstandVoltmeterMEditField.Enable = 'off';
            app.WiderstandrealkEditFieldLabel = uilabel(app.Widerstandsbestimmung);
            app.WiderstandrealkEditFieldLabel.Visible = 'off';
            app.WiderstandrealkEditFieldLabel.Text = 'Widerstand real (kΩ)';
            app.WiderstandrealkEditField = uieditfield(app.Widerstandsbestimmung, 'numeric');
            app.WiderstandrealkEditField.Editable = 'off';
            app.WiderstandrealkEditField.Enable = 'off';
            app.WiderstandrealkEditField.Visible = 'off';
            app.FehlerLabel = uilabel(app.Widerstandsbestimmung);
            app.FehlerLabel.Visible = 'off';
            app.FehlerLabel.Text = 'Fehler';
            app.Abweichung5Label_2 = uilabel(app.Widerstandsbestimmung);
            app.Abweichung5Label_2.Visible = 'off';
            app.Abweichung5Label_2.Text = 'Abweichung < 5%';
            app.Abweichung5Label = uilabel(app.Widerstandsbestimmung);
            app.Abweichung5Label.Visible = 'off';
            app.Abweichung5Label.Text = 'Abweichung > 5%';
            app.CopyrightTab = uitab(app.TabGroup);
            app.CopyrightTab.Title = 'Copyright';
            % Show the figure after all components are created
            app.Vielfachmesser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Vielfachmesser)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Vielfachmesser)
        end
    end
end